classdef bioSAXSmat2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioSAXSMatUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        BackSubTab                      matlab.ui.container.Tab
        SaveSAXSReport1Button           matlab.ui.control.Button
        SAXSReportEditField             matlab.ui.control.EditField
        SAXSReportEditFieldLabel        matlab.ui.control.Label
        SimulatenousSubtractionConcExtroplationLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        ResetBackSubDataButton          matlab.ui.control.Button
        LoadBackSubDataButton           matlab.ui.control.Button
        SaveBackSubDataParametersButton  matlab.ui.control.Button
        FittingErrorsLabel              matlab.ui.control.Label
        FittedConcRatioLabel            matlab.ui.control.Label
        ConcRatioStr                    matlab.ui.control.Label
        FittingMsgLabel                 matlab.ui.control.Label
        SaveBackSubsButton              matlab.ui.control.Button
        FitalphaBetaConcExtroplateButton  matlab.ui.control.Button
        qMin                            matlab.ui.control.NumericEditField
        qMaxEditFieldLabel_2            matlab.ui.control.Label
        qMax                            matlab.ui.control.NumericEditField
        qMaxEditFieldLabel              matlab.ui.control.Label
        beta1                           matlab.ui.control.NumericEditField
        beta2                           matlab.ui.control.NumericEditField
        beta3                           matlab.ui.control.NumericEditField
        beta4                           matlab.ui.control.NumericEditField
        beta5                           matlab.ui.control.NumericEditField
        alpha1                          matlab.ui.control.NumericEditField
        alpha2                          matlab.ui.control.NumericEditField
        alpha3                          matlab.ui.control.NumericEditField
        alpha4                          matlab.ui.control.NumericEditField
        alpha5                          matlab.ui.control.NumericEditField
        beta6                           matlab.ui.control.NumericEditField
        alpha6                          matlab.ui.control.NumericEditField
        betaLB                          matlab.ui.control.NumericEditField
        alphaLB                         matlab.ui.control.NumericEditField
        betaUB                          matlab.ui.control.NumericEditField
        alphaUB                         matlab.ui.control.NumericEditField
        S6CHK2                          matlab.ui.control.CheckBox
        S5CHK2                          matlab.ui.control.CheckBox
        S4CHK2                          matlab.ui.control.CheckBox
        S3CHK2                          matlab.ui.control.CheckBox
        S2CHK2                          matlab.ui.control.CheckBox
        S1CHK2                          matlab.ui.control.CheckBox
        betaLabel                       matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        UBLabel                         matlab.ui.control.Label
        LBLabel                         matlab.ui.control.Label
        DataSelectionLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SubtractPlotSaveButton          matlab.ui.control.Button
        SubtractPlotButton              matlab.ui.control.Button
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        PlotSelectedDataButton          matlab.ui.control.Button
        DataDirLabel                    matlab.ui.control.Label
        S6FileName                      matlab.ui.control.EditField
        S6EditFieldLabel                matlab.ui.control.Label
        B6CHK                           matlab.ui.control.CheckBox
        B6FileName                      matlab.ui.control.EditField
        B1EditFieldLabel_6              matlab.ui.control.Label
        SelectB6                        matlab.ui.control.Button
        S6CHK                           matlab.ui.control.CheckBox
        S6Conc                          matlab.ui.control.NumericEditField
        SelectS6                        matlab.ui.control.Button
        S5FileName                      matlab.ui.control.EditField
        S5EditFieldLabel                matlab.ui.control.Label
        B5CHK                           matlab.ui.control.CheckBox
        B5FileName                      matlab.ui.control.EditField
        B1EditFieldLabel_5              matlab.ui.control.Label
        SelectB5                        matlab.ui.control.Button
        S5CHK                           matlab.ui.control.CheckBox
        S5Conc                          matlab.ui.control.NumericEditField
        SelectS5                        matlab.ui.control.Button
        S4FileName                      matlab.ui.control.EditField
        S4EditFieldLabel                matlab.ui.control.Label
        B4CHK                           matlab.ui.control.CheckBox
        B4FileName                      matlab.ui.control.EditField
        B1EditFieldLabel_4              matlab.ui.control.Label
        SelectB4                        matlab.ui.control.Button
        S4CHK                           matlab.ui.control.CheckBox
        S4Conc                          matlab.ui.control.NumericEditField
        SelectS4                        matlab.ui.control.Button
        S3FileName                      matlab.ui.control.EditField
        S3Label                         matlab.ui.control.Label
        B3CHK                           matlab.ui.control.CheckBox
        B3FileName                      matlab.ui.control.EditField
        B1EditFieldLabel_3              matlab.ui.control.Label
        SelectB3                        matlab.ui.control.Button
        S3CHK                           matlab.ui.control.CheckBox
        S3Conc                          matlab.ui.control.NumericEditField
        SelectS3                        matlab.ui.control.Button
        S2FileName                      matlab.ui.control.EditField
        S2Label                         matlab.ui.control.Label
        B2CHK                           matlab.ui.control.CheckBox
        B2FileName                      matlab.ui.control.EditField
        B1EditFieldLabel_2              matlab.ui.control.Label
        SelectB2                        matlab.ui.control.Button
        S2CHK                           matlab.ui.control.CheckBox
        S2Conc                          matlab.ui.control.NumericEditField
        SelectS2                        matlab.ui.control.Button
        B1CHK                           matlab.ui.control.CheckBox
        B1FileName                      matlab.ui.control.EditField
        B1EditFieldLabel                matlab.ui.control.Label
        SelectB1                        matlab.ui.control.Button
        S1CHK                           matlab.ui.control.CheckBox
        S1Conc                          matlab.ui.control.NumericEditField
        SelectS1                        matlab.ui.control.Button
        S1FileName                      matlab.ui.control.EditField
        S1EditFieldLabel                matlab.ui.control.Label
        BufferDataFilesUsefoRestLabel   matlab.ui.control.Label
        SampleDataFilesConcmgmlinUseLabel  matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        SaveSAXSReport2Button           matlab.ui.control.Button
        KratkyMWetcLabel                matlab.ui.control.Label
        PathAtsasbinLabel               matlab.ui.control.Label
        SetATSASbinPathButton           matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        MsgLabel                        matlab.ui.control.Label
        CalculateSAXSInvariantButton    matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NAButton                        matlab.ui.control.RadioButton
        ProteinButton                   matlab.ui.control.RadioButton
        EstimateMWButton                matlab.ui.control.Button
        qMaxEditField_4                 matlab.ui.control.NumericEditField
        qMaxEditField_3Label_2          matlab.ui.control.Label
        qMinEditField_2                 matlab.ui.control.NumericEditField
        qMinEditFieldLabel_2            matlab.ui.control.Label
        QnIQplotButton                  matlab.ui.control.Button
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        KratkyPlotButton                matlab.ui.control.Button
        SAXS3Fn                         matlab.ui.control.EditField
        SelectSAXSFileKratky            matlab.ui.control.Button
        PDDFAnalysisLabel               matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        liveGNOM                        matlab.ui.control.CheckBox
        DmaxSpinner                     matlab.ui.control.Spinner
        DmaxSpinnerLabel                matlab.ui.control.Label
        alphaPDDF                       matlab.ui.control.NumericEditField
        alphaEditField_14Label          matlab.ui.control.Label
        SaveGNOMExtropSAXSButton        matlab.ui.control.Button
        PlotSAXSFitButton               matlab.ui.control.Button
        NormalizeCheckBox               matlab.ui.control.CheckBox
        SavePDDFButton                  matlab.ui.control.Button
        PlotPDDFButton                  matlab.ui.control.Button
        ManualGNOMButton                matlab.ui.control.Button
        RunAutoGNOMButton               matlab.ui.control.Button
        gnomFile                        matlab.ui.control.EditField
        SelectGNOMoutputButton          matlab.ui.control.Button
        qMax2                           matlab.ui.control.NumericEditField
        qMaxEditField_3Label            matlab.ui.control.Label
        qMin2                           matlab.ui.control.NumericEditField
        qMinEditFieldLabel              matlab.ui.control.Label
        SAXS2Fn                         matlab.ui.control.EditField
        SelectSAXSFilePDDF              matlab.ui.control.Button
        GuinierLabel                    matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        SAVEResultsButton               matlab.ui.control.Button
        RgqMaxEditField                 matlab.ui.control.NumericEditField
        RgqMaxEditFieldLabel            matlab.ui.control.Label
        ptEndSpinner                    matlab.ui.control.Spinner
        ptEndSpinnerLabel               matlab.ui.control.Label
        ptStartSpinner                  matlab.ui.control.Spinner
        ptStartSpinnerLabel             matlab.ui.control.Label
        FileNameLabel                   matlab.ui.control.Label
        gtotFn                          matlab.ui.control.EditField
        UsemidptsSNCheckBox             matlab.ui.control.CheckBox
        ExtrapolateDateSaveFileButton   matlab.ui.control.Button
        IoEditField                     matlab.ui.control.NumericEditField
        IoEditFieldLabel                matlab.ui.control.Label
        RgEditField                     matlab.ui.control.NumericEditField
        RgEditFieldLabel                matlab.ui.control.Label
        RunATSASGuinierFitButton        matlab.ui.control.Button
        SAXSFn                          matlab.ui.control.EditField
        SelectSAXSFileButton            matlab.ui.control.Button
        MergeZeroConcLabel              matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        mZeroConcFn                     matlab.ui.control.EditField
        MergeZeroConcFileButton         matlab.ui.control.Button
        mBsubDropDown                   matlab.ui.control.DropDown
        mBsubDropDownLabel              matlab.ui.control.Label
        qMergeEditField                 matlab.ui.control.NumericEditField
        qMergeEditFieldLabel            matlab.ui.control.Label
        SAXSWAXSTab                     matlab.ui.container.Tab
        SaveSAXSReport3Button           matlab.ui.control.Button
        SAXSWAXSBackgroundSubtractionLabel  matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        AutomaticSAXSWAXSMergeButton    matlab.ui.control.Button
        qMergeEditField_2               matlab.ui.control.NumericEditField
        qMergeEditField_2Label          matlab.ui.control.Label
        qHighEditField                  matlab.ui.control.NumericEditField
        qHighEditFieldLabel             matlab.ui.control.Label
        qLowEditField                   matlab.ui.control.NumericEditField
        qLowEditFieldLabel              matlab.ui.control.Label
        SAXSWAXSMergingLabel            matlab.ui.control.Label
        WAXS_betaEditField              matlab.ui.control.NumericEditField
        WAXS_betaEditFieldLabel         matlab.ui.control.Label
        PlotBSubonlyCheckBox            matlab.ui.control.CheckBox
        SAXSWAXSBackgroundSubtractionButton  matlab.ui.control.Button
        WAXS_alphaEditField             matlab.ui.control.NumericEditField
        WAXS_alphaEditFieldLabel        matlab.ui.control.Label
        synwWAXSCheckBox                matlab.ui.control.CheckBox
        SAXS_alphaEditField             matlab.ui.control.NumericEditField
        SAXS_alphaEditFieldLabel        matlab.ui.control.Label
        EmptyCellOnCheckBox             matlab.ui.control.CheckBox
        EditField_22                    matlab.ui.control.EditField
        LoadEmptyCellWAXSButton         matlab.ui.control.Button
        EditField_21                    matlab.ui.control.EditField
        LoadEmptyCellSAXSButton         matlab.ui.control.Button
        EditField_20                    matlab.ui.control.EditField
        LoadBufferWAXSButton            matlab.ui.control.Button
        EditField_19                    matlab.ui.control.EditField
        LoadBufferSAXSButton            matlab.ui.control.Button
        EditField_18                    matlab.ui.control.EditField
        LoadSampleWAXSButton            matlab.ui.control.Button
        EditField_17                    matlab.ui.control.EditField
        LoadSampleSAXSButton            matlab.ui.control.Button
        AveragingTab2                   matlab.ui.container.Tab
        SAXSAvgReportEditField          matlab.ui.control.EditField
        SAXSAvgReportEditFieldLabel     matlab.ui.control.Label
        SAXSWAXSAveragingSettingLabel   matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        SAXSWAXSLabel                   matlab.ui.control.Label
        AverageSAXSWAXS1DsbySerialButton  matlab.ui.control.Button
        DataSerialsEditField_2          matlab.ui.control.EditField
        DataSerialsEditField_2Label     matlab.ui.control.Label
        FileNameFormatEditField_2       matlab.ui.control.EditField
        FileNameFormatEditField_2Label  matlab.ui.control.Label
        qSegmSpinner_2                  matlab.ui.control.Spinner
        qSegmSpinner_2Label             matlab.ui.control.Label
        qrangeEditField_4               matlab.ui.control.NumericEditField
        qrangeEditField_4Label          matlab.ui.control.Label
        AveragebySerialButton_2         matlab.ui.control.Button
        AutoDetectBKGCheckBox_2         matlab.ui.control.CheckBox
        EditField_24                    matlab.ui.control.EditField
        Select1DWAXSDateFolderButton    matlab.ui.control.Button
        SelectFilestoAverageButton_2    matlab.ui.control.Button
        CheckDamageCheckBox_2           matlab.ui.control.CheckBox
        qrangeEditField_3               matlab.ui.control.NumericEditField
        WAXSLabel                       matlab.ui.control.Label
        DataSerialsEditField            matlab.ui.control.EditField
        DataSerialsEditFieldLabel       matlab.ui.control.Label
        AveragebySerialButton           matlab.ui.control.Button
        AutoDetectBKGCheckBox           matlab.ui.control.CheckBox
        EditField_23                    matlab.ui.control.EditField
        Select1DSAXSDateFolderButton    matlab.ui.control.Button
        SelectFilestoAverageButton      matlab.ui.control.Button
        FileNameFormatEditField         matlab.ui.control.EditField
        FileNameFormatEditFieldLabel    matlab.ui.control.Label
        CheckDamageCheckBox             matlab.ui.control.CheckBox
        qSegmSpinner                    matlab.ui.control.Spinner
        qSegmSpinnerLabel               matlab.ui.control.Label
        qrangeEditField_2               matlab.ui.control.NumericEditField
        SAXSLabel                       matlab.ui.control.Label
        qrangeEditField                 matlab.ui.control.NumericEditField
        qrangeEditFieldLabel            matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        critPEditField                  matlab.ui.control.NumericEditField
        critPEditFieldLabel             matlab.ui.control.Label
        alphaEditField_15               matlab.ui.control.NumericEditField
        alphaEditField_15Label          matlab.ui.control.Label
        nMADEditField                   matlab.ui.control.NumericEditField
        nMADEditFieldLabel              matlab.ui.control.Label
        StandardButton                  matlab.ui.control.RadioButton
        MADButton                       matlab.ui.control.RadioButton
        OutlierDetectionLabel           matlab.ui.control.Label
        AlgorithmsinUseLabel            matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        ChebyshevdistanceCheckBox       matlab.ui.control.CheckBox
        Y2distanceCheckBox              matlab.ui.control.CheckBox
        CrossCorrectionCheckBox         matlab.ui.control.CheckBox
        SECTab                          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PlotSer_vs_SAXSButton           matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        BkgFileTag                      matlab.ui.control.EditField
        DataFileTag                     matlab.ui.control.EditField
        DataSerNumLow                   matlab.ui.control.NumericEditField
        DatarangeEditFieldLabel         matlab.ui.control.Label
        AveragingasDataSAXSButton       matlab.ui.control.Button
        DataSerNumHigh                  matlab.ui.control.NumericEditField
        AveragingasBkgSAXSButton        matlab.ui.control.Button
        BkgSerNumHigh                   matlab.ui.control.NumericEditField
        BkgSerNumLow                    matlab.ui.control.NumericEditField
        BkgrangeEditFieldLabel          matlab.ui.control.Label
        IntergrationSettingLabel        matlab.ui.control.Label
        flexiblebytimeLabel             matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        SelectDataSetsRunIntergrationButton  matlab.ui.control.Button
        DataSelectionbySerialLabel      matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        SEC_qLow                        matlab.ui.control.NumericEditField
        q_rangeEditFieldLabel           matlab.ui.control.Label
        SEC_AreaCutoffLow               matlab.ui.control.NumericEditField
        area_cutoffEditFieldLabel       matlab.ui.control.Label
        SEC_qHigh                       matlab.ui.control.NumericEditField
        SEC_AreaCutoffHigh              matlab.ui.control.NumericEditField
        Panel_10                        matlab.ui.container.Panel
        SerFormat                       matlab.ui.control.EditField
        FormatEditFieldLabel            matlab.ui.control.Label
        SEC_DataSerialNum               matlab.ui.control.NumericEditField
        DataSerialEditFieldLabel        matlab.ui.control.Label
        RunSAXSIntergrationButton       matlab.ui.control.Button
        LoadSECSAXSMetaResultsButton    matlab.ui.control.Button
        SaveSECSAXSMetaResultsButton    matlab.ui.control.Button
        SECDataFolder                   matlab.ui.control.EditField
        Select1DSAXSFolderButton_2      matlab.ui.control.Button
        SECResultFolder                 matlab.ui.control.EditField
        SelectResultsFolderButton       matlab.ui.control.Button
        TRSAXSTab                       matlab.ui.container.Tab
        trAlphaEditField                matlab.ui.control.NumericEditField
        alphaEditField_16Label          matlab.ui.control.Label
        SaveTRSAXSReportButton          matlab.ui.control.Button
        TRSAXSReportEditField           matlab.ui.control.EditField
        TRSAXSReportEditFieldLabel      matlab.ui.control.Label
        DurationmsEditField             matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel        matlab.ui.control.Label
        SFAFirst3timepoints             matlab.ui.control.EditField
        First3timepointsmsLabel         matlab.ui.control.Label
        FlowRatemLsEditField            matlab.ui.control.NumericEditField
        FlowRatemLsEditFieldLabel       matlab.ui.control.Label
        DeadVolumeuLEditField           matlab.ui.control.NumericEditField
        DeadVolumeuLEditFieldLabel      matlab.ui.control.Label
        SFAExperimentalNotes            matlab.ui.control.EditField
        ExperimentalNotesEditFieldLabel  matlab.ui.control.Label
        CheckFlowSpeedButton            matlab.ui.control.Button
        RunTRSAXSDataProcessingButton   matlab.ui.control.Button
        StoppedFlowoperationSettingLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        XrayPeriodtimemsEditField       matlab.ui.control.NumericEditField
        XrayPeriodtimemsEditFieldLabel  matlab.ui.control.Label
        ExtraDelaymsEditField           matlab.ui.control.NumericEditField
        ExtraDelaymsEditFieldLabel      matlab.ui.control.Label
        XrayExptimemsEditField          matlab.ui.control.NumericEditField
        XrayExptimemsEditFieldLabel     matlab.ui.control.Label
        ShotsSerialEditField            matlab.ui.control.NumericEditField
        NumberofShotsSerialLabel        matlab.ui.control.Label
        DataSerialSquenceEditField      matlab.ui.control.EditField
        DataSerialSquenceLabel          matlab.ui.control.Label
        SampleDataNameEditField         matlab.ui.control.EditField
        SampleDataNameEditFieldLabel    matlab.ui.control.Label
        SelectBackgrounddataFileButton  matlab.ui.control.Button
        SFATR_BkgFile                   matlab.ui.control.EditField
        SelectResultFolderButton        matlab.ui.control.Button
        SFATR_ResultDir                 matlab.ui.control.EditField
        SelectDataFolderButton          matlab.ui.control.Button
        SFATR_DataDir                   matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        SAXSPlotTab                     matlab.ui.container.Tab
        ErrorbaronCheckBox              matlab.ui.control.CheckBox
        EditField_16                    matlab.ui.control.EditField
        LoadDatatoPlotButton            matlab.ui.control.Button
        SaveFigureButton                matlab.ui.control.Button
        TitleLabelEditField             matlab.ui.control.EditField
        TitleLabelEditFieldLabel        matlab.ui.control.Label
        XLabelEditField_2               matlab.ui.control.EditField
        XLabelEditFieldLabel_2          matlab.ui.control.Label
        XLabelEditField                 matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        AddDataButton                   matlab.ui.control.Button
        ReplotButton                    matlab.ui.control.Button
        PlotOptionDropDown              matlab.ui.control.DropDown
        PlotOptionDropDownLabel         matlab.ui.control.Label
        YlimMin                         matlab.ui.control.NumericEditField
        YlimEditFieldLabel              matlab.ui.control.Label
        YlimMax                         matlab.ui.control.NumericEditField
        XlimMax                         matlab.ui.control.NumericEditField
        XlimMin                         matlab.ui.control.NumericEditField
        XlimEditFieldLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnalysisPlotTab                 matlab.ui.container.Tab
        UIAxesSFA4                      matlab.ui.control.UIAxes
        UIAxesSFA3                      matlab.ui.control.UIAxes
        SECPlotTab                      matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        MWEditField                     matlab.ui.control.NumericEditField
        MWEditFieldLabel                matlab.ui.control.Label
        AlignatPeakButton               matlab.ui.control.Button
        ExtCoeffEditField               matlab.ui.control.NumericEditField
        ExtCoeffLabel                   matlab.ui.control.Label
        XlimMin_2                       matlab.ui.control.NumericEditField
        LylimLabel                      matlab.ui.control.Label
        SEC_FPLCcurveStyle              matlab.ui.control.EditField
        SECCurvestyleLabel_2            matlab.ui.control.Label
        SEC_SAXSCurveStyle              matlab.ui.control.EditField
        SECCurvestyleLabel              matlab.ui.control.Label
        SEC_saxsTimeDelay               matlab.ui.control.NumericEditField
        FlowRatemlminLabel_3            matlab.ui.control.Label
        SEC_saxsPeriod                  matlab.ui.control.NumericEditField
        FlowRatemlminLabel_2            matlab.ui.control.Label
        SEC_fplcFlowRate                matlab.ui.control.NumericEditField
        FlowRatemlminLabel              matlab.ui.control.Label
        RightYLabelEditField            matlab.ui.control.EditField
        XLabelEditFieldLabel_4          matlab.ui.control.Label
        SAXSLabel_2                     matlab.ui.control.Label
        FPLCLabel                       matlab.ui.control.Label
        LeftYLabelEditField             matlab.ui.control.EditField
        XLabelEditFieldLabel_3          matlab.ui.control.Label
        YlimMin_3                       matlab.ui.control.NumericEditField
        YlimEditFieldLabel_3            matlab.ui.control.Label
        YlimMax_3                       matlab.ui.control.NumericEditField
        YlimMin_2                       matlab.ui.control.NumericEditField
        YlimEditFieldLabel_2            matlab.ui.control.Label
        YlimMax_2                       matlab.ui.control.NumericEditField
        XlimMax_2                       matlab.ui.control.NumericEditField
        LoadSAXS_vs_SerFileButton_2     matlab.ui.control.Button
        PlotSAXS_vs_SerButton           matlab.ui.control.Button
        LoadFPLCElutionFileButton       matlab.ui.control.Button
        SEC2Axes                        matlab.ui.control.UIAxes
        SFATab                          matlab.ui.container.Tab
        UIAxesSFA2                      matlab.ui.control.UIAxes
        UIAxesSFA1                      matlab.ui.control.UIAxes
        PlotsTab                        matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %Property % Description
        % global parameters:
        LastPath=pwd;

        % property 'saxs' to replace 'dataXXX'
        saxs = struct('dataDir', '.', 'alpha', 1.0, 'qMin', 0.008, 'qMax', 0.60, 'qMerge', 0.009, ...
            'buf1Fn', 'buffer1.dat', 'buf2Fn', 'buffer2.dat', 'buf3Fn', 'buffer3.dat', ...
            'buf4Fn', 'buffer4.dat', 'buf5Fn', 'buffer5.dat', 'buf6Fn', 'buffer6.dat', ...
            'buf1Sel', 1, 'buf2Sel', 0, 'buf3Sel', 0, 'buf4Sel', 0, 'buf5Sel', 0, 'buf6Sel', 0, ...
            'samp1Conc', 1.0, 'samp2Conc', 2.0, 'samp3Conc', 3.0, 'samp4Conc', 4.0, 'samp5Conc', 5.0, 'samp6Conc', 6.0, ...
            'samp1Fn', 'sample1.dat', 'samp2Fn', 'sample2.dat','samp3Fn', 'sample3.dat', ...
            'samp4Fn', 'sample4.dat', 'samp5Fn', 'sample5.dat','samp6Fn', 'sample6.dat', ...
            'samp1Sel', 1, 'samp2Sel', 1,'samp3Sel', 1, 'samp4Sel', 0, 'samp5Sel', 0, 'samp6Sel', 0, ...
            'buf1', [], 'buf2', [], 'buf3', [], 'buf4', [], 'buf5', [], 'buf6', [], ...
            'samp1', [], 'samp2', [], 'samp3', [], 'samp4', [], 'samp5', [], 'samp6', [], ...
            'samp1Bsub', [], 'samp2Bsub', [], 'samp3Bsub', [], 'samp4Bsub', [], 'samp5Bsub', [], 'samp6Bsub', [], ...
            'mBsub', [], 'zeroConc', [], 'mZeroConc', [], 'midSN', 1, 'mZeroConcFn', '', 'zConcFn', '', ...
            'samp1Alpha', 1.0, 'samp2Alpha', 1.0, 'samp3Alpha', 1.0, 'samp4Alpha', 1.0, 'samp5Alpha', 1.0, 'samp6Alpha', 1.0, ...
            'samp1Beta', 1.0, 'samp2Beta', 1.0, 'samp3Beta', 1.0, 'samp4Beta', 1.0, 'samp5Beta', 1.0, 'samp6Beta', 1.0, ...
            'alphaLB',0.9, 'alphaUB', 1.0, 'betaLB', 0.75, 'betaUB', 1.50, 'NQ', 1.0, 'fitErr', [], ...
            'atsasPath','C:\atsas\bin',  'saxs', [], 'saxsFn', '', 'saxsFnShort','', 'gtot', [], 'gtotFn', '', ...
            'Rg', 10.0, 'RgErr', 1.0, 'Io', 10.0, 'IoErr', 1.0, 'firstPts', 1, 'endPts', 5000, 'Qfit', 0.99, 'aggr', 0.5, ...
            'pddfSAXSq1', 0.008, 'pddfSAXSq2', 0.350, 'pddf', [], 'pddfFn', '', 'saxsFit', [], 'RgGnom', -1, 'Dmax', 100.0);
        
        % Analysis
        saxs2 = struct( 'atsasPath','C:\atsas\bin',  'dataGDir', '', 'dataPDir', '', ...
            'dataG', []);
        
        % Data Average parameters
        avg = struct('dataDir', '', 'resultDir', '', 'qLow', 0.001, 'qHigh', 0.7, ... 
                    'cutoffLow', 0.0, 'cutoffHigh', 1.0, 'serNum', 20, 'fnTemp', '', ...
                    'goodScans', [], 'iqAreas',[], 'outliers', [], 'missings', [], ...
                    'scanInPlot', [], 'iqAreaInPlot', []);
        
        
        % SEC parameters
        sec = struct('dataDir', '', 'resultDir', '', 'qLow', 0.001, 'qHigh', 0.7, ... 
                    'cutoffLow', 0.0, 'cutoffHigh', 1.0, 'serNum', 20, 'fnTemp', '', ...
                    'goodScans', [], 'iqAreas',[], 'outliers', [], 'missings', [], ...
                    'scanInPlot', [], 'iqAreaInPlot', []);

        % Stopped-Flow Apparatus time-resolved SAXS
        sfaTR = struct('dataDir', '', 'resultDir', '', 'bkgFn', '', 'sampFn', '', ...
            'serNseq', '', 'serNs', [], 'bkg', [], 'nShot', 20, 'expt', 10, 'prd', 50, ...
            'deadtime', 0, 'delay', 0, 'deadVol', 18.0, 'flowRate', 4.0, 'duration', 100, ...
            'alpha', 1.000, ...
            'serT', [], 'serRg', [], 'serIo', [], 'q',[], 'Iq', [], 'IqErr', [], 'IqKrt', []);

%         secDataDir = ''
%         secResultDir = ''
%         secQLow = 0.001
%         secQHigh = 0.7
%         secCutoffLow = 0.0
%         secCutoffHigh = 1.0
%         secSerNum = 20
%         secGoodScans = []
%         secIqAreas =[] 
%         secFntemp = ''

%         data={}
%         dataDir = '.'
%         dataBuf1Fn='buffer1.dat'
%         dataBuf2Fn='buffer2.dat'
%         dataBuf3Fn='buffer3.dat'
%         dataBuf4Fn='buffer4.dat'
%         dataBuf5Fn='buffer5.dat'
%         dataBuf6Fn='buffer6.dat'
%         
%         dataBuf1Sel=1
%         dataBuf2Sel=0
%         dataBuf3Sel=0
%         dataBuf4Sel=0
%         dataBuf5Sel=0
%         dataBuf6Sel=0
%         
%         dataSamp1Fn='sample1.dat'
%         dataSamp1Conc=1.0
%         dataSamp1Sel=1
%         dataSamp2Fn='sample2.dat'
%         dataSamp2Conc=2.0
%         dataSamp2Sel=1
%         dataSamp3Fn='sample3.dat'
%         dataSamp3Conc=3.0
%         dataSamp3Sel=1
%         dataSamp4Fn='sample4.dat'
%         dataSamp4Conc=4.0
%         dataSamp4Sel=0
%         dataSamp5Fn='sample5.dat'
%         dataSamp5Conc=5.0
%         dataSamp5Sel=0
%         dataSamp6Fn='sample6.dat'
%         dataSamp6Conc=6.0
%         dataSamp6Sel=0        
%         dataAlpha = 1.00
%         
%         dataMidSN=1
%         
%         dataBuf1=[]
%         dataBuf2=[]
%         dataBuf3=[]
%         dataBuf4=[]
%         dataBuf5=[]
%         dataBuf6=[]
%         
%         dataSamp1=[]
%         dataSamp2=[]
%         dataSamp3=[]
%         dataSamp4=[]
%         dataSamp5=[]
%         dataSamp6=[]
%         dataSamp1Bsub=[]
%         dataSamp2Bsub=[]
%         dataSamp3Bsub=[]
%         dataSamp4Bsub=[]
%         dataSamp5Bsub=[]
%         dataSamp6Bsub=[]
%         
%         dataZeroConc=[]
%         datamZeroConc=[]
%         datagtot=[]
%         
%         datamZeroConcFn = ''
%         datagtotFn=''
% 
%         dataSamp1Alpha= 1.0
%         dataSamp2Alpha= 1.0 
%         dataSamp3Alpha= 1.0
%         dataSamp4Alpha= 1.0 
%         dataSamp5Alpha= 1.0 
%         dataSamp6Alpha= 1.0 
%         dataSamp1Beta= 1.0 
%         dataSamp2Beta= 1.0 
%         dataSamp3Beta= 1.0 
%         dataSamp4Beta= 1.0 
%         dataSamp5Beta= 1.0 
%         dataSamp6Beta= 1.0 
%         
%         dataAlphaLB=0.90
%         dataAlphaUB=1.00        
%         dataBetaLB=0.75
%         dataBetaUB=1.50
%         
%         dataQMin = 0.08
%         dataQMax = 0.60
%         dataQMerge = 0.09
%         
%         dataNq = 1.0


    end
    
    methods (Access = private)
  
        %%%===================================================================================================================
        %   1. functions for Background subtraction
        %%%===================================================================================================================
        
        function results = selectDataFile(app, ftype)
            results = 0;
            [file,path] = uigetfile({'*.dat; *.avg; *.txt';'*.*'},...
                          'Select a data file', app.LastPath);
            if isequal(file,0)
               disp('File selection canceled')
            else
               disp(['Selected file: ', fullfile(path, file)]);
%                app.dataDir = path;
               app.saxs.dataDir = path;
               fullDataFile = fullfile(path, file);
               datType = ftype(1); datN = ftype(2);
               if upper(datType) == 'S'
                   %str1= sprintf('dataSamp%sFn = "%s"', datN, file);
                   %disp(str1)
%                    eval(sprintf('app.dataSamp%sFn = "%s";', datN, file)); 
%                    eval(sprintf('app.dataSamp%s = readData("%s");', datN, fullDataFile));
                   eval(sprintf('app.saxs.samp%sFn = "%s";', datN, file)); 
                   eval(sprintf('app.saxs.samp%s = readData("%s");', datN, fullDataFile));                   
               elseif upper(datType) == 'B'
%                    eval(sprintf('app.dataBuf%sFn = "%s";', datN, file)); 
%                    eval(sprintf('app.dataBuf%s = readData("%s");', datN, fullDataFile));  
                   eval(sprintf('app.saxs.buf%sFn = "%s";', datN, file)); 
                   eval(sprintf('app.saxs.buf%s = readData("%s");', datN, fullDataFile));                     
               else
                   disp('Wrong here 1.')
               end
               
               eval(sprintf('app.%sFileName.Value = "%s";', upper(ftype), file));  
               app.DataDirLabel.Text = sprintf('Data Dir: %s', path);
               results = 1;

               app.LastPath = path;
            end

            % Bring app window back to front and active
            figure(app.BioSAXSMatUIFigure);
            drawnow; 
        end
        
      % Limits Changed function: UIAxes, NOT working
        function UIAxesLimitsChangedFcn(app, event)
            app.UIAxes.XLim
            app.UIAxes.YLim
%             app.UIAxes.OldLimits
%             app.UIAxes.NewLimits
%             app.XlimMin.Value = app.UIAxes.XLim(1);
%             app.XlimMax.Value = app.UIAxes.XLim(2);
%             app.YlimMin.Value = app.UIAxes.YLim(1);
%             app.YlimMax.Value = app.UIAxes.YLim(2);
        end
        
        function results = dataRanges(app, mt, positive)
            if nargin==2
                positive = 1;
            end
            if positive
                x=mt(:,1); x1 = min(x(x>0)); x2 = max(x(x>0));
                y=mt(:,2); y1 = min(y(y>0)); y2 = max(y(y>0));

            else
                x1 = min(mt(:,1)); x2 =max(mt(:,2));
                y1 = min(mt(:,1)); y2 =max(mt(:,2));
            end
            results= [x1 x2 y1 y2];
        end
        
        function results = combinePlotLimits(app, limits, margin)
            if margin>0.5 || margin < 0.001
                margin = 0.15;
            end

            results = [min(limits(:,1)) max(limits(:,2)) min(limits(:,3)) max(limits(:,4))] .* [(1-margin) (1+margin) (1-margin) (1+margin)];
            
        end
        
        % plot one set data to app.UIAxes
        function [limits, kk, legendText] = plot1Set(app, ax, data, datN, kk, cLegend, legendText, lineThickness)
            if nargin == 7
                lineThickness = 1;
            end
            limits = [];
            colors='rbgcmyk';
            
            if ~isempty(data)  
                limits=[limits; dataRanges(app,data)];
                eval(sprintf("loglog(ax, data(:,1), data(:,2), '-%s', 'LineWidth', %d);", colors(datN), lineThickness));
                legendText{kk}=regexprep(cLegend, '_', '\\_');
                kk=kk+1;
                hold(app.UIAxes, 'on');         
            else
                disp(sprintf('data are not ready to plot!'))
            end
        end
            

        %backsub & plot 1 set
        function [limits, kk, legendText] = backsubPlot1Set(app, sampN, alpha, numCHK, kk, legendText)
            limits = [];
            colors='rbgcmyk';
%             eval(sprintf('samp = app.dataSamp%d;', sampN));
%             eval(sprintf('sampFn = app.dataSamp%dFn;', sampN));
%             eval(sprintf('buf = app.dataBuf%d;', sampN));
%             eval(sprintf('bufFn = app.dataBuf%dFn;', sampN));
            eval(sprintf('samp = app.saxs.samp%d;', sampN));
            eval(sprintf('sampFn = app.saxs.samp%dFn;', sampN));
            eval(sprintf('buf = app.saxs.buf%d;', sampN));
            eval(sprintf('bufFn = app.saxs.buf%dFn;', sampN));
            
            if ~isempty(samp) && ~isempty(buf) 
                sampBSub = samp;
                sampBSub(:,2) = samp(:,2) - alpha*buf(:,2);
                limits=[limits; dataRanges(app,samp)];
                limits=[limits; dataRanges(app,sampBSub)];
                limits=[limits; dataRanges(app,buf)];

                if length(buf(1,:))>2 && length(samp(1,:))>2
                    sampBSub(:,3) = sqrt(samp(:,3).^2 + alpha*alpha*buf(:,3).^2);
                else
                    sampBSub(:,3) = 0.02 * sampBSub(:,2);
                end
                %strB1 = sprintf('app.B%dCHK.Value', sampN);
                %bufChecked = eval(strB1);
                bufChecked = eval(sprintf('app.B%dCHK.Value', sampN));
                if bufChecked && (numCHK == 1)
                    loglog(app.UIAxes, buf(:,1), buf(:,2), '-k', 'LineWidth',1);
                    bufFn2 = regexprep(bufFn, '_', '\\_');
                    legendText{kk}=bufFn2;
                    kk=kk+1;
                    hold(app.UIAxes, 'on');
                end
                %str2 = sprintf('loglog(app.UIAxes, samp(:,1), samp(:,2), "-%s", "LineWidth",1)',colors(sampN));
                %eval(str2);
                if numCHK == 1
                    eval(sprintf('loglog(app.UIAxes, samp(:,1), samp(:,2), "-%s", "LineWidth",1)',colors(sampN)));
                    sampFn2 = regexprep(sampFn, '_', '\\_');
                    legendText{kk}=sampFn2;
                    kk=kk+1;
                    hold(app.UIAxes, 'on');
                end

                eval(sprintf('loglog(app.UIAxes, sampBSub(:,1), sampBSub(:,2), "-%s", "LineWidth",2)',colors(sampN)));
                newStr = split(sampFn, '.');
                fn = join(newStr(1:end-1), '.');
                sampBSubFn = sprintf('%s_Bsub',fn{1});
                sampBSubFn2 = regexprep(sampBSubFn, '_', '\\_');
                %sampBSubFn = sprintf('%s_Bsub',sampFn);
                eval(sprintf('app.saxs.buf%dFn;', sampN));
                legendText{kk}=sampBSubFn2;
                kk=kk+1;
                hold(app.UIAxes, 'on');                
    
            else
                disp(sprintf('data for sample #%d or buffer#%d are not ready!', sampN, sampN))
            end
        end
    

        %backsub, plot & save 1 set
        function [limits, kk, legendText] = backsubPlotSave1Set(app, sampN, alpha, numCHK, kk, legendText)
            limits = [];
            colors='rbgcmy';
            eval(sprintf('samp = app.saxs.samp%d;', sampN));
            eval(sprintf('sampFn = app.saxs.samp%dFn;', sampN));
            eval(sprintf('buf = app.saxs.buf%d;', sampN));
            eval(sprintf('bufFn = app.saxs.buf%dFn;', sampN));
            
%             eval(sprintf('samp = app.dataSamp%d;', sampN));
%             eval(sprintf('sampFn = app.dataSamp%dFn;', sampN));
%             eval(sprintf('buf = app.dataBuf%d;', sampN));
%             eval(sprintf('bufFn = app.dataBuf%dFn;', sampN));
    
            if ~isempty(samp) && ~isempty(buf) 
                sampBSub = samp;
                sampBSub(:,2) = samp(:,2) - alpha*buf(:,2);
                limits=[limits; dataRanges(app,samp)];
                limits=[limits; dataRanges(app,sampBSub)];
                limits=[limits; dataRanges(app,buf)];
    
                if length(buf(1,:))>2 && length(samp(1,:))>2
                    sampBSub(:,3) = sqrt(samp(:,3).^2 + alpha*alpha*buf(:,3).^2);
                else
                    sampBSub(:,3) = 0.02 * sampBSub(:,2);
                end
                %strB1 = sprintf('app.B%dCHK.Value', sampN);
                %bufChecked = eval(strB1);
                bufChecked = eval(sprintf('app.B%dCHK.Value', sampN));
                if bufChecked && (numCHK == 1)
                    loglog(app.UIAxes, buf(:,1), buf(:,2), '-k', 'LineWidth',1);
                    legendText{kk}=regexprep(bufFn, '_', '\\_');
                    kk=kk+1;
                    hold(app.UIAxes, 'on');
                end
                %str2 = sprintf('loglog(app.UIAxes, samp(:,1), samp(:,2), "-%s", "LineWidth",1)',colors(sampN));
                %eval(str2);
                if numCHK == 1
                    eval(sprintf('loglog(app.UIAxes, samp(:,1), samp(:,2), "-%s", "LineWidth",1)',colors(sampN)));
                    legendText{kk}=regexprep(sampFn, '_', '\\_');
                    kk=kk+1;
                    hold(app.UIAxes, 'on');
                end
    
                eval(sprintf('loglog(app.UIAxes, sampBSub(:,1), sampBSub(:,2), "-%s", "LineWidth",2)',colors(sampN)));
    
                newStr = split(sampFn, '.');
                fn = join(newStr(1:end-1), '.');
                sampBSubFn = sprintf('%s_Bsub.dat',fn{1});
                fullSampBSubFn = fullfile(app.saxs.dataDir, sampBSubFn); 
                legendText{kk}=regexprep(sampBSubFn, '_', '\\_');
                kk=kk+1;
                hold(app.UIAxes, 'on');                
                %save BSub data set
                fid = fopen(fullSampBSubFn,'w');
                fprintf(fid, '#SampBsub = Samp - alpha * Bkg\n');
                fprintf(fid,'#Samp : %s\n', sampFn);
                fprintf(fid,'#Bkg : %s\n', bufFn);
                fprintf(fid,'#alpha : %.5f\n', alpha);
                fprintf(fid,'#SampBsub : %s\n', sampBSubFn);
                fprintf(fid,'#Data Dir :  %s\n', app.saxs.dataDir);                
                fprintf(fid,'#Time : %s\n', datetime);
                fprintf(fid,'# \n');
                fprintf(fid,'#  q          I(q)          I(q)_err\n');
                for jj = 1:length(sampBSub)
                    fprintf(fid, '%.5E  %.5E  %.4E\n', sampBSub(jj,1),  sampBSub(jj,2), sampBSub(jj,3));
                end
                fclose(fid);
%                 writematrix(sprintf('#sampBsub : samp - alpha * bkg'), fullSampBSubFn, 'WriteMode','overwrite');
%                 writematrix(sprintf('#samp : %s', sampFn), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(sprintf('#bkg : %s', bufFn), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(sprintf('#alpha : %.5f', alpha), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(sprintf('#sampBsub : %s', sampBSubFn), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(sprintf('#data Dir :  %s', app.dataDir), fullSampBSubFn, 'WriteMode','append');                
%                 writematrix(sprintf('#time = %s', datetime), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(sprintf('# '), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(sprintf('#  q       I(q)          I(q)_err'), fullSampBSubFn, 'WriteMode','append');
%                 writematrix(num2str(sampBSub, '%.5E'), fullSampBSubFn, 'WriteMode','append', 'Delimiter',' ');
                disp('here2')
                %writematrix(sampBSub, fullSampBSubFn, 'WriteMode','append', 'Delimiter','tab', 'precision', '%.5E');
                
            else
                disp(sprintf('data for sample #%d or buffer#%d are not ready!', sampN, sampN))
            end
    
        end   
    

        % set current buffer file for the rest
        function results = setBufferForRest(app, bufN)
            %bufFn = app.dataBuf1Fn;
            %buf = app.dataBuf1;
            %app.B1CHK.Value = 1;
            results = 1;
            eval(sprintf('buf = app.saxs.buf%d;', bufN));
            eval(sprintf('bufFn = app.saxs.buf%dFn;', bufN));
            if ~isempty(buf)  
                %eval(sprintf('app.B%dCHK.Value = 1;', bufN));
                for kk=bufN+1 : 6
                    eval(sprintf('app.saxs.buf%d = buf;', kk));
                    eval(sprintf('app.saxs.buf%dFn = bufFn;', kk));
                    eval(sprintf('app.B%dFileName.Value = "%s";', kk, bufFn));
                end
            else
                results = 0;
                disp('load buffer file first!');
            end
            
        end
        
        % simultaneous Background subtraction fitting


        %%%===================================================================================================================
        %   4. functions for SEC-SAXS
        %%%===================================================================================================================
        function [goodScans, area, outliers, missings, fntemp] = secIqAreaVsN(app, saxsDataFolder, saxsResultFolder, fSerNum, qRange, areaCutoff)
            qLow = qRange(1); qHigh = qRange(2);
            areaLow = areaCutoff(1); areaHigh = areaCutoff(2);
            format = app.SerFormat.Value;
            serialN = sprintf(format, fSerNum);
            fn = ['*_' serialN '_?????.dat'];
            fn2 = fullfile(saxsDataFolder, fn);
            fns = dir(fn2);
            fn3 = fullfile(fns(1).folder,fns(1).name);
            fn4 = fn3(1:end-10)
            [fdir, fntemp] =fileparts(fn4); 
            
            mt = readData(fullfile(fns(1).folder,fns(1).name));
            q = mt(:,1);
            [qLowN, qHighN] = findQrange(q, qLow, qHigh); 
            
            goodScans =[];
            area=[];
            outliers = [];
            for kk=1:length(fns)
                cfile = fns(kk).name;
                if mod(kk,10) ==0
                    fprintf('%s, %4d of %4d.\n', cfile, kk, length(fns));
                end
                cName = fullfile(fns(kk).folder, cfile);
                dat = readData(cName);
            %     iq = dat(:,2) - iqbkg;
            %     dat2 = [q iq];
            %     figure(4); loglog(q, iq);
            %     pause(0.2);
                mt3 = curveArea(dat, qLowN, qHighN);
                aa=split(cfile, '_');
                bb=split(aa(end), '.');
                %if mt3<1.0 % elimite not It-normalized curve
                if mt3<=areaHigh && mt3>= areaLow % elimite not It-normalized curve    
                    goodScans = [goodScans; str2num(bb{1}) ];
                    area = [area; mt3];
                else
                    outliers =[outliers; str2num(bb{1}) ];
                end
            end
            disp("outliers:")
            outliers'
            
            totRange = 1:max(goodScans); totRange = totRange(:);
            missing1 =  setdiff(totRange, goodScans);
            missings = setdiff(missing1, outliers);

            figure(333); plot(goodScans, area, '-or')
            fn3 = fullfile(saxsResultFolder, ['IqArea_vs_N_' serialN '.dat']);
            if isempty(outliers)
                outlierStr = ' ';
            else
                outlierStr = regexprep(num2str(outliers'),'\s+',',');
            end
            if isempty(missings)
                missingStr = ' ';
            else
                missingStr = regexprep(num2str(missings'),'\s+',',');
            end

            fid = fopen(fn3, 'w');
            fprintf(fid, '# Original data folder: %s\n', saxsDataFolder);
            fprintf(fid, '# Data file: %s\n', fntemp);
            fprintf(fid, '# Outliers: %s\n', outlierStr);
            fprintf(fid, '# Missing: %s\n', missingStr);
            fprintf(fid, '# Processed time: %s\n', datetime);
            fprintf(fid, '# \n');
            fprintf(fid, '# frame_Num   Integrate_I(q)\n');
            for kkk=1:length(goodScans)
                fprintf(fid, '%5d  %.6E\n', goodScans(kkk), area(kkk));
            end
            fclose(fid);
            %dlmwrite(fn3, [num area] , ' ');            

        end
        
        function results = avgSECsaxsScans(app, saxsFolder, fntemp, frameRange, resultFolder, fileTag, goodScans)
            results = 1; 
            range1 = frameRange(1):1:frameRange(2);
            range2 = intersect(range1, goodScans);
            iqs=[];
            errs=[];
            totScan = length(range2);
            for kk= range2'
                cfile = sprintf('%s_%05d.dat',fntemp,kk);
                cName = fullfile(saxsFolder, cfile);
                dat = readData(cName);
                q = dat(:,1);
                iq = dat(:,2);
                err = dat(:,3);
                iqs=[iqs iq];
                errs = [errs err];
            end
            mean_iq = mean(iqs')';
            %std_iq = std(iqs')';   % 3/5/2025
            err_se = (sqrt(sum(errs' .^2))/totScan)';
            
            figure(444); loglog(q, mean_iq, '-or');
            
            fntempParts = split(fntemp, '_');
            fntemp2 = join(fntempParts(1:end-1), '_');
            fn_ser1 = fntempParts(end);

            resultFn = sprintf('%s%s_%s.avg',fntemp2{1},fileTag, fn_ser1{1});
            fid = fopen(fullfile(resultFolder, resultFn), 'w');
            fprintf(fid, '# Original data folder: %s\n', saxsFolder);
            fprintf(fid, '# Data file: %s\n', fntemp);
            fprintf(fid, '# Frame range: %s\n', num2str(frameRange));
            fprintf(fid, '# Time processed: %s\n', datetime);
            fprintf(fid, '# \n');
            %fprintf(fid, '%s\n', '% q(A^-1)   I(q)    sqrt(I(q))');
            fprintf(fid, '%s\n', '% q(A^-1)   I(q)    I(q)_SE');
            for kkk=1:length(q)
                %fprintf(fid, '%.4E  %.6E  %.4E\n', q(kkk), mean_iq(kkk), std_iq(kkk));
                fprintf(fid, '%.4E  %.6E  %.4E\n', q(kkk), mean_iq(kkk), err_se(kkk));
            end
            fclose(fid);
        end

        
        function results = plotSAXSSeries(app)
            results = 1;
            saxsCurveStyle = app.SEC_SAXSCurveStyle.Value;
            saxsTimeDelay = app.SEC_saxsTimeDelay.Value; % sec
            saxsPeriod = app.SEC_saxsPeriod.Value;  % sec
            fplcFlowRate = app.SEC_fplcFlowRate.Value; % mL/mim

            vol = (app.sec.scanInPlot * saxsPeriod + saxsTimeDelay ) * fplcFlowRate / 60.0;
            yyaxis(app.SEC2Axes, 'right');
            ylabel(app.SEC2Axes, 'SAXS integration(a.u.)');
            %ylabel('SAXS integration');
            eval(sprintf('ax1=plot(app.SEC2Axes, vol, app.sec.iqAreaInPlot, %s);',saxsCurveStyle));
            %axis(app.SEC2Axes, 'padded');
            
        end
        

        %%%===================================================================================================================
        %   5. functions for TR-SAXS
        %%%===================================================================================================================

        % 
        function tabStr = table2Text(app, table, dataFormat)
            % dataFormat should contact extra col at the beginning for the
            % col name
            % example: dataFormat ='%% %15s  %20s  %10.2f  %10.2f  %10.2f  %10.2f\n'
            %             format1 = '%% %15s  %20s  %10s  %10s  %10s  %10s\n'
            tabStr='';
            [row, col]=size(table.Data)
            colNameFormat = regexprep(dataFormat, 'd', 's');
            colNameFormat = regexprep(colNameFormat, '.\df', 's');
            
            tabStr = sprintf(colNameFormat, '---', table.ColumnName{:}); 
            for kk=1:row-2
                tabStr = [tabStr sprintf(dataFormat, table.RowName{kk}, table.Data{kk,:})]; 
            end
            tabStr = [tabStr sprintf(colNameFormat, table.RowName{kk+1}, table.Data{kk+1,:})];
            tabStr = [tabStr sprintf(dataFormat, table.RowName{kk+2}, table.Data{kk+2,:})];
        end


        function results = convertSerNseq(app, numSeq)
            % numSeq = '20:32, 36:45, 66'
            % numSeq = '20:32, 36:45; 66'
            results = [];
            newSeq = split(numSeq, [",", ";"]);
            for kk=1:length(newSeq)
                eval(sprintf('cNums = %s;',newSeq{kk}));
                results = [results cNums];
            end
        end

        % data average and background subtraction for SFA-TR
        function results = avgTRSAXS5(app )
            %avgTRSAXS2( saxsFolder, fName, fSerNums, shotNum, save2Folder, bkgFn );
            results = 1;

            saxsFolder = app.sfaTR.dataDir;
            fName = app.sfaTR.sampFn;
            fSerNums = app.sfaTR.serNs;
            shotNum = app.sfaTR.nShot;
            save2Folder = app.sfaTR.resultDir;
            bkgFn = app.sfaTR.bkgFn;
            bkg = app.sfaTR.bkg;
            alpha = app.sfaTR.alpha;
            app.sfaTR.q = bkg(:,1);
            app.sfaTR.Iq=[];
            app.sfaTR.IqErr=[];
            app.sfaTR.IqKrty=[];

            dataFormat ='# %15s  %20s  %10.2f  %10.2f  %10.2f  %10.2f\n';
            tabStr = table2Text(app, app.UITable, dataFormat);

            if isempty(bkg)
                results = 0;
                disp('background data not ready, load it first!');
                return ;
            end
            rstFn = fullfile(app.sfaTR.resultDir, join([app.sfaTR.sampFn, '_Rg_rst.txt']));
            %fid = fopen(rstFn,'a');
            fid = fopen(rstFn,'w');
            %tabStr = table2Text(app.UITable);
            fprintf(fid, '#\n#\n');
            fprintf(fid, '%s\n',tabStr);
            fprintf(fid, '# file: %s; shotNum: %d; ser#: %s\n', fName, shotNum, num2str(fSerNums));
            fprintf(fid, '#\n# file_name    Rg    RgErr    Io    IoErr\n');
            
            Rgs=[];
            I0s=[];
            %cdir = pwd;
            cdir='';
            for kk = 1:shotNum
                Iqs=[];
                Iqerrs=[];
                shotN = sprintf('%05d', kk);
                fNameSave = fullfile(cdir, save2Folder, [fName '_' shotN '.avg']);
                fNameSaveImg = fullfile(cdir, save2Folder, [fName '_' shotN '.jpg']);
                fnStr='% Files averaged: ';
                for jj = fSerNums
                   serialN = sprintf('%05d', jj);        
                   fn = [fName '_' serialN '_' shotN '.dat'];
                   fn2 = fullfile(cdir, saxsFolder, fn);
                   try
                   mt = readData(fn2);
                   catch ME
                       rethrow(ME);
                   end
                   if ~isempty(mt)
                      q = mt(:,1);
                      Iqs= [Iqs mt(:,2)];
                      Iqerrs=[Iqerrs mt(:,3)];  
                      fnStr = [fnStr ' ' fn];
                   end
                end
                %q = q;
                IqAvg = mean(Iqs')';
                %IqErr = std(Iqs')';
                IqErr = sqrt(sum((Iqs.^2)')')/shotNum;  % 3/11/2025 
                % generate figure for average and individual shots
                %f1=figure(2001);
                %loglog(q,Iqs); xlabel('Q /A'); ylabel('Intensity'); txtStr=sprintf('data: %s_%s', fName, shotN);  title(strrep(txtStr, '_', '\_')); hold on;
                %loglog(q,IqAvg, '-r', bkg(:,1), alpha*bkg(:,2), '-b','Linewidth',2); hold on;
                % hold off; 
                %saveas(gcf, fNameSaveImg);
                %pause(0.1);

                % not use Iqerrs
                dlmwrite(fNameSave, fnStr, 'delimiter', '', 'Precision', '%s');
                dlmwrite(fNameSave, '% ', 'delimiter', '', 'Precision', '%s','-append' );
                dlmwrite(fNameSave, '% q(A^-1)   I(q)    I(q)_SE', 'delimiter', '', 'Precision', '%s', '-append');
                dlmwrite(fNameSave, [q IqAvg IqErr], 'delimiter', ' ', 'precision', '%.4E', '-append');
                if ~isempty(bkg)
                    fNameSave2 = fullfile(cdir, save2Folder, [fName '_' shotN '_bsub.dat']);       
                    iqBsub = IqAvg - alpha * bkg(:,2);
                    iqErrBsub = sqrt(IqErr.^2 + alpha^2 *bkg(:,3).^2);
                    app.sfaTR.Iq = [app.sfaTR.Iq iqBsub];
                    app.sfaTR.IqErr = [app.sfaTR.IqErr iqErrBsub];
                    app.sfaTR.IqKrty = [app.sfaTR.IqKrty iqBsub.*(app.sfaTR.q.^2)];

                    figure(2001);
                    loglog(q,Iqs); xlabel('Q /A'); ylabel('Intensity'); txtStr=sprintf('data: %s_%s', fName, shotN);  title(strrep(txtStr, '_', '\_')); hold on;
                    loglog(q,IqAvg, '-r', bkg(:,1), alpha*bkg(:,2), '-b','Linewidth',2); hold on;                    
                    loglog(q,iqBsub, '-c', 'Linewidth',2); hold off;
                    %pause(0.2)
                    saveas(gcf, fNameSaveImg);
                    pause(1);

                    dlmwrite(fNameSave2, fnStr, 'delimiter', '', 'Precision', '%s');
                    dlmwrite(fNameSave2, ['% bkg: ' bkgFn], 'delimiter', '', 'Precision', '%s','-append' );
                    dlmwrite(fNameSave2, '% ', 'delimiter', '', 'Precision', '%s','-append' );
                    dlmwrite(fNameSave2, '% q(A^-1)   I(q)    I(q)_Err', 'delimiter', '', 'Precision', '%s', '-append')
                    dlmwrite(fNameSave2, [q iqBsub iqErrBsub], 'delimiter', ' ', 'precision', '%.4E', '-append');

                    f1 = loglog(app.UIAxesSFA3, app.sfaTR.q, app.sfaTR.Iq);    axis(app.UIAxesSFA3, 'normal'); axis(app.UIAxesSFA3, 'padded');
                    f2 = plot(app.UIAxesSFA4, app.sfaTR.q, app.sfaTR.IqKrty);  axis(app.UIAxesSFA4, 'normal'); axis(app.UIAxesSFA4, 'padded');
                else                    
                    figure(2001);
                    loglog(q,Iqs); xlabel('Q /A'); ylabel('Intensity'); txtStr=sprintf('data: %s_%s', fName, shotN);  title(strrep(txtStr, '_', '\_')); hold on;
                    loglog(q,IqAvg, '-r', bkg(:,1), alpha*bkg(:,2), '-b','Linewidth',2); hold on;
                    saveas(gcf, fNameSaveImg);
                    pause(0.8);
                end
                rst = calDatRg5(app, fNameSave2);
                if isempty(rst)
                    continue;
                else
                    [~, fn, ext] = fileparts(fNameSave2);
                    Rgs=[Rgs ;[kk rst.Rg rst.RgErr]];
                    %figure(100); 
                    e1=errorbar(app.UIAxesSFA1, Rgs(:,1), Rgs(:,2), Rgs(:,3), '-or'); %e1.Color = "red";
                    axis(app.UIAxesSFA1, 'normal'); axis(app.UIAxesSFA1, 'padded');
                    I0s=[I0s ;[kk rst.Io rst.IoErr]];
                    %figure(200); 
                    e2=errorbar(app.UIAxesSFA2, I0s(:,1), I0s(:,2), I0s(:,3), '-ob'); %e2.Color = "blue";
                    axis(app.UIAxesSFA2, 'normal'); axis(app.UIAxesSFA2, 'padded');
                    fprintf(fid, '%s  %.4E  %.4E  %.4E  %.4E\n', [fn ext], rst.Rg, rst.RgErr, rst.Io, rst.IoErr);
                    disp( [fNameSave ' done!']);
                end
                
            end
            xlim(app.UIAxesSFA4, [0, 0.4]); legend(app.UIAxesSFA4);
            fclose(fid);
        end

        % calculate Rg & IO
        function rst = calDatRg5(app, datFull)            
            tmpLog = 'tmp.log';
            rst={};
            if ispc
                %app.saxs2.atsasPath 
                %Rgcmd= sprintf('C:\\ATSAS\\bin\\autorg "%s" -f ssv > "%s"', datFull, tmpLog); 
                Rgcmd= sprintf('%s\\autorg "%s" -f ssv > "%s"', app.saxs2.atsasPath, datFull, tmpLog); 
                Rgcmd;
                dos(Rgcmd);
            elseif isunix
                pfile = mfilename('fullpath');
                [pdir, ~,~] = fileparts(pfile);
                %Rgcmd= sprintf('%s/atsas/unix/autorg2 "%s" -f ssv > "%s"', pdir, datFull, tmpLog); 
                Rgcmd= sprintf('%s/atsas/unix/autorg2 "%s" -f ssv > "%s"', pdir, datFull, tmpLog); 
                unix(Rgcmd);  
            end
            fid=fopen(tmpLog,'r');
            line = fgetl(fid);
            fclose(fid);
            if length(line)<2
                fprintf("difficulty in processing data %s\n", datFull);
                %continue;
            else
                str=strsplit(line, ' ');
                rst.Rg   =str2num(str{1});
                rst.RgErr=str2num(str{2});
                rst.Io   =str2num(str{3});
                rst.IoErr=str2num(str{4});
                rst.firstPts = str2num(str{5});
                rst.endPts = str2num(str{6});
                rst.Qfit = str2num(str{7});
                
                % set(handles.Rg, 'String', num2str(bioSAXSData.Rg, '%5.2f'));
                % set(handles.Io, 'String', num2str(bioSAXSData.Io, '%0.3e'));
                % set(handles.firstPts, 'String', num2str(bioSAXSData.firstPts));
                % set(handles.endPts, 'String', num2str(bioSAXSData.endPts));
                
                % set(handles.msg, 'String', sprintf('Fit Quality: %5.1f%%.', bioSAXSData.Qfit*100));
                
                pts1 = rst.firstPts;
                pts2 = rst.endPts;
                dat = readData(datFull);
                % if isempty(dat)
                %     dat = readData(datFull);
                % end
                q = dat(:,1);
                iq = dat(:,2);
                iqErr = dat(:,3);
                
                q2 = q(pts1:pts2).^2;
                iqfit = rst.Io * exp(-1*(rst.Rg)^2*q2/3.);
                
                RgQm=q(pts2) * rst.Rg;
                pts3 = max(floor(pts1*0.9),1);
                pts4 = min(ceil(pts2*1.05),length(q));
                q3 = q(pts3:pts4).^2;
                figure(906); 
                h = errorbar(q3,iq(pts3:pts4),iqErr(pts3:pts4),'or');
                set(get(h,'Parent'), 'YScale', 'log'); hold on;
                semilogy(q2,iqfit,'-b', 'LineWidth',3); hold on;
                ylabel('Iq'); xlabel('q^2, A^-2');
                legend('iExp','iFit'); title('Guinier fitting');
                strlab=sprintf('Rg:%5.2f +/- %4.2f\nIo:%0.3E +/- %0.1E\nRg*qmax:%3.2f', rst.Rg, rst.RgErr, rst.Io, rst.IoErr,RgQm);
                TeXString=texlabel(strlab);
                text(median(q2)*1.2,median(iqfit)*1.2,TeXString); 
                hold off;
            end
        end
        
        function results = calculateSFAtimes(app)
            results = 1;
            app.sfaTR.serT =[];
            %app.sfaTR.serT = ((1:app.sfaTR.nShot) -1)* app.sfaTR.prd + app.sfaTR.delay + app.sfaTR.delay2; 
            % yTRtime = mapTRtime(app, Ndelay, prd, expt, nShot, deadtime, duration)

            app.sfaTR.serT = mapTRtime(app, app.sfaTR.delay, app.sfaTR.prd, app.sfaTR.expt, app.sfaTR.nShot, app.sfaTR.deadtime, app.sfaTR.duration);
            app.SFAFirst3timepoints.Value = sprintf('[%.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f]', app.sfaTR.serT(1:10));

            %plot in UIAxesSFA1, first five shots            
            app.TabGroup2.SelectedTab = app.SFATab;
            num = 500;
            xt = (0:num) / num * (8*app.sfaTR.prd);
            yt = xt *0.;
            yt2 = xt *0.;
            for kk=1:length(xt)
                yt(kk) =  mapTRtime1(app, xt(kk), app.sfaTR.delay, app.sfaTR.deadtime, app.sfaTR.duration);

                if mod(xt(kk), app.sfaTR.prd) <= app.sfaTR.expt
                    yt2(kk) = 50;
                end
            end            
            plot(app.UIAxesSFA1, xt, yt, '-r', xt, yt2, '-b'); 
            xlabel(app.UIAxesSFA1, 'Time (ms)'); ylabel(app.UIAxesSFA1, 'TR\_Time (ms)'); 
            title(app.UIAxesSFA1, 'Data Collection Scheme'); legend(app.UIAxesSFA1,{'Time Delay','Data Collection'}, 'Location',"best");
            axis(app.UIAxesSFA1, 'normal'); axis(app.UIAxesSFA1, 'padded');
        end


        % Map single time point
        function yTime = mapTRtime1(app, xt, Ndelay, deadtime, duration)
            % inject duration >> expt ; duration > prd
            % [Ndelay, Ndelay+prd*nshot]
            if xt < abs(Ndelay) + deadtime
                yTime = -50;
            else 
                if xt< (abs(Ndelay) + deadtime + duration)
                    yTime = deadtime;
                else
                    yTime = deadtime + xt - (abs(Ndelay) + deadtime + duration);
                end
            end
        end
        
        % Map the time point of a single measurement
        function yTRtime = mapTRtime(app, Ndelay, prd, expt, nShot, deadtime, duration)
            shots = 1:nShot;
            tStart = (shots-1) *prd;
            tEnd   = tStart + expt;
            %yStart = tStart * 0;
            %yEnd   = yStart;
            yTRtime = tStart * 0;

            for kk=1:nShot
                yT1 = mapTRtime1(app, tStart(kk), Ndelay, deadtime, duration);
                yT2 = mapTRtime1(app, tEnd(kk), Ndelay, deadtime, duration);
                yTRtime(kk) = 0.5*(yT1 + yT2); 
            end
        end
        
        function results = genGNOMInput(app)
            results =1;
            if isempty(app.saxs.saxsFn) || ~exist(app.saxs.saxsFn, 'file')
                fprintf('SAXS data file: %s not exist!!!\n', app.saxs.saxsFn);
                results = 0;
                return;
            end
            fn = 'ans.txt';
            q = app.saxs.saxs(:,1);
            q1 = app.qMin2.Value;
            q2 = app.qMax2.Value;
            alpha = app.alphaPDDF.Value;
            fnfull = fullfile(app.saxs.dataDir,fn);
            [pt1, pt2] = findQrange(q, q1, q2);
            setup = 0;
            systemType =0;
            dmax = app.DmaxSpinner.Value;
            rminZero = 'yes';
            rmaxZero = 'yes';
            numPts  = 100;
            fid=fopen(fnfull, 'w');
            fprintf(fid, 'u\n%s\n',app.saxs.saxsFn);
            fprintf(fid,'%d\n', pt1);
            fprintf(fid,'%d\n', pt2);
            fprintf(fid,'%d\n', setup);
            fprintf(fid,'%d\n', systemType);
            fprintf(fid,'%.1f\n', dmax);
            fprintf(fid,'%s\n', rminZero);
            fprintf(fid,'%s\n', rmaxZero);
            fprintf(fid,'%d\n', numPts);
            fprintf(fid,'%.3f\n', alpha);
            fprintf(fid, '%s\n',fullfile(app.saxs.dataDir, app.saxs.pddfFn));
            fclose(fid);            
        end
        
        function results = runManualGNOM(app)
            results = 1;
            if isempty(app.saxs.saxsFn) || ~exist(app.saxs.saxsFn, 'file')
                fprintf('SAXS data file: %s not exist!!!\n', app.saxs.saxsFn);
                results = 0;
                return;
            end
            gnomCmd = sprintf('%s < %s > tmpG.txt', fullfile(app.saxs.atsasPath, 'gnom'), 'ans.txt');
            dos(gnomCmd);

            [saxs,pddf,Rg,Io] = readGNOM(fullfile(app.saxs.dataDir, app.saxs.pddfFn));

            app.saxs.saxsFit=saxs;
            app.saxs.pddf=pddf;
            app.saxs.RgGnom=Rg;
            dmax = pddf(end,1);
            
            h = findall(0,'type','figure','number', 9110);
            if isempty(h)
                h=figure(9110); h.Name = 'GNOM PDDF';  h.Position = [430 220 560 860];
                h1=subplot(2,1,1); loglog(app.saxs.saxs(:,1), app.saxs.saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
                legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)'); title('GNOM Fit'); axis padded; hold off;
            
                h2=subplot(2,1,2); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',2); axis padded; hold on; 
                plot(h2.XLim, [0 0], '-.', 'LineWidth',1); set(h2, 'XLim', [0 dmax]);
                legend('PDDF'); xlabel('r, A');ylabel('p(r)'); title('PDDF');  hold off;
            else
                figure(h);  %h.Position = [430 220 560 860];
                h1=subplot(2,1,1, 'replace'); loglog(app.saxs.saxs(:,1), app.saxs.saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
                legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)'); title('GNOM Fit'); axis padded;  hold off;
            
                h2=subplot(2,1,2, 'replace'); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',2); axis padded; hold on; 
                plot(h2.XLim, [0 0], '-.', 'LineWidth',1);
                set(h2, 'XLim',[0 dmax]);
                legend('PDDF');xlabel('r, A');ylabel('p(r)'); title('PDDF');  hold off;
            end
            saveas(gcf, [app.saxs.pddfFn(1:end-4) '_autopddf.jpg']);

            
        end
        
        function results = runGNOM1(app)
            results = 1;
            try
                genGNOMInput(app);
                runManualGNOM(app);
            catch
                disp('something wrong!');
            end
            
        end
        
        function results = saveSAXSReport(app)
            results = 1;
            reportfn = app.SAXSReportEditField.Value;
            
        end
        
        function results = saveSECReport(app)
            results = 1;
            
        end

        %%%===================================================================================================================
        %   7. General functions 
        %%%===================================================================================================================
        
        function LeftTabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
        
            % Check which tab on the left is selected
            switch selectedTab
                case app.BackSubTab
                    app.TabGroup2.SelectedTab = app.SAXSPlotTab;
                case app.AnalysisTab
                    app.TabGroup2.SelectedTab = app.AnalysisPlotTab;
                case app.SECTab
                    app.TabGroup2.SelectedTab = app.SECPlotTab;
                case app.TRSAXSTab
                    app.TabGroup2.SelectedTab = app.SFATab;
                case app.BackSubTab
                    app.TabGroup2.SAXSWAXSTab = app.SAXSPlotTab;
                case app.AveragingTab2
                    app.TabGroup2.SelectedTab = app.PlotsTab;    
                % etc.
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        % initialize UITable:    
        app.UITable.ColumnName = {'Sample'; 'Phase1'; 'Phase2'; 'Phase3'; 'Phase4'};
        app.UITable.RowName = {'Time(ms)'; 'Syr1(uL)'; 'Syr2(uL)';  'Syr3(uL)'; 'Syr4(uL)'; 'Synchro'; 'Speed(mL/s)'};
        app.UITable.ColumnWidth = {125, 60, 60, 60, 60};
        myData = {'--'  100 150 0 0; ...
            'denatureBuf' 100 100 0 0 ; ...
            'fdSample' 100 100 0 0 ; ...
            'dnSample' 100 100 0 0 ; ...
            'fdBuff' 100 100 0 0 ; ...
            '--' 'on' 'off' 'off' 'off'; ...
            '--' 4.00 2.67 0 0
            };
        app.sfaTR.deadtime = app.sfaTR.deadVol / app.sfaTR.flowRate;
        
%             app.UITable.ColumnName = {'Syring';'Sample'; 'Phase1'; 'Phase2'; 'Phase3'; 'Phase4'};
%             app.UITable.RowName = {'Time(ms)'; 'Syr1(uL)'; 'Syr2(uL)';  'Syr3(uL)'; 'Syr4(uL)'; 'Synchro'; 'Speed(L/S)'};
%             app.UITable.ColumnWidth = {60,125, 60, 60, 60, 60};
%             myData = {'Time(ms)' '--'  100 150 0 0; ...
%                 'Syr1(mL)' 'denatureBuf' 100 100 0 0 ; ...
%                 'Syr2(mL)' 'fdSample' 100 100 0 0 ; ...
%                 'Syr3(mL)' 'dnSample' 100 100 0 0 ; ...
%                 'Syr4(mL)' 'fdBuff' 100 100 0 0 ; ...
%                 'Synchro' '--' 'on' 'off' 'off' 'off'; ...
%                 'Speed(L/s)' '--' 4.00 2.67 0 0
%                 };
            app.UITable.Data = myData;

        %

        end

        % Button pushed function: SelectS1
        function SelectS1ButtonPushed(app, event)
            selectDataFile(app,'S1');
        end

        % Button pushed function: SelectS2
        function SelectS2ButtonPushed(app, event)
            selectDataFile(app,'S2');
        end

        % Button pushed function: SelectS3
        function SelectS3ButtonPushed(app, event)
            selectDataFile(app,'S3');
        end

        % Button pushed function: SelectS4
        function SelectS4ButtonPushed(app, event)
            selectDataFile(app,'S4');
        end

        % Button pushed function: SelectS5
        function SelectS5ButtonPushed(app, event)
            selectDataFile(app,'S5');
        end

        % Button pushed function: SelectS6
        function SelectS6ButtonPushed(app, event)
            selectDataFile(app,'S6');
        end

        % Button pushed function: SelectB1
        function SelectB1ButtonPushed(app, event)
            selectDataFile(app,'B1');
        end

        % Button pushed function: SelectB2
        function SelectB2ButtonPushed(app, event)
            selectDataFile(app,'B2');
        end

        % Button pushed function: SelectB3
        function SelectB3ButtonPushed(app, event)
            selectDataFile(app,'B3');
        end

        % Button pushed function: SelectB4
        function SelectB4ButtonPushed(app, event)
            selectDataFile(app,'B4');
        end

        % Button pushed function: SelectB5
        function SelectB5ButtonPushed(app, event)
            selectDataFile(app,'B5');
        end

        % Button pushed function: SelectB6
        function SelectB6ButtonPushed(app, event)
            selectDataFile(app,'B6');
        end

        % Value changed function: XLabelEditField
        function XLabelEditFieldValueChanged(app, event)
            %value = app.XLabelEditField.Value;
            app.UIAxes.XLabel.String = app.XLabelEditField.Value; 
        end

        % Value changing function: XLabelEditField_2
        function XLabelEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: XLabelEditField_2
        function XLabelEditField_2ValueChanged(app, event)
            app.UIAxes.YLabel.String = app.XLabelEditField_2.Value;
            
        end

        % Value changed function: TitleLabelEditField
        function TitleLabelEditFieldValueChanged(app, event)
            app.UIAxes.Title.String = app.TitleLabelEditField.Value;
            
        end

        % Value changed function: XlimMin
        function XlimMinValueChanged(app, event)
            app.UIAxes.XLim(1) = app.XlimMin.Value;
            
        end

        % Value changed function: XlimMax
        function XlimMaxValueChanged(app, event)
            app.UIAxes.XLim(2) = app.XlimMax.Value;
            
        end

        % Value changed function: YlimMin
        function YlimMinValueChanged(app, event)
            app.UIAxes.YLim(1) = app.YlimMin.Value;
            
        end

        % Value changed function: YlimMax
        function YlimMaxValueChanged(app, event)
            app.UIAxes.YLim(2) = app.YlimMax.Value;
            
        end

        % Value changed function: PlotOptionDropDown
        function PlotOptionDropDownValueChanged(app, event)
            value = app.PlotOptionDropDown.Value;
            switch lower(value)
                case 'linxy'
                    app.UIAxes.XScale = 'linear';
                    app.UIAxes.YScale = 'linear';
                case 'logx'
                    app.UIAxes.XScale = 'log';
                    app.UIAxes.YScale = 'linear';    
                case 'logy'
                    app.UIAxes.XScale = 'linear';
                    app.UIAxes.YScale = 'log';  
                case 'logxy'
                    app.UIAxes.XScale = 'log';
                    app.UIAxes.YScale = 'log';  
                otherwise
                    disp('Wrong here 2!')
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.XlimMin.Value = app.UIAxes.XLim(1);
            app.XlimMax.Value = app.UIAxes.XLim(2);
            app.YlimMin.Value = app.UIAxes.YLim(1);
            app.YlimMax.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: PlotSelectedDataButton
        function PlotSelectedDataButtonPushed(app, event)
            %cla(app.UIAxes, "reset");
            %axes(app.UIAxes);
            margin = 0.15;
            limits=[];
            kk=1;
%             if ~isempty(app.dataBuf1)
%                 buf = app.dataBuf1;
%                 cLimit = dataRanges(app,buf);
%                 limits = [limits; cLimit];
%                 
%                 fn = app.dataBuf1Fn;
%                 loglog(app.UIAxes, buf(:,1), buf(:,2), '-k', 'LineWidth',2);
%                 legendText{kk}=fn;
%                 kk=kk+1;
%                 hold(app.UIAxes, 'on');
%             end

% buf1 is not default to plot anymore
            % if ~isempty(app.saxs.buf1)
            %     buf = app.saxs.buf1;
            %     cLimit = dataRanges(app,buf);
            %     limits = [limits; cLimit];
            % 
            %     fn = app.saxs.buf1Fn;
            %     loglog(app.UIAxes, buf(:,1), buf(:,2), '-k', 'LineWidth',2);
            %     legendText{kk}= regexprep(fn, '_', '\\_');
            %     kk=kk+1;
            %     hold(app.UIAxes, 'on');
            % end     
            
            lineColor='rbgmcy';
            for jj = 1:6                
                eval(sprintf('chkd = app.B%dCHK.Value;', jj));
                eval(sprintf('buf  = app.saxs.buf%d;', jj));
                eval(sprintf('bufFn = app.saxs.buf%dFn;', jj));
                if chkd && ~isempty(buf)
                    cLimit = dataRanges(app,buf);
                    limits = [limits; cLimit];                    
                    eval(sprintf("loglog(app.UIAxes, buf(:,1), buf(:,2), '-%s', 'LineWidth',1);", lineColor(jj)));
                    legendText{kk} = regexprep(bufFn, '_', '\\_');
                    kk=kk+1;
                    hold(app.UIAxes, 'on');
                end
            end
            
            for jj = 1:6                
                eval(sprintf('chkd = app.saxs.samp%dSel;', jj));
                eval(sprintf('samp = app.saxs.samp%d;', jj));
                eval(sprintf('sampFn = app.saxs.samp%dFn;', jj));
                if chkd && ~isempty(samp)
                    cLimit = dataRanges(app,samp);
                    limits = [limits; cLimit];                    
                    eval(sprintf("loglog(app.UIAxes, samp(:,1), samp(:,2), '-%s', 'LineWidth',2);", lineColor(jj)));
                    legendText{kk} = regexprep(sampFn, '_', '\\_');
                    kk=kk+1;
                    hold(app.UIAxes, 'on');
                end
            end
            
%             if app.dataSamp1Sel==1 && ~isempty(app.dataSamp1)
%                 samp = app.dataSamp1;
%                 cLimit = dataRanges(app,samp);
%                 limits = [limits; cLimit];
% 
%                 fn = app.dataSamp1Fn;
%                 loglog(app.UIAxes, samp(:,1), samp(:,2), '-r', 'LineWidth',2);
%                 legendText{kk}=fn;
%                 kk=kk+1;
%                 hold(app.UIAxes, 'on');
%             end
%             

                        
            if exist('legendText')
                legend(app.UIAxes,legendText);
            end
            plotLimits = combinePlotLimits(app,limits, margin);
            xlim(app.UIAxes,plotLimits(1:2));
            ylim(app.UIAxes,plotLimits(3:4));
            app.XlimMin.Value = plotLimits(1);
            app.XlimMax.Value = plotLimits(2);
            app.YlimMin.Value = plotLimits(3);
            app.YlimMax.Value = plotLimits(4);
            hold(app.UIAxes, 'off');
        end

        % Value changed function: S1CHK
        function S1CHKValueChanged(app, event)
            app.S1CHK2.Value = app.S1CHK.Value;
            app.saxs.samp1Sel = app.S1CHK.Value;
        end

        % Value changed function: S2CHK
        function S2CHKValueChanged(app, event)
            app.S2CHK2.Value = app.S2CHK.Value;
            app.saxs.samp2Sel = app.S2CHK.Value;
        end

        % Value changed function: S3CHK
        function S3CHKValueChanged(app, event)
            app.S3CHK2.Value = app.S3CHK.Value;
            app.saxs.samp3Sel = app.S3CHK.Value;
        end

        % Value changed function: S4CHK
        function S4CHKValueChanged(app, event)
            app.S4CHK2.Value = app.S4CHK.Value;
            app.saxs.samp4Sel = app.S4CHK.Value;
        end

        % Value changed function: S5CHK
        function S5CHKValueChanged(app, event)
            app.S5CHK2.Value = app.S5CHK.Value;
            app.saxs.samp5Sel = app.S5CHK.Value;
        end

        % Value changed function: S6CHK
        function S6CHKValueChanged(app, event)
            app.S6CHK2.Value = app.S6CHK.Value;
            app.saxs.samp6Sel = app.S6CHK.Value;
        end

        % Value changed function: S1CHK2
        function S1CHK2ValueChanged(app, event)
            app.S1CHK.Value = app.S1CHK2.Value;
            
        end

        % Value changed function: S2CHK2
        function S2CHK2ValueChanged(app, event)
            app.S2CHK.Value = app.S2CHK2.Value;
            
        end

        % Value changed function: S3CHK2
        function S3CHK2ValueChanged(app, event)
            app.S3CHK.Value = app.S3CHK2.Value;
            
        end

        % Value changed function: S4CHK2
        function S4CHK2ValueChanged(app, event)
            app.S4CHK.Value = app.S4CHK2.Value;
            
        end

        % Value changed function: S5CHK2
        function S5CHK2ValueChanged(app, event)
            app.S5CHK.Value = app.S5CHK2.Value;
            
        end

        % Value changed function: S6CHK2
        function S6CHK2ValueChanged(app, event)
            app.S6CHK.Value = app.S6CHK2.Value;
            
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            filter = {'*.jpg';'*.tif';'*.png';'*.bmp';'*.*'};
            [file, path] = uiputfile(filter);
            %[file, path] = uiputfile({'*.jpg';'*.tif';'*.png';'*.bmp';'*.*'}, 'SAXS.jpg');
            if file~=0
                figFile = fullfile(path, file);
                exportgraphics(app.UIAxes, figFile);
            end
%             F = getframe(handles.axes1);
%             Image = frame2im(F);
%             imwrite(Image, 'Image.jpg')
        end

        % Button pushed function: SubtractPlotButton
        function SubtractPlotButtonPushed(app, event)
            alpha = app.alphaEditField.Value;
            cla(app.UIAxes, "reset");
            margin = 0.15;
            limits=[];
            kk=1;
            legendText = {};
            numCHK = 0;
            for sampN=1:6
                if eval(sprintf('app.S%dCHK.Value',sampN))
                    numCHK = numCHK + 1;
                end
            end

            for sampN=1:6
                if eval(sprintf('app.S%dCHK.Value',sampN))
                    [climits, kk, legendText] = backsubPlot1Set(app, sampN, alpha, numCHK, kk, legendText);
                    limits = [limits; climits];
                end
            end


            if exist('legendText')
                legend(app.UIAxes,legendText);
            end
            plotLimits = combinePlotLimits(app,limits, margin);
            xlim(app.UIAxes,plotLimits(1:2));
            ylim(app.UIAxes,plotLimits(3:4));
            app.XlimMin.Value = plotLimits(1);
            app.XlimMax.Value = plotLimits(2);
            app.YlimMin.Value = plotLimits(3);
            app.YlimMax.Value = plotLimits(4);
            hold(app.UIAxes, 'off');

        end

        % Button pushed function: SubtractPlotSaveButton
        function SubtractPlotSaveButtonPushed(app, event)
            alpha = app.alphaEditField.Value;
            cla(app.UIAxes, "reset");
            margin = 0.15;
            limits=[];
            kk=1;
            legendText = {};
            numCHK = 0;
            for sampN=1:6
                if eval(sprintf('app.S%dCHK.Value',sampN))
                    numCHK = numCHK + 1;
                end
            end

            for sampN=1:6
                if eval(sprintf('app.S%dCHK.Value',sampN))
                    [climits, kk, legendText] = backsubPlotSave1Set(app, sampN, alpha, numCHK, kk, legendText);
                    limits = [limits; climits];
                end
            end

            if exist('legendText')
                legend(app.UIAxes,legendText);
            end
            plotLimits = combinePlotLimits(app,limits, margin);
            xlim(app.UIAxes,plotLimits(1:2));
            ylim(app.UIAxes,plotLimits(3:4));
            app.XlimMin.Value = plotLimits(1);
            app.XlimMax.Value = plotLimits(2);
            app.YlimMin.Value = plotLimits(3);
            app.YlimMax.Value = plotLimits(4);
            hold(app.UIAxes, 'off');
        end

        % Value changed function: B1CHK
        function B1CHKValueChanged(app, event)
            if app.B1CHK.Value
                setBufferForRest(app, 1); % add code here
            end
            
        end

        % Button pushed function: FitalphaBetaConcExtroplateButton
        function FitalphaBetaConcExtroplateButtonPushed(app, event)
            indx=[0 0 0 0 0 0]; % indication of selections
            conc=[];
            Iq_buf=[];  std_buf=[];
            Iq_samp=[]; std_samp=[];          
            
            a0=[]; aLB=[]; aUB=[];
            b0=[]; bLB=[]; bUB=[];
            
            eval(sprintf('alphaLB = app.alphaLB.Value;'));
            eval(sprintf('alphaUB = app.alphaUB.Value;'));
            eval(sprintf('betaLB = app.betaLB.Value;'));
            eval(sprintf('betaUB = app.betaUB.Value;'));
            app.saxs.qMin = app.qMin.Value;
            app.saxs.qMax = app.qMax.Value;
            fileStr = '';

            for sampN=1:6
                if eval(sprintf('app.S%dCHK.Value',sampN))
                    
                    eval(sprintf('buf = app.saxs.buf%d;', sampN));   
                    eval(sprintf('samp = app.saxs.samp%d;', sampN));
                    eval(sprintf('sampConc = app.saxs.samp%dConc;', sampN));

                    
                    eval(sprintf('alpha1  = app.alpha%d.Value;', sampN));
                    eval(sprintf('beta1  = app.beta%d.Value;', sampN));
                    
                    eval(sprintf('bufFn = app.saxs.buf%dFn;', sampN));
                    eval(sprintf('sampFn = app.saxs.samp%dFn;', sampN));
                    fileStr = sprintf('%s%%  %s, %s\n',fileStr, sampFn, bufFn);
                    %fileStr =fileStr+'% '+sampFn+', '+ bufFn +'\n';
                    %fileStr =fileStr+sampFn+', '+ bufFn +'\n';

                    if ~isempty(buf) && ~isempty(samp)
                        indx(sampN) = 1;
                        q_mt = samp(:,1);
                        
                        Iq_buf  = [Iq_buf  buf(:,2)];
                        Iq_samp = [Iq_samp samp(:,2)];
                        std_buf  = [std_buf  buf(:,3)];
                        std_samp = [std_samp samp(:,3)];  
                        conc  = [conc sampConc];
                        aLB = [aLB alphaLB];
                        aUB = [aUB alphaUB];
                        a0  = [a0  alpha1];
                        
                        bLB = [bLB betaLB];
                        bUB = [bUB betaUB];
                        b0  = [b0 beta1];
                    end
                    
                end
            end    
            
            if ~isempty(Iq_samp)
                [lowQ, highQ] = findQrange(q_mt, app.saxs.qMin, app.saxs.qMax);
                
                datN = length(conc);
                beta0 = conc(end)./conc; 
                
                betaLb = beta0 .* bLB;
                betaLb = [betaLb(1:end-1) 1.0];
                
                betaUb = beta0 .* bUB;
                betaUb = [betaUb(1:end-1) 1.0];
               
                xLb = [aLB betaLb]; % lower limits
                xUb = [aUB betaUb]; % upper limits
                
                x0= [a0 b0.*beta0]; % initials
                
                
                % optimization on alpha and beta
                options = optimset('Display','iter','TolFun',1e-8, 'MaxFunEvals',1e12, 'MaxIter', 1e8);
                xval=[]; fval = 0.;
                for jj=1:5
                    x0b = x0 .* (1+0.2*(rand(1,6)-0.5)); % randmize initial values by +/- 10% 
                    [xval1, fval1] = fminsearchcon(@(x) simultSAXSbacksubFit(x, Iq_buf, Iq_samp, std_buf, std_samp, lowQ, highQ), x0b, xLb, xUb);
                    if jj == 1
                        xval = xval1; fval = fval1;
                    else
                        if fval1 < fval
                            fval = fval1; 
                            xval = xval1;
                        end
                    end
                end

                %[xval, fval] = fminsearchcon(@(x) simultSAXSbacksubtraction5(x, Iq_buf, Iq_samp, std_buf, std_samp, lowQ, highQ), x0, xLb, xUb)
                %[xval, fval, exitFlag, output] = fminsearchcon(@(x) simultSAXSbacksubtraction(x, Iq_mt, std_mt, lowQ, highQ), x0, xLb, xUb)
                
                %after optimized
                alpha = xval(1:datN);
                beta = xval(datN+1:end);
            
                %%% here here
                beta2 = 1./beta;
                betaRatio = beta2 / beta2(1);
                betaRatioStr = num2str(betaRatio, '%5.2f');
                betaRatioStr2  = regexprep(betaRatioStr, ' ', ' : ');
                app.ConcRatioStr.Text = betaRatioStr2;
                
                alphaMtx = diag(alpha);
                betaMtx  = diag(beta);
                
                Iq3 = (Iq_samp - Iq_buf * alphaMtx ) * betaMtx;
                std3 = (sqrt(std_samp.^2 + std_buf.^2 * alphaMtx.^2))*betaMtx; 
                
                dIq3 = Iq3(:,1:end-1) - Iq3(:,end)*ones(1,datN-1);
                
                % calculate fitting errors
                % 1. fitting error: errFit
                mt3 = (dIq3(lowQ:highQ,:) ./ std3(lowQ:highQ,1:end-1)).^2;
                errFit = sum(sum(mt3, 'omitnan')); 
                % 2. negative values, errNeg
                nNum = 0;
                for kk=1:datN
                    nNum = nNum + countNegative(Iq3(:,kk),lowQ, highQ);
                end
                errNeg = nNum * 10.0;
                % 3. non-desending alpha values
                nNum2 = 0;
                for kk=1:datN-1
                    if (xval(kk+1)-xval(kk))*(xval(datN+kk+1) - xval(datN+kk)) <= 0  % alpha should descend
                        nNum2 = nNum2 + 1;
                    end
                end
                errAlpha = nNum2 * 100;

                err3 = errFit + errAlpha + errNeg;
                app.saxs.fitErr = [err3 errFit nNum nNum2];
                %set(handles.msg, 'String', sprintf('fitting error: %.2f',err3));
                fittingErrStr = sprintf('%.2f (%.2f, %d, %d).',err3, errFit, nNum, nNum2);
                app.FittingMsgLabel.Text =  fittingErrStr;
                
                % updata alpha, beta & bsub values
                jj=1;
                maxKK=0;
                legendText={}; limits=[];
                for kk=1:6
                    if indx(kk)==1  
                        eval(sprintf('app.alpha%d.Value = alpha(jj);', kk));
                        eval(sprintf('app.beta%d.Value  = beta(jj);', kk));
                        
                        data = [q_mt Iq3(:,jj) std3(:,jj)];
                        eval(sprintf('app.saxs.samp%dBsub=[q_mt Iq3(:,jj) std3(:,jj)];',kk));
                        eval(sprintf('app.saxs.samp%dAlpha=alpha(jj);',kk));
                        eval(sprintf('app.saxs.samp%dBeta=beta(jj);',kk));
                        eval(sprintf('cLegend = app.saxs.samp%dFn;',kk)); 

                        datN = kk; 
                        [climits, jj, legendText] = plot1Set(app, app.UIAxes, data, datN, jj, cLegend, legendText);  % jj = jj +1 in the function
                        %[climits, kk, legendText] = plot1Set(app, ax, data, datN, kk, cLegend, legendText)
                        limits = [limits; climits];

                        %legendStr = ['sample#' num2str(kk)];
                        %legendText{jj}=legendStr;
                        maxKK=kk;
                        %jj=jj+1;
                    end
                end
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % saxs profile extraploation against concentrations 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                iqInt = concIntraplate(Iq3, std3, conc)';
                %legendText{jj}='zeroConc';
                
%                 figure(904);loglog(q_mt,Iq3, 'LineWidth',1.5);
%                 hold on
%                 loglog(q_mt, iqInt, 'm', 'LineWidth', 2);
%                 hold on;
%                 title('Background Subtraction and Scaling'); xlabel('q, /A'); ylabel('I(q)'); legend(legendText);
%                 hold off
                
                app.saxs.zeroConc  = [q_mt  iqInt std3(:,end)];
                cLegend = 'zeroConc';
                [climits, jj, legendText] = plot1Set(app, app.UIAxes, app.saxs.zeroConc, 7, jj, cLegend, legendText, 2);               
                limits = [limits; climits];

                if exist('legendText')
                    legend(app.UIAxes,legendText);
                end
                margin = 0.15;
                plotLimits = combinePlotLimits(app,limits, margin);
                xlim(app.UIAxes,plotLimits(1:2));
                ylim(app.UIAxes,plotLimits(3:4));
                app.XlimMin.Value = plotLimits(1);
                app.XlimMax.Value = plotLimits(2);
                app.YlimMin.Value = plotLimits(3);
                app.YlimMax.Value = plotLimits(4);
                hold(app.UIAxes, 'off');

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % prepare file names for further processing 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %set(handles.popupmenu2, 'Value', maxKK);
                app.mBsubDropDown.Value = app.mBsubDropDown.Items(maxKK);
                eval(sprintf('app.saxs.mBsub=app.saxs.samp%dBsub;',maxKK));
                
                eval(sprintf('mBsubFn=app.saxs.samp%dFn;',maxKK));
                cFn = char(mBsubFn); % "string" to 'char'
                app.saxs.zConcFn = [cFn(1:end-4) '_zConc.dat'];
                app.saxs.mZeroConcFn = [cFn(1:end-4) '_mzConc.dat'];
                app.mZeroConcFn.Value = app.saxs.mZeroConcFn;
                app.SAXSFn.Value = app.saxs.mZeroConcFn;
                %set(handles.mZeroConcDat, 'String', fn);

                zConcFullFn = fullfile(app.saxs.dataDir, app.saxs.zConcFn);
                
                fid = fopen(zConcFullFn, 'w');
                fprintf(fid, '%% The zero Conc data were extroplated from files:\n' );
                fprintf(fid, '%s' , fileStr );
                fprintf(fid, '%%\n%% q      I(q)       I(q)_err\n' );
                for mm=1:length(app.saxs.zeroConc)
                    fprintf(fid, '%.5E  %.5E  %.4E \n' ,  app.saxs.zeroConc(mm,1), app.saxs.zeroConc(mm,2), app.saxs.zeroConc(mm,3));
                end
                fclose(fid);

                app.saxs.gtotFn = [cFn(1:end-4) '_gtot.dat'];
                app.gtotFn.Value = app.saxs.gtotFn;

            else
                fprintf('No Data !!!');
            end
        end

        % Value changed function: B2CHK
        function B2CHKValueChanged(app, event)
            if app.B2CHK.Value
                setBufferForRest(app, 2); % add code here
            end                       
        end

        % Value changed function: B3CHK
        function B3CHKValueChanged(app, event)
            if app.B3CHK.Value
                setBufferForRest(app, 3); 
            end            
            
        end

        % Value changed function: B4CHK
        function B4CHKValueChanged(app, event)
            if app.B4CHK.Value
                setBufferForRest(app, 4); 
            end 
        end

        % Value changed function: B5CHK
        function B5CHKValueChanged(app, event)
            if app.B5CHK.Value
                setBufferForRest(app, 5); 
            end 
        end

        % Value changed function: B6CHK
        function B6CHKValueChanged(app, event)
            value = app.B6CHK.Value;
         
        end

        % Button pushed function: Select1DSAXSFolderButton_2
        function Select1DSAXSFolderButton_2Pushed(app, event)
            if isempty(app.sec.dataDir)
                cDir =pwd;
            else
                cDir  = app.sec.dataDir;
            end
            path = uigetdir(cDir);
            if path ~= 0
                app.sec.dataDir = path;
                app.SECDataFolder.Value = path; 
                %app.sec.dataDir = path;
            end
        end

        % Button pushed function: SelectResultsFolderButton
        function SelectResultsFolderButtonPushed(app, event)
            if isempty(app.sec.resultDir)
                cDir = pwd;
            else
                cDir = app.sec.resultDir;
            end
            path = uigetdir(cDir);
            if path ~= 0
                %app.secResultDir = path;
                app.SECResultFolder.Value = path; 
                app.sec.resultDir = path;
            end
        end

        % Button pushed function: RunSAXSIntergrationButton
        function RunSAXSIntergrationButtonPushed(app, event)
            app.TabGroup2.SelectedTab = app.SECPlotTab;
            
            app.sec.qLow = app.SEC_qLow.Value;
            app.sec.qHigh = app.SEC_qHigh.Value;
            app.sec.cutoffLow = app.SEC_AreaCutoffLow.Value;
            app.sec.cutoffHigh = app.SEC_AreaCutoffHigh.Value;
            app.sec.serNum = app.SEC_DataSerialNum.Value;
            qRange = [app.sec.qLow app.sec.qHigh];
            areaCutoff = [app.sec.cutoffLow app.sec.cutoffHigh];
            [goodScans, area, outliers, missings, fntemp]=secIqAreaVsN(app, app.sec.dataDir, app.sec.resultDir, app.sec.serNum, qRange, areaCutoff);
            try
                app.sec.goodScans = goodScans;
                app.sec.iqAreas = area;
                app.sec.fnTemp = fntemp;
                app.sec.outliers = outliers;
                app.missings = missings;
            catch
                disp('some field missing.')
            end
            

        end

        % Button pushed function: AveragingasBkgSAXSButton
        function AveragingasBkgSAXSButtonPushed(app, event)
            saxsFolder = app.sec.dataDir;
            fntemp = app.sec.fnTemp;
            resultFolder = app.sec.resultDir;
            fileTag = app.BkgFileTag.Value;
            numLow = app.BkgSerNumLow.Value;
            numHigh = app.BkgSerNumHigh.Value;

            frameRange = [numLow numHigh];
            goodScans = app.sec.goodScans;            
            
            avgSECsaxsScans(app, saxsFolder, fntemp, frameRange, resultFolder, fileTag, goodScans)
            %avgSECsaxsScans(saxsFolder, serialNum, [350 450], 'Processed','bkg1', goodScans);
        end

        % Button pushed function: AveragingasDataSAXSButton
        function AveragingasDataSAXSButtonPushed(app, event)
            saxsFolder = app.sec.dataDir;
            fntemp = app.sec.fnTemp;
            resultFolder = app.sec.resultDir;
            fileTag = app.DataFileTag.Value;
            numLow = app.DataSerNumLow.Value;
            numHigh = app.DataSerNumHigh.Value;

            frameRange = [numLow numHigh];
            goodScans = app.sec.goodScans;

            avgSECsaxsScans(app, saxsFolder, fntemp, frameRange, resultFolder, fileTag, goodScans)
        end

        % Button pushed function: SaveSECSAXSMetaResultsButton
        function SaveSECSAXSMetaResultsButtonPushed(app, event)
            secSAXS=app.sec;
            metaInfoFn = join([app.sec.fnTemp, '_secSAXS_metaInfo.mat'], '' );
            [fileName, pathName] = uiputfile('*.mat', 'File Selection', ...
                         fullfile(app.sec.resultDir, metaInfoFn));
            if ~isequal(fileName, 0)
                save(fullfile(pathName, fileName), 'secSAXS');
            end
        end

        % Button pushed function: LoadSECSAXSMetaResultsButton
        function LoadSECSAXSMetaResultsButtonPushed(app, event)
%             [filename, pathname] = uigetfile( ...
%        {'*.mat', 'Select SEC Meta Data', }, ...
%         'Open as');
            if isempty(app.sec.resultDir)
                app.sec.resultDir = pwd;
            end
            [fileName, pathName] = uigetfile('*.mat; *.*', 'File Selection', fullfile(app.sec.resultDir, '\'));
            if ~isequal(fileName, 0)
                s=load(fullfile(pathName, fileName), 'secSAXS');
            
            app.sec = s.secSAXS;
            app.SECResultFolder.Value = app.sec.resultDir;
            app.SECDataFolder.Value = app.sec.dataDir;
            app.SEC_qLow.Value = app.sec.qLow;
            app.SEC_qHigh.Value = app.sec.qHigh;
            app.SEC_AreaCutoffLow.Value  = app.sec.cutoffLow;
            app.SEC_AreaCutoffHigh.Value = app.sec.cutoffHigh;
            app.SEC_DataSerialNum.Value  = app.sec.serNum;
            end
        end

        % Button pushed function: PlotSer_vs_SAXSButton
        function PlotSer_vs_SAXSButtonPushed(app, event)
            f=figure(100); clf(f);
            app.TabGroup2.SelectedTab = app.SECPlotTab;
            plot(app.sec.goodScans, app.sec.iqAreas, '-or'); xlabel('Data Serial#'); ylabel('SAXS Curve Area Integration');
        end

        % Button pushed function: LoadFPLCElutionFileButton
        function LoadFPLCElutionFileButtonPushed(app, event)
            if isempty(app.sec.resultDir)
                app.sec.resultDir = pwd;
            end
            [fileName, pathName] = uigetfile('*.asc; *.*', 'File Selection', fullfile(app.sec.resultDir, '\'));
            if ~isequal(fileName, 0)
                mt=readData(fullfile(pathName, fileName), 3);  % consider if different lengths

                % always plot the first curve
                fplcCurveStyle = app.SEC_FPLCcurveStyle.Value;
                yyaxis(app.SEC2Axes, 'left');
                eval(sprintf('ax1=plot(app.SEC2Axes, mt(:,1), mt(:,2), %s);',fplcCurveStyle));
                axis(app.SEC2Axes, 'padded');
            end
        end

        % Value changed function: XlimMin_2
        function XlimMin_2ValueChanged(app, event)
            xMin = app.XlimMin_2.Value;
            x =xlim(app.SEC2Axes);
            xlim(app.SEC2Axes, [xMin, x(2)]);
        end

        % Value changed function: XlimMax_2
        function XlimMax_2ValueChanged(app, event)
            xMax = app.XlimMax_2.Value;
            x =xlim(app.SEC2Axes);
            xlim(app.SEC2Axes, [x(1), xMax]);
        end

        % Value changed function: YlimMin_2
        function YlimMin_2ValueChanged(app, event)
            yMin = app.YlimMin_2.Value;
            yyaxis(app.SEC2Axes, 'left');
            y = ylim(app.SEC2Axes);
            ylim(app.SEC2Axes, [yMin, y(2)]);
        end

        % Value changed function: YlimMax_2
        function YlimMax_2ValueChanged(app, event)
            yMax = app.YlimMax_2.Value;
            yyaxis(app.SEC2Axes, 'left');
            y = ylim(app.SEC2Axes);
            ylim(app.SEC2Axes, [ y(1), yMax]);
            
        end

        % Button pushed function: LoadSAXS_vs_SerFileButton_2
        function LoadSAXS_vs_SerFileButton_2Pushed(app, event)
            if isempty(app.sec.resultDir)
                app.sec.resultDir = pwd;
            end
            [fileName, pathName] = uigetfile('*.dat; *.*', 'File Selection', fullfile(app.sec.resultDir, '\'));
            if ~isequal(fileName, 0)
                mt=readData(fullfile(pathName, fileName), 3);  % consider if different lengths
                app.sec.scanInPlot = mt(:,1);
                app.sec.iqAreaInPlot = mt(:,2);
                plotSAXSSeries(app);

%                 saxsCurveStyle = app.SEC_SAXSCurveStyle.Value;
%                 saxsTimeDelay = app.SEC_saxsTimeDelay.Value; % sec
%                 saxsPeriod = app.SEC_saxsPeriod.Value;  % sec
%                 fplcFlowRate = app.SEC_fplcFlowRate.Value; % mL/mim
%                 vol = (mt(:,1) * saxsPeriod + saxsTimeDelay ) * fplcFlowRate / 60.0;
%                 yyaxis(app.SEC2Axes, 'right');
%                 eval(sprintf('ax1=plot(app.SEC2Axes, vol, mt(:,2), %s);',saxsCurveStyle));
                axis(app.SEC2Axes, 'padded');
            end
            
        end

        % Button pushed function: PlotSAXS_vs_SerButton
        function PlotSAXS_vs_SerButtonPushed(app, event)
            if ~isempty(app.sec.goodScans)
                app.sec.scanInPlot = app.sec.goodScans;
                app.sec.iqAreaInPlot = app.sec.iqAreas;
                plotSAXSSeries(app);
                axis(app.SEC2Axes, 'padded');
            end
        end

        % Value changed function: SEC_saxsTimeDelay
        function SEC_saxsTimeDelayValueChanged(app, event)
            value = app.SEC_saxsTimeDelay.Value;
            if ~isempty(app.sec.scanInPlot)
                plotSAXSSeries(app);
            end
        end

        % Value changed function: SEC_saxsPeriod
        function SEC_saxsPeriodValueChanged(app, event)
            value = app.SEC_saxsPeriod.Value;
            if ~isempty(app.sec.scanInPlot)
                plotSAXSSeries(app);
            end            
        end

        % Value changed function: YlimMin_3
        function YlimMin_3ValueChanged(app, event)
            yMin = app.YlimMin_3.Value;
            yyaxis(app.SEC2Axes, 'right');
            y = ylim(app.SEC2Axes);
            ylim(app.SEC2Axes, [yMin y(2)]);           
        end

        % Value changed function: YlimMax_3
        function YlimMax_3ValueChanged(app, event)
            yMax = app.YlimMax_3.Value;
            yyaxis(app.SEC2Axes, 'right');
            y = ylim(app.SEC2Axes);
            ylim(app.SEC2Axes, [ y(1) yMax]);              
        end

        % Value changed function: SEC_fplcFlowRate
        function SEC_fplcFlowRateValueChanged(app, event)
            value = app.SEC_fplcFlowRate.Value;
            if ~isempty(app.sec.scanInPlot)
                plotSAXSSeries(app);
            end             
        end

        % Value changed function: SEC_SAXSCurveStyle
        function SEC_SAXSCurveStyleValueChanged(app, event)
            value = app.SEC_SAXSCurveStyle.Value;
            if ~isempty(app.sec.scanInPlot)
                plotSAXSSeries(app);
            end               
        end

        % Value changed function: LeftYLabelEditField
        function LeftYLabelEditFieldValueChanged(app, event)
            value = app.LeftYLabelEditField.Value;
            yyaxis(app.SEC2Axes, 'left');
            ylabel(app.SEC2Axes, value);
        end

        % Value changed function: RightYLabelEditField
        function RightYLabelEditFieldValueChanged(app, event)
            value = app.RightYLabelEditField.Value;
             yyaxis(app.SEC2Axes, 'right');
            ylabel(app.SEC2Axes, value);           
        end

        % Value changed function: S1Conc
        function S1ConcValueChanged(app, event)
            value = app.S1Conc.Value;
            app.saxs.samp1Conc = value;
        end

        % Value changed function: S2Conc
        function S2ConcValueChanged(app, event)
            value = app.S2Conc.Value;
            app.saxs.samp2Conc = value;
        end

        % Value changed function: S3Conc
        function S3ConcValueChanged(app, event)
            value = app.S3Conc.Value;
            app.saxs.samp3Conc = value;
        end

        % Value changed function: S4Conc
        function S4ConcValueChanged(app, event)
            value = app.S4Conc.Value;
            app.saxs.samp4Conc = value;
        end

        % Value changed function: S5Conc
        function S5ConcValueChanged(app, event)
            value = app.S5Conc.Value;
            app.saxs.samp5Conc = value;
        end

        % Value changed function: S6Conc
        function S6ConcValueChanged(app, event)
            value = app.S6Conc.Value;
            app.saxs.samp6Conc = value;
        end

        % Value changed function: mBsubDropDown
        function mBsubDropDownValueChanged(app, event)
            value = app.mBsubDropDown.Value;
             
        end

        % Value changed function: qMergeEditField
        function qMergeEditFieldValueChanged(app, event)
            app.saxs.qMerge  = app.qMergeEditField.Value;
             
        end

        % Button pushed function: MergeZeroConcFileButton
        function MergeZeroConcFileButtonPushed(app, event)
            if ~isempty(app.saxs.zeroConc)
                dat1 = app.saxs.zeroConc;
            else
                disp('zeroConcDat was not available!');
                return;
            end

            if ~isempty(app.saxs.mBsub)
                dat2 = app.saxs.mBsub;
            else
                disp('mBsub data was not specified!');
                return;
            end

            q= dat1(:,1);
            [lPts, uPts] = findQrange(q, 0,app.saxs.qMerge);
            newIq = [dat1(1:uPts,2);dat2(uPts+1:end,2)];
            
            dat3= [q newIq dat2(:,3)];
            
            %figure(905);
            loglog(app.UIAxes, q,dat1(:,2), '-or', q,dat2(:,2), '-b', q,newIq, '-g','LineWidth',2);
            legend(app.UIAxes, {'zConc', 'mBsub', 'mzConc'});     
            saveas(gcf, [app.saxs.mZeroConcFn(1:end-3) 'jpg']); 
            app.saxs.mZeroConc = dat3;     
            app.saxs.saxs = app.saxs.mZeroConc; % default values for saxs.saxs
        end

        % Callback function
        function SaveMergedFileButtonPushed(app, event)
            if ~isempty(app.saxs.mZeroConc) && ~isempty(app.saxs.mZeroConcFn)
                fullfn = fullfile(app.saxs.dataDir, app.saxs.mZeroConcFn);
                dlmwrite(fullfn, app.saxs.mZeroConc, 'delimiter', ' ','precision', '%.4E');
            else
                disp('merge data not ready!');
                return;
            end
        end

        % Value changed function: mZeroConcFn
        function mZeroConcFnValueChanged(app, event)
            app.saxs.mZeroConcFn = app.mZeroConcFn.Value;
            
        end

        % Button pushed function: SetATSASbinPathButton
        function SetATSASbinPathButtonPushed(app, event)
            atsasPath = uigetdir('C:\');
            if atsasPath
                fprintf('Selected ATSAS/bin Path: %s\n', atsasPath);
                app.saxs2.atsasPath = atsasPath;
                app.PathAtsasbinLabel.Text = atsasPath;
            else
                fprintf('No ATSAS/bin Path Selected, Default path is: %s\n', app.saxs2.atsasPath);
            end
        end

        % Button pushed function: SaveBackSubsButton
        function SaveBackSubsButtonPushed(app, event)
            fpath = app.saxs.dataDir;
            for kk=1:6
                eval(sprintf('chkd = app.saxs.samp%dSel;', kk)); 
                eval(sprintf('bSub = app.saxs.samp%dBsub;',kk));
                if chkd==1 && ~isempty(bSub)
                    eval(sprintf('sampFn = app.saxs.samp%dFn;',kk));
                    eval(sprintf('bufFn = app.saxs.buf%dFn;',kk));
                    eval(sprintf('beta1 = app.saxs.samp%dBeta;',kk));
                    eval(sprintf('alpha1 = app.saxs.samp%dAlpha;',kk));
                    str1 = strsplit(sampFn, '.');
                    nfName = [str1{1} '_BSub2.dat'];
                    newFullfile=fullfile(fpath,nfName);
                    %dlmwrite(newFullfile, sampBSub, ' ');
                    fid = fopen(newFullfile, 'w');
                    nn = length(bSub);
                    fprintf(fid, '%% File name: %s\n', newFullfile);                    
                    fprintf(fid, '%% Sample file: %s\n', sampFn);
                    fprintf(fid, '%% Buffer file: %s\n', bufFn);
                    fprintf(fid, '%% alpha = %7.5f, beta = %7.4f \n', alpha1, beta1);
                    fprintf(fid, '%%\n%% q      I(q)      I(q)_err\n');
                    %fprintf(fid, '%.5E   %.5E   %.5E\n', bSub);
                    for jj=1:nn
                        fprintf(fid, '%.5E   %.5E   %.5E\n', bSub(jj,:));
                    end
                    fclose(fid);
                end
            end
        end

        % Button pushed function: SaveBackSubDataParametersButton
        function SaveBackSubDataParametersButtonPushed(app, event)
            bsubSAXS=app.saxs;
            metaInfoFn = join(['Samp', '_bsub_metaInfo.mat'], '' );
            [fileName, pathName] = uiputfile('*.mat', 'File Selection', ...
                         fullfile(app.saxs.dataDir, metaInfoFn));
            if ~isequal(fileName, 0)
                save(fullfile(pathName, fileName), 'bsubSAXS');
            end
        end

        % Button pushed function: LoadBackSubDataButton
        function LoadBackSubDataButtonPushed(app, event)
            if isempty(app.saxs.dataDir)
                app.saxs.dataDir = pwd;
            end
            [fileName, pathName] = uigetfile('*.mat; *.*', 'File Selection', fullfile(app.saxs.dataDir, '\'));
            if ~isequal(fileName, 0)
                s=load(fullfile(pathName, fileName), 'bsubSAXS');            
                app.saxs = s.bsubSAXS;
                for kk=1:6
                    eval(sprintf('app.S%dFileName.Value = app.saxs.samp%dFn;', kk, kk));
                    eval(sprintf('app.S%dConc.Value = app.saxs.samp%dConc;', kk, kk));
                    eval(sprintf('app.S%dCHK.Value = app.saxs.samp%dSel;', kk, kk));
                    eval(sprintf('app.B%dFileName.Value = app.saxs.buf%dFn;', kk, kk));
                    eval(sprintf('app.alpha%d.Value = app.saxs.samp%dAlpha;', kk, kk));
                    eval(sprintf('app.beta%d.Value = app.saxs.samp%dBeta;', kk, kk));
                end
                app.alphaEditField.Value = app.saxs.alpha;
                app.qMin.Value = app.saxs.qMin;
                app.qMax.Value = app.saxs.qMax;
                app.alphaLB.Value = app.saxs.alphaLB;
                app.alphaUB.Value = app.saxs.alphaUB;
                app.betaLB.Value = app.saxs.betaLB;
                app.betaUB.Value = app.saxs.betaUB;
            end
        end

        % Button pushed function: ResetBackSubDataButton
        function ResetBackSubDataButtonPushed(app, event)
             for kk=1:6
                eval(sprintf('app.saxs.samp%dFn = "sample%d.dat";', kk, kk));                
                eval(sprintf('app.saxs.samp%dConc = %.1f;', kk, kk));
                eval(sprintf('app.saxs.samp%dBsubFn = "";', kk)); 
                eval(sprintf('app.saxs.samp%dSel = 0;', kk));
                eval(sprintf('app.S%dFileName.Value = app.saxs.samp%dFn;', kk, kk));
                eval(sprintf('app.saxs.buf%dFn = "buffer%d.dat";', kk, kk));
                eval(sprintf('app.saxs.samp%d = [];', kk, kk));
                eval(sprintf('app.saxs.buf%d = [];', kk, kk));
                eval(sprintf('app.saxs.samp%dBsub = [];', kk, kk));
                eval(sprintf('app.saxs.samp%dAlpha = 1.0;', kk));
                eval(sprintf('app.saxs.samp%dBeta = 1.0;', kk));                

                eval(sprintf('app.S%dConc.Value = app.saxs.samp%dConc;', kk, kk));
                eval(sprintf('app.S%dCHK.Value = app.saxs.samp%dSel;', kk, kk));
                eval(sprintf('app.B%dFileName.Value = app.saxs.buf%dFn;', kk, kk));
                eval(sprintf('app.alpha%d.Value = app.saxs.samp%dAlpha;', kk, kk));
                eval(sprintf('app.beta%d.Value = app.saxs.samp%dBeta;', kk, kk));
             end
             app.saxs.alpha = 1.0;
             app.saxs.qMin = 0.08;
             app.saxs.qMax = 0.60; 
             app.saxs.alphaLB = 0.98;
             app.saxs.alphaUB = 1.00;
             app.saxs.betaLB = 0.75;
             app.saxs.betaUB = 1.50;
            app.alphaEditField.Value = app.saxs.alpha;
            app.qMin.Value = app.saxs.qMin;
            app.qMax.Value = app.saxs.qMax;
            app.alphaLB.Value = app.saxs.alphaLB;
            app.alphaUB.Value = app.saxs.alphaUB;
            app.betaLB.Value = app.saxs.betaLB;
            app.betaUB.Value = app.saxs.betaUB;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            %app.UITable.RowName = {};
        end

        % Button pushed function: CheckFlowSpeedButton
        function CheckFlowSpeedButtonPushed(app, event)
            dat = app.UITable.Data;
            for kk=1:4
                tt=dat(1:5,kk+1);
                ttt =cell2mat(tt);
                totVol = sum(ttt(2:5));
                if totVol==0 || ttt(1)==0
                    sp = 0.0;
                else
                    sp = totVol/ttt(1);
                end
                app.UITable.Data{7,kk+1} = sp;
            end
        end

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
            if isempty(app.sfaTR.dataDir)
                cDir =pwd;
            else
                cDir  = app.sfaTR.dataDir;
            end
            path = uigetdir(cDir);
            if path ~= 0
                app.sfaTR.dataDir = path;
                app.SFATR_DataDir.Value = path; 
            end
        end

        % Button pushed function: SelectResultFolderButton
        function SelectResultFolderButtonPushed(app, event)
            if isempty(app.sfaTR.resultDir)
                cDir =pwd;
            else
                cDir  = app.sfaTR.resultDir;
            end
            path = uigetdir(cDir);
            if path ~= 0
                app.sfaTR.resultDir = path;
                app.SFATR_ResultDir.Value = path; 
                %app.sec.dataDir = path;
            end            
        end

        % Button pushed function: SelectBackgrounddataFileButton
        function SelectBackgrounddataFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.dat; *.avg; *.txt';'*.*'},...
                          'Select a data file');
            if isequal(file,0)
               disp('File selection canceled')
            else
               fullDataFile = fullfile(path, file);
               app.sfaTR.bkgFn = fullDataFile;
               app.sfaTR.bkg = readData(fullDataFile);
               app.SFATR_BkgFile.Value = fullDataFile; 
            end
            
        end

        % Value changed function: SampleDataNameEditField
        function SampleDataNameEditFieldValueChanged(app, event)
            app.sfaTR.sampFn = app.SampleDataNameEditField.Value;
            
        end

        % Value changed function: DataSerialSquenceEditField
        function DataSerialSquenceEditFieldValueChanged(app, event)
            app.sfaTR.serNseq = app.DataSerialSquenceEditField.Value;
            app.sfaTR.serNs = convertSerNseq(app, app.sfaTR.serNseq);
        end

        % Value changed function: ShotsSerialEditField
        function ShotsSerialEditFieldValueChanged(app, event)
            app.sfaTR.nShot = app.ShotsSerialEditField.Value;
            
        end

        % Value changed function: XrayExptimemsEditField
        function XrayExptimemsEditFieldValueChanged(app, event)
            app.sfaTR.expt = app.XrayExptimemsEditField.Value;
            calculateSFAtimes(app);
        end

        % Value changed function: XrayPeriodtimemsEditField
        function XrayPeriodtimemsEditFieldValueChanged(app, event)
            app.sfaTR.prd = app.XrayPeriodtimemsEditField.Value;
            calculateSFAtimes(app);
        end

        % Value changed function: ExtraDelaymsEditField
        function ExtraDelaymsEditFieldValueChanged(app, event)
            app.sfaTR.delay = app.ExtraDelaymsEditField.Value;
            calculateSFAtimes(app);
        end

        % Button pushed function: RunTRSAXSDataProcessingButton
        function RunTRSAXSDataProcessingButtonPushed(app, event)
            if ~isempty(app.sfaTR.bkg)
                saxsFolder = app.sfaTR.dataDir;
                fName = app.sfaTR.sampFn;
                fSerNums = app.sfaTR.serNs;
                shotNum = app.sfaTR.nShot;
                save2Folder = app.sfaTR.resultDir;
                bkgFn = app.sfaTR.bkgFn;
                app.sfaTR.alpha = app.trAlphaEditField.Value;
                app.TabGroup2.SelectedTab = app.SFATab;
                calculateSFAtimes(app);
                avgTRSAXS5(app); 
                %avgTRSAXS2( saxsFolder, fName, fSerNums, shotNum, save2Folder, bkgFn );
                %avgTRSAXS( saxsFolder, fName, fSerNums, shotNum, save2Folder, bkgFn )
            end
        end

        % Value changed function: DeadVolumeuLEditField
        function DeadVolumeuLEditFieldValueChanged(app, event)
            app.sfaTR.deadVol = app.DeadVolumeuLEditField.Value;
            if ~(app.sfaTR.flowRate == 0)
                app.sfaTR.deadtime = app.sfaTR.deadVol  / app.sfaTR.flowRate; % in msec
            end
            calculateSFAtimes(app);
        end

        % Value changed function: FlowRatemLsEditField
        function FlowRatemLsEditFieldValueChanged(app, event)
            app.sfaTR.flowRate = app.FlowRatemLsEditField.Value;
            %app.sfaTR.deadVol = app.DeadVolumeuLEditField.Value;
            if ~(app.sfaTR.flowRate == 0)
                app.sfaTR.deadtime = app.sfaTR.deadVol / app.sfaTR.flowRate; % in msec
            end
            calculateSFAtimes(app);            
            
        end

        % Value changed function: DurationmsEditField
        function DurationmsEditFieldValueChanged(app, event)
            app.sfaTR.duration = app.DurationmsEditField.Value;
            calculateSFAtimes(app); 
        end

        % Button pushed function: SelectSAXSFileButton
        function SelectSAXSFileButtonPushed(app, event)
            %here            
            [file,path] = uigetfile({'*.dat; *.avg; *.txt';'*.*'},...
                          'Select a data file');
            if isequal(file,0)
               disp('File selection canceled')
            else
                app.saxs.dataDir = path;
                app.saxs.saxsFnShort = file; 
                app.saxs.saxsFn = fullfile(path, file);
                app.SAXSFn.Value = app.saxs.saxsFn;
                app.saxs.saxs = readData(app.saxs.saxsFn);
                ss = strsplit(file, '.');
                app.saxs.gtotFn = [ss{1} '_gtot.dat'];
                app.gtotFn.Value = app.saxs.gtotFn;
            end
        end

        % Button pushed function: RunATSASGuinierFitButton
        function RunATSASGuinierFitButtonPushed(app, event)
            dataNameFull = app.saxs.saxsFn;            
            atsasPath = app.saxs.atsasPath;
            savefig = 1;
            if exist(dataNameFull) && exist(atsasPath)
                data = readData(dataNameFull );
                result = autoRgMat(dataNameFull, atsasPath, savefig);
            end

            if result.Rg > -1
                app.saxs.Rg    = result.Rg;
                app.saxs.RgErr = result.RgErr;
                app.saxs.Io    = result.Io ;
                app.saxs.IoErr   = result.IoErr ;
                app.saxs.Qfit   = result.Qfit ;
                app.saxs.firstPts  = result.firstPts ;
                app.saxs.endPts   = result.endPts ;
                app.saxs.aggr   = result.aggr ;

                app.RgEditField.Value = result.Rg;
                app.IoEditField.Value = result.Io;

                app.ptStartSpinner.Value = result.firstPts;
                app.ptEndSpinner.Value = result.endPts;
                app.RgqMaxEditField.Value = result.Rg * data(result.endPts, 1);
            end

        end

        % Button pushed function: ExtrapolateDateSaveFileButton
        function ExtrapolateDateSaveFileButtonPushed(app, event)
            if app.saxs.Rg < 0
                disp('Rg value is wrong!');
                reture;
            end

            q  = app.saxs.saxs(:,1);
            iq =app.saxs.saxs(:,2);
            iqErr =app.saxs.saxs(:,3);
            
            midPts=round(0.5*(app.saxs.firstPts + app.saxs.endPts));
            q2=q(1:midPts);
            iq2 = app.saxs.Io * exp(-1/3.*(app.saxs.Rg)^2 * q2.^2);
            newIq = [iq2;iq(midPts+1:end)];
            
            if app.saxs.midSN
                cSN = mean(iqErr(midPts-2:midPts-2)./ iq(midPts-2:midPts-2));  % use average S/N of [-2 2] pts around middle pts
                iqErr2 = cSN * iq2;
                iqErr = [iqErr2;iqErr(midPts+1:end)];
            end
            gtotDat =[q newIq iqErr];
            
            app.saxs.gtot=gtotDat;
            
            gtotFnFull = fullfile(app.saxs.dataDir, app.saxs.gtotFn);
            figure(907); loglog(q,newIq,'-ob',q,iq,'-r','LineWidth',2); hold on; 
            legend('gtot','zeroConc');xlabel('q,/A');ylabel('I(q)');
            title('Guinier Extrapolation'); hold off;
            saveas(gcf, [app.saxs.gtotFn(1:end-3) 'jpg']);  % save figure
            dlmwrite(gtotFnFull, gtotDat, 'delimiter',' ','precision', '%.4E');

        end

        % Value changed function: RgEditField
        function RgEditFieldValueChanged(app, event)
            app.saxs.Rg = app.RgEditField.Value;
            
        end

        % Value changed function: IoEditField
        function IoEditFieldValueChanged(app, event)
            app.saxs.Io = app.IoEditField.Value;
            
        end

        % Value changed function: ptStartSpinner
        function ptStartSpinnerValueChanged(app, event)
            app.saxs.firstPts = app.ptStartSpinner.Value;
            
        end

        % Value changed function: ptEndSpinner
        function ptEndSpinnerValueChanged(app, event)
            app.saxs.endPts = app.ptEndSpinner.Value;
            
        end

        % Value changed function: UsemidptsSNCheckBox
        function UsemidptsSNCheckBoxValueChanged(app, event)
            app.saxs.midSN = app.UsemidptsSNCheckBox.Value;
            
        end

        % Button pushed function: SelectSAXSFilePDDF
        function SelectSAXSFilePDDFButtonPushed(app, event)
            [file,path] = uigetfile({'*.dat; *.avg; *.txt';'*.*'},...
                          'Select a data file');
            if isequal(file,0)
               disp('File selection canceled')
            else
                app.saxs.dataDir = path;
                app.saxs.saxsFnShort = file; 
                app.saxs.saxsFn = fullfile(path, file);
                app.SAXS2Fn.Value = app.saxs.saxsFn;
                app.saxs.saxs = readData(app.saxs.saxsFn);
                ss = strsplit(file, '.');
                app.saxs.pddfFn = [ss{1} '.out'];
                app.gnomFile.Value = app.saxs.pddfFn;
            end        
        end

        % Button pushed function: RunAutoGNOMButton
        function RunAutoGNOMButtonPushed(app, event)
            if exist(app.saxs.saxsFn)
                saxsFile = app.saxs.saxsFn; % fullfile(bioSAXSData.dir, bioSAXSData.saxsFn);
                dat = readData(saxsFile);
                q = dat(:,1);
                [lowQts, highQts] = findQrange(q, app.saxs.pddfSAXSq1,app.saxs.pddfSAXSq2);
                saxsFileN2 = '_tmp999.dat';
                saxsFile2 = fullfile(app.saxs.dataDir, saxsFileN2);
                dlmwrite(saxsFile2, dat(lowQts:highQts,:), ' ');
                
            else
                disp('SAXS file not available!'); 
                return;
            end
            
            if ~isempty(app.saxs.pddfFn)
                pddfFn = fullfile(app.saxs.dataDir, app.saxs.pddfFn);
            else
                disp('PDDF file name not available!'); 
                return;
            end
            
            pddfLog = fullfile(app.saxs.dataDir, 'pddf.log');
            %gnomCMD = sprintf('datgnom %s --skip %d  --output %s > %s',saxsFile, lowQts, pddfFn, pddfLog);
            %gnomCMD = sprintf('datgnom %s --output %s > %s',saxsFile2, pddfFn, pddfLog);
            gnomCMD = sprintf('%s %s -r %f -o %s > %s', fullfile(app.saxs.atsasPath, 'datgnom4'),saxsFile2, app.saxs.Rg, pddfFn, pddfLog);
            dos(gnomCMD);
            %dos(eval(gnomCMD));
            %     Rgcmd= sprintf('autorg "%s" -f ssv > "%s"', datFull, tmpLog); 
            %     dos(Rgcmd);
            %     fid=fopen(pddfLog,'r');
            %     line = fgetl(fid);
            %     fclose(fid);
            [saxs, pddf, Rg, Io] = readGNOM(pddfFn);
            app.saxs.pddf = pddf;
            app.saxs.saxsFit = saxs;
            app.saxs.RgGnom=Rg;
            
            % combine into one figure???
            dmax = pddf(end,1);
            
            h = findall(0,'type','figure','number', 9111);
            if isempty(h)
                h=figure(9111); h.Name = 'AutoGNOM PDDF';  h.Position = [230 220 560 860]; %position = h.Position; 
                h1=subplot(2,1,1); loglog(app.saxs.saxs(:,1), app.saxs.saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
                legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)'); title('AutoGNOM Fit'); axis padded; hold off;
            
                h2=subplot(2,1,2); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',2); axis padded; hold on; 
                plot(h2.XLim, [0 0], '-.', 'LineWidth',1); set(h2, 'XLim', [0 dmax]);
                legend('PDDF'); xlabel('r, A');ylabel('p(r)'); title('PDDF');  hold off;
            else
                figure(h); h.Name = 'AutoGNOM PDDF'; %h.Position = [230 220 560 860];
                h1=subplot(2,1,1, 'replace'); loglog(app.saxs.saxs(:,1), app.saxs.saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
                legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)'); title('AutoGNOM Fit'); axis padded;  hold off;
            
                h2=subplot(2,1,2, 'replace'); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',2); axis padded; hold on; 
                plot(h2.XLim, [0 0], '-.', 'LineWidth',1);
                set(h2, 'XLim',[0 dmax]);
                legend('PDDF');xlabel('r, A');ylabel('p(r)'); title('PDDF');  hold off;
            end


%             figure(909); loglog(saxs(:,1), saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
%             legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)');
%             title('GNOM Fit');hold off;
%             
%             figure(910); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',3); hold on; 
%             legend('PDDF');xlabel('r, A');ylabel('p(r)');
%             title('PDDF');hold off;     
            saveas(gcf, [app.saxs.pddfFn(1:end-4) '_autopddf.jpg']);

        end

        % Value changed function: gnomFile
        function gnomFileValueChanged(app, event)
            app.saxs.pddfFn = app.gnomFile.Value;
            
        end

        % Button pushed function: SelectGNOMoutputButton
        function SelectGNOMoutputButtonPushed(app, event)
            [fn,fpath] = uigetfile({'*.out; *.dat; *.txt'; '*.*'},'File Selection');
            if fn ~= 0
                app.gnomFile.Value = fn; 
                app.saxs.pddfFn = fn;
                app.saxs.dataDir = fpath;
                [saxs,pddf,Rg,Io]=readGNOM(fullfile(fpath,fn));
                app.saxs.saxsFit=saxs;
                app.saxs.pddf=pddf;
                app.saxs.RgGnom=Rg;
                
                figure(909); loglog(saxs(:,1), saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
                legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)');
                title('GNOM Fit');hold off;
            
                figure(910); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',3); hold on; 
                legend('PDDF');xlabel('r, A');ylabel('p(r)');
                title('PDDF');hold off;
            else
                disp('No file was select! Use previous selection!')
                %bioSAXSData.bufFn = fn;
                %bioSAXSData.bufPath = fpath;    
            end
        end

        % Button pushed function: ManualGNOMButton
        function ManualGNOMButtonPushed(app, event)
            runGNOM1(app);
%             try
%                 genGNOMInput(app);
%                 runManualGNOM(app);
%             catch
%                 disp('something wrong!');
%             end
        end

        % Button pushed function: PlotPDDFButton
        function PlotPDDFButtonPushed(app, event)
%             figure(909); loglog(saxs(:,1), saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
%             legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)');
%             title('GNOM Fit');hold off;
%            
            pddf = app.saxs.pddf;
            if isempty(pddf)
                disp('no PDDF data!!!');
                return;
            end
            figure(910); plot(pddf(:,1), pddf(:,2),'-r','LineWidth',3); hold on; 
            legend('PDDF');xlabel('r, A');ylabel('p(r)');
            title('PDDF');hold off;  
        end

        % Button pushed function: SavePDDFButton
        function SavePDDFButtonPushed(app, event)
            pddf = app.saxs.pddf;
            fn = app.saxs.pddfFn;

            if isempty(pddf) || isempty(fn)
                disp('no PDDF data!!!');
                return;
            end
            
            pddfFn = [fn(1:end-4) '_pr.dat'];

            if app.NormalizeCheckBox.Value
                pddf(:,2:3) =  pddf(:,2:3) / max(pddf(:,2));
            end
        
            [file,path] = uiputfile(pddfFn,'Save PDDF File');
            if file == 0
                disp('Save file cancelled.');
                %return;
            else
                pddfFull = fullfile(path, file);
                dlmwrite(pddfFull, pddf, 'delimiter', ' ', 'precision', '%.4E');
            end

        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            value = app.NormalizeCheckBox.Value;
            
        end

        % Button pushed function: PlotSAXSFitButton
        function PlotSAXSFitButtonPushed(app, event)
            saxs = app.saxs.saxsFit;
            if isempty(saxs)
                disp('No SAXS fitting!!!')
                return;
            end
            figure(909); loglog(saxs(:,1), saxs(:,2),'-ob',saxs(:,1), saxs(:,4),'-r','LineWidth',2); hold on; 
            legend('iExp','iFit');xlabel('q,/A');ylabel('I(q)');
            title('GNOM Fit');hold off;
%   
        end

        % Button pushed function: SaveGNOMExtropSAXSButton
        function SaveGNOMExtropSAXSButtonPushed(app, event)
            saxs = app.saxs.saxsFit;
            fn = app.saxs.pddfFn;
            if isempty(saxs) || isempty(fn)
                disp('No SAXS fitting!!!')
                return;
            end                     

            saxsFitFn = [fn(1:end-4) '_sFit.dat'];
            
            [file,path] = uiputfile(saxsFitFn,'Save SAXS Fit File');
            if file == 0
                disp('Save file cancelled.');
                return;
            else
                saxsFitFull = fullfile(path, file);
                dlmwrite(saxsFitFull, [saxs(:,1) saxs(:,4) saxs(:,3)], 'delimiter', ' ', 'precision', '%.4E');
            end

        end

        % Value changed function: qMin2
        function qMin2ValueChanged(app, event)
            value = app.qMin2.Value;
            
        end

        % Value changed function: qMax2
        function qMax2ValueChanged(app, event)
            value = app.qMax2.Value;
            
        end

        % Value changed function: alphaPDDF
        function alphaPDDFValueChanged(app, event)
            value = app.alphaPDDF.Value;
            
        end

        % Button pushed function: KratkyPlotButton
        function KratkyPlotButtonPushed(app, event)
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            
        end

        % Button pushed function: QnIQplotButton
        function QnIQplotButtonPushed(app, event)
            
        end

        % Value changed function: qMinEditField_2
        function qMinEditField_2ValueChanged(app, event)
            value = app.qMinEditField_2.Value;
            
        end

        % Value changed function: qMaxEditField_4
        function qMaxEditField_4ValueChanged(app, event)
            value = app.qMaxEditField_4.Value;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Button pushed function: EstimateMWButton
        function EstimateMWButtonPushed(app, event)
            
        end

        % Button pushed function: CalculateSAXSInvariantButton
        function CalculateSAXSInvariantButtonPushed(app, event)
            
        end

        % Value changed function: gtotFn
        function gtotFnValueChanged(app, event)
            app.saxs.gtotFn = app.gtotFn.Value;
            
        end

        % Value changed function: DmaxSpinner
        function DmaxSpinnerValueChanged(app, event)
            app.saxs.Dmax = app.DmaxSpinner.Value;
            if app.liveGNOM.Value
                runGNOM1(app);
            end
        end

        % Value changed function: liveGNOM
        function liveGNOMValueChanged(app, event)
            value = app.liveGNOM.Value;
            
        end

        % Button pushed function: SelectDataSetsRunIntergrationButton
        function SelectDataSetsRunIntergrationButtonPushed(app, event)
            app.TabGroup2.SelectedTab = app.SECPlotTab;            
            app.sec.qLow = app.SEC_qLow.Value;
            app.sec.qHigh = app.SEC_qHigh.Value;
            app.sec.cutoffLow = app.SEC_AreaCutoffLow.Value;
            app.sec.cutoffHigh = app.SEC_AreaCutoffHigh.Value;
            app.sec.serNum = app.SEC_DataSerialNum.Value;
            qRange = [app.sec.qLow app.sec.qHigh];
            areaCutoff = [app.sec.cutoffLow app.sec.cutoffHigh];
            %[goodScans, area, outliers, missings, fntemp]=secIqAreaVsN(app, app.sec.dataDir, app.sec.resultDir, app.sec.serNum, qRange, areaCutoff);
            [goodScans, area, outliers, missings, fntemp]=secIqAreaVsN(app, app.sec.dataDir, app.sec.resultDir, app.sec.serNum, qRange, areaCutoff);
            try
                app.sec.goodScans = goodScans;
                app.sec.iqAreas = area;
                app.sec.fnTemp = fntemp;
                app.sec.outliers = outliers;
                app.missings = missings;
            catch
                disp('some field missing.')
            end
            
        end

        % Size changed function: BackSubTab
        function BackSubTabSizeChanged(app, event)
            position = app.BackSubTab.Position;
            % here here to change
        end

        % Button down function: BackSubTab
        function BackSubTabButtonDown(app, event)
            %x=1; %here here to change
            LeftTabGroupSelectionChanged(app, event);
        end

        % Button down function: AnalysisTab
        function AnalysisTabButtonDown(app, event)
            LeftTabGroupSelectionChanged(app, event);
        end

        % Button down function: SAXSWAXSTab
        function SAXSWAXSTabButtonDown(app, event)
            LeftTabGroupSelectionChanged(app, event);
        end

        % Button down function: AveragingTab2
        function AveragingTab2ButtonDown(app, event)
            LeftTabGroupSelectionChanged(app, event);
        end

        % Button down function: SECTab
        function SECTabButtonDown(app, event)
            LeftTabGroupSelectionChanged(app, event);
        end

        % Button down function: TRSAXSTab
        function TRSAXSTabButtonDown(app, event)
            LeftTabGroupSelectionChanged(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BioSAXSMatUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {563, 563};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {512, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioSAXSMatUIFigure = uifigure('Visible', 'off');
            app.BioSAXSMatUIFigure.Name = 'BioSAXSMat';
            app.BioSAXSMatUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BioSAXSMatUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.BackSubTab = uitab(app.TabGroup);
            app.BackSubTab.SizeChangedFcn = createCallbackFcn(app, @BackSubTabSizeChanged, true);
            app.BackSubTab.Title = 'BackSub';
            app.BackSubTab.ButtonDownFcn = createCallbackFcn(app, @BackSubTabButtonDown, true);
            app.Panel = uipanel(app.BackSubTab);
            app.SampleDataFilesConcmgmlinUseLabel = uilabel(app.Panel);
            app.SampleDataFilesConcmgmlinUseLabel.Text = 'Sample Data Files      Conc(mg/ml)    inUse?';
            app.BufferDataFilesUsefoRestLabel = uilabel(app.Panel);
            app.BufferDataFilesUsefoRestLabel.Text = 'Buffer Data Files       Use fo Rest?';
            app.S1EditFieldLabel = uilabel(app.Panel);
            app.S1EditFieldLabel.Text = 'S#1';
            app.S1FileName = uieditfield(app.Panel, 'text');
            app.S1FileName.Value = 'sample1.dat';
            app.SelectS1 = uibutton(app.Panel, 'push');
            app.SelectS1.ButtonPushedFcn = createCallbackFcn(app, @SelectS1ButtonPushed, true);
            app.SelectS1.Text = 'Select';
            app.S1Conc = uieditfield(app.Panel, 'numeric');
            app.S1Conc.ValueDisplayFormat = '%5.2f';
            app.S1Conc.ValueChangedFcn = createCallbackFcn(app, @S1ConcValueChanged, true);
            app.S1Conc.Value = 1;
            app.S1CHK = uicheckbox(app.Panel);
            app.S1CHK.ValueChangedFcn = createCallbackFcn(app, @S1CHKValueChanged, true);
            app.S1CHK.Text = '';
            app.S1CHK.Value = true;
            app.SelectB1 = uibutton(app.Panel, 'push');
            app.SelectB1.ButtonPushedFcn = createCallbackFcn(app, @SelectB1ButtonPushed, true);
            app.SelectB1.Text = 'Select';
            app.B1EditFieldLabel = uilabel(app.Panel);
            app.B1EditFieldLabel.Text = 'B#1';
            app.B1FileName = uieditfield(app.Panel, 'text');
            app.B1FileName.Value = 'buffer1.dat';
            app.B1CHK = uicheckbox(app.Panel);
            app.B1CHK.ValueChangedFcn = createCallbackFcn(app, @B1CHKValueChanged, true);
            app.B1CHK.Text = '';
            app.B1CHK.Value = true;
            app.SelectS2 = uibutton(app.Panel, 'push');
            app.SelectS2.ButtonPushedFcn = createCallbackFcn(app, @SelectS2ButtonPushed, true);
            app.SelectS2.Text = 'Select';
            app.S2Conc = uieditfield(app.Panel, 'numeric');
            app.S2Conc.ValueDisplayFormat = '%5.2f';
            app.S2Conc.ValueChangedFcn = createCallbackFcn(app, @S2ConcValueChanged, true);
            app.S2Conc.Value = 2;
            app.S2CHK = uicheckbox(app.Panel);
            app.S2CHK.ValueChangedFcn = createCallbackFcn(app, @S2CHKValueChanged, true);
            app.S2CHK.Text = '';
            app.S2CHK.Value = true;
            app.SelectB2 = uibutton(app.Panel, 'push');
            app.SelectB2.ButtonPushedFcn = createCallbackFcn(app, @SelectB2ButtonPushed, true);
            app.SelectB2.Text = 'Select';
            app.B1EditFieldLabel_2 = uilabel(app.Panel);
            app.B1EditFieldLabel_2.Text = 'B#2';
            app.B2FileName = uieditfield(app.Panel, 'text');
            app.B2FileName.Value = 'buffer2.dat';
            app.B2CHK = uicheckbox(app.Panel);
            app.B2CHK.ValueChangedFcn = createCallbackFcn(app, @B2CHKValueChanged, true);
            app.B2CHK.Text = '';
            app.S2Label = uilabel(app.Panel);
            app.S2Label.Text = 'S#2';
            app.S2FileName = uieditfield(app.Panel, 'text');
            app.S2FileName.Value = 'sample2.dat';
            app.SelectS3 = uibutton(app.Panel, 'push');
            app.SelectS3.ButtonPushedFcn = createCallbackFcn(app, @SelectS3ButtonPushed, true);
            app.SelectS3.Text = 'Select';
            app.S3Conc = uieditfield(app.Panel, 'numeric');
            app.S3Conc.ValueDisplayFormat = '%5.2f';
            app.S3Conc.ValueChangedFcn = createCallbackFcn(app, @S3ConcValueChanged, true);
            app.S3Conc.Value = 3;
            app.S3CHK = uicheckbox(app.Panel);
            app.S3CHK.ValueChangedFcn = createCallbackFcn(app, @S3CHKValueChanged, true);
            app.S3CHK.Text = '';
            app.S3CHK.Value = true;
            app.SelectB3 = uibutton(app.Panel, 'push');
            app.SelectB3.ButtonPushedFcn = createCallbackFcn(app, @SelectB3ButtonPushed, true);
            app.SelectB3.Text = 'Select';
            app.B1EditFieldLabel_3 = uilabel(app.Panel);
            app.B1EditFieldLabel_3.Text = 'B#3';
            app.B3FileName = uieditfield(app.Panel, 'text');
            app.B3FileName.Value = 'buffer3.dat';
            app.B3CHK = uicheckbox(app.Panel);
            app.B3CHK.ValueChangedFcn = createCallbackFcn(app, @B3CHKValueChanged, true);
            app.B3CHK.Text = '';
            app.S3Label = uilabel(app.Panel);
            app.S3Label.Text = 'S#3';
            app.S3FileName = uieditfield(app.Panel, 'text');
            app.S3FileName.Value = 'sample3.dat';
            app.SelectS4 = uibutton(app.Panel, 'push');
            app.SelectS4.ButtonPushedFcn = createCallbackFcn(app, @SelectS4ButtonPushed, true);
            app.SelectS4.Text = 'Select';
            app.S4Conc = uieditfield(app.Panel, 'numeric');
            app.S4Conc.ValueDisplayFormat = '%5.2f';
            app.S4Conc.ValueChangedFcn = createCallbackFcn(app, @S4ConcValueChanged, true);
            app.S4Conc.Value = 4;
            app.S4CHK = uicheckbox(app.Panel);
            app.S4CHK.ValueChangedFcn = createCallbackFcn(app, @S4CHKValueChanged, true);
            app.S4CHK.Text = '';
            app.SelectB4 = uibutton(app.Panel, 'push');
            app.SelectB4.ButtonPushedFcn = createCallbackFcn(app, @SelectB4ButtonPushed, true);
            app.SelectB4.Text = 'Select';
            app.B1EditFieldLabel_4 = uilabel(app.Panel);
            app.B1EditFieldLabel_4.Text = 'B#4';
            app.B4FileName = uieditfield(app.Panel, 'text');
            app.B4FileName.Value = 'buffer4.dat';
            app.B4CHK = uicheckbox(app.Panel);
            app.B4CHK.ValueChangedFcn = createCallbackFcn(app, @B4CHKValueChanged, true);
            app.B4CHK.Text = '';
            app.S4EditFieldLabel = uilabel(app.Panel);
            app.S4EditFieldLabel.Text = 'S#4';
            app.S4FileName = uieditfield(app.Panel, 'text');
            app.S4FileName.Value = 'sample4.dat';
            app.SelectS5 = uibutton(app.Panel, 'push');
            app.SelectS5.ButtonPushedFcn = createCallbackFcn(app, @SelectS5ButtonPushed, true);
            app.SelectS5.Text = 'Select';
            app.S5Conc = uieditfield(app.Panel, 'numeric');
            app.S5Conc.ValueDisplayFormat = '%5.2f';
            app.S5Conc.ValueChangedFcn = createCallbackFcn(app, @S5ConcValueChanged, true);
            app.S5Conc.Value = 5;
            app.S5CHK = uicheckbox(app.Panel);
            app.S5CHK.ValueChangedFcn = createCallbackFcn(app, @S5CHKValueChanged, true);
            app.S5CHK.Text = '';
            app.SelectB5 = uibutton(app.Panel, 'push');
            app.SelectB5.ButtonPushedFcn = createCallbackFcn(app, @SelectB5ButtonPushed, true);
            app.SelectB5.Text = 'Select';
            app.B1EditFieldLabel_5 = uilabel(app.Panel);
            app.B1EditFieldLabel_5.Text = 'B#5';
            app.B5FileName = uieditfield(app.Panel, 'text');
            app.B5FileName.Value = 'buffer5.dat';
            app.B5CHK = uicheckbox(app.Panel);
            app.B5CHK.ValueChangedFcn = createCallbackFcn(app, @B5CHKValueChanged, true);
            app.B5CHK.Text = '';
            app.S5EditFieldLabel = uilabel(app.Panel);
            app.S5EditFieldLabel.Text = 'S#5';
            app.S5FileName = uieditfield(app.Panel, 'text');
            app.S5FileName.Value = 'sample5.dat';
            app.SelectS6 = uibutton(app.Panel, 'push');
            app.SelectS6.ButtonPushedFcn = createCallbackFcn(app, @SelectS6ButtonPushed, true);
            app.SelectS6.Text = 'Select';
            app.S6Conc = uieditfield(app.Panel, 'numeric');
            app.S6Conc.ValueDisplayFormat = '%5.2f';
            app.S6Conc.ValueChangedFcn = createCallbackFcn(app, @S6ConcValueChanged, true);
            app.S6Conc.Value = 6;
            app.S6CHK = uicheckbox(app.Panel);
            app.S6CHK.ValueChangedFcn = createCallbackFcn(app, @S6CHKValueChanged, true);
            app.S6CHK.Text = '';
            app.SelectB6 = uibutton(app.Panel, 'push');
            app.SelectB6.ButtonPushedFcn = createCallbackFcn(app, @SelectB6ButtonPushed, true);
            app.SelectB6.Text = 'Select';
            app.B1EditFieldLabel_6 = uilabel(app.Panel);
            app.B1EditFieldLabel_6.Text = 'B#6';
            app.B6FileName = uieditfield(app.Panel, 'text');
            app.B6FileName.Value = 'buffer6.dat';
            app.B6CHK = uicheckbox(app.Panel);
            app.B6CHK.ValueChangedFcn = createCallbackFcn(app, @B6CHKValueChanged, true);
            app.B6CHK.Text = '';
            app.S6EditFieldLabel = uilabel(app.Panel);
            app.S6EditFieldLabel.Text = 'S#6';
            app.S6FileName = uieditfield(app.Panel, 'text');
            app.S6FileName.Value = 'sample6.dat';
            app.DataDirLabel = uilabel(app.Panel);
            app.DataDirLabel.Text = 'Data Dir: ';
            app.PlotSelectedDataButton = uibutton(app.Panel, 'push');
            app.PlotSelectedDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedDataButtonPushed, true);
            app.PlotSelectedDataButton.Text = 'Plot Selected Data';
            app.alphaEditFieldLabel = uilabel(app.Panel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.Panel, 'numeric');
            app.alphaEditField.ValueDisplayFormat = '%5.5f';
            app.alphaEditField.Value = 1;
            app.SubtractPlotButton = uibutton(app.Panel, 'push');
            app.SubtractPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractPlotButtonPushed, true);
            app.SubtractPlotButton.Text = 'Subtract & Plot';
            app.SubtractPlotSaveButton = uibutton(app.Panel, 'push');
            app.SubtractPlotSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractPlotSaveButtonPushed, true);
            app.SubtractPlotSaveButton.Text = 'Subtract, Plot & Save';
            app.DataSelectionLabel = uilabel(app.BackSubTab);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.Panel_2 = uipanel(app.BackSubTab);
            app.LBLabel = uilabel(app.Panel_2);
            app.LBLabel.Text = 'LB';
            app.UBLabel = uilabel(app.Panel_2);
            app.UBLabel.Text = 'UB';
            app.alphaLabel = uilabel(app.Panel_2);
            app.alphaLabel.Text = 'alpha';
            app.betaLabel = uilabel(app.Panel_2);
            app.betaLabel.Text = 'beta';
            app.S1CHK2 = uicheckbox(app.Panel_2);
            app.S1CHK2.ValueChangedFcn = createCallbackFcn(app, @S1CHK2ValueChanged, true);
            app.S1CHK2.Text = 'S#1';
            app.S1CHK2.Value = true;
            app.S2CHK2 = uicheckbox(app.Panel_2);
            app.S2CHK2.ValueChangedFcn = createCallbackFcn(app, @S2CHK2ValueChanged, true);
            app.S2CHK2.Text = 'S#2';
            app.S2CHK2.Value = true;
            app.S3CHK2 = uicheckbox(app.Panel_2);
            app.S3CHK2.ValueChangedFcn = createCallbackFcn(app, @S3CHK2ValueChanged, true);
            app.S3CHK2.Text = 'S#3';
            app.S3CHK2.Value = true;
            app.S4CHK2 = uicheckbox(app.Panel_2);
            app.S4CHK2.ValueChangedFcn = createCallbackFcn(app, @S4CHK2ValueChanged, true);
            app.S4CHK2.Text = 'S#4';
            app.S5CHK2 = uicheckbox(app.Panel_2);
            app.S5CHK2.ValueChangedFcn = createCallbackFcn(app, @S5CHK2ValueChanged, true);
            app.S5CHK2.Text = 'S#5';
            app.S6CHK2 = uicheckbox(app.Panel_2);
            app.S6CHK2.ValueChangedFcn = createCallbackFcn(app, @S6CHK2ValueChanged, true);
            app.S6CHK2.Text = 'S#6';
            app.alphaUB = uieditfield(app.Panel_2, 'numeric');
            app.alphaUB.ValueDisplayFormat = '%5.3f';
            app.alphaUB.Value = 1;
            app.betaUB = uieditfield(app.Panel_2, 'numeric');
            app.betaUB.ValueDisplayFormat = '%5.3f';
            app.betaUB.Value = 1.5;
            app.alphaLB = uieditfield(app.Panel_2, 'numeric');
            app.alphaLB.ValueDisplayFormat = '%5.3f';
            app.alphaLB.Value = 0.985;
            app.betaLB = uieditfield(app.Panel_2, 'numeric');
            app.betaLB.ValueDisplayFormat = '%5.3f';
            app.betaLB.Value = 0.75;
            app.alpha6 = uieditfield(app.Panel_2, 'numeric');
            app.alpha6.ValueDisplayFormat = '%5.5f';
            app.alpha6.Value = 1;
            app.beta6 = uieditfield(app.Panel_2, 'numeric');
            app.beta6.ValueDisplayFormat = '%5.5f';
            app.beta6.Value = 1;
            app.alpha5 = uieditfield(app.Panel_2, 'numeric');
            app.alpha5.ValueDisplayFormat = '%5.5f';
            app.alpha5.Value = 1;
            app.alpha4 = uieditfield(app.Panel_2, 'numeric');
            app.alpha4.ValueDisplayFormat = '%5.5f';
            app.alpha4.Value = 1;
            app.alpha3 = uieditfield(app.Panel_2, 'numeric');
            app.alpha3.ValueDisplayFormat = '%5.5f';
            app.alpha3.Value = 1;
            app.alpha2 = uieditfield(app.Panel_2, 'numeric');
            app.alpha2.ValueDisplayFormat = '%5.5f';
            app.alpha2.Value = 1;
            app.alpha1 = uieditfield(app.Panel_2, 'numeric');
            app.alpha1.ValueDisplayFormat = '%5.5f';
            app.alpha1.Value = 1;
            app.beta5 = uieditfield(app.Panel_2, 'numeric');
            app.beta5.ValueDisplayFormat = '%5.5f';
            app.beta5.Value = 1;
            app.beta4 = uieditfield(app.Panel_2, 'numeric');
            app.beta4.ValueDisplayFormat = '%5.5f';
            app.beta4.Value = 1;
            app.beta3 = uieditfield(app.Panel_2, 'numeric');
            app.beta3.ValueDisplayFormat = '%5.5f';
            app.beta3.Value = 1;
            app.beta2 = uieditfield(app.Panel_2, 'numeric');
            app.beta2.ValueDisplayFormat = '%5.5f';
            app.beta2.Value = 1;
            app.beta1 = uieditfield(app.Panel_2, 'numeric');
            app.beta1.ValueDisplayFormat = '%5.5f';
            app.beta1.Value = 1;
            app.qMaxEditFieldLabel = uilabel(app.Panel_2);
            app.qMaxEditFieldLabel.Text = 'qMax';
            app.qMax = uieditfield(app.Panel_2, 'numeric');
            app.qMax.ValueDisplayFormat = '%5.3f';
            app.qMax.Value = 0.6;
            app.qMaxEditFieldLabel_2 = uilabel(app.Panel_2);
            app.qMaxEditFieldLabel_2.Text = 'qMin';
            app.qMin = uieditfield(app.Panel_2, 'numeric');
            app.qMin.ValueDisplayFormat = '%5.3f';
            app.qMin.Value = 0.08;
            app.FitalphaBetaConcExtroplateButton = uibutton(app.Panel_2, 'push');
            app.FitalphaBetaConcExtroplateButton.ButtonPushedFcn = createCallbackFcn(app, @FitalphaBetaConcExtroplateButtonPushed, true);
            app.FitalphaBetaConcExtroplateButton.Text = 'Fit alpha, Beta & Conc Extroplate';
            app.SaveBackSubsButton = uibutton(app.Panel_2, 'push');
            app.SaveBackSubsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackSubsButtonPushed, true);
            app.SaveBackSubsButton.Text = 'Save BackSubs';
            app.FittingMsgLabel = uilabel(app.Panel_2);
            app.FittingMsgLabel.Text = 'Fitting Msg: ';
            app.ConcRatioStr = uilabel(app.Panel_2);
            app.ConcRatioStr.Text = '1.0:2.0:3.0:4.0:5.0:6.0';
            app.FittedConcRatioLabel = uilabel(app.Panel_2);
            app.FittedConcRatioLabel.Text = 'Fitted Conc Ratio';
            app.FittingErrorsLabel = uilabel(app.Panel_2);
            app.FittingErrorsLabel.Text = 'Fitting Errors:';
            app.SaveBackSubDataParametersButton = uibutton(app.Panel_2, 'push');
            app.SaveBackSubDataParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackSubDataParametersButtonPushed, true);
            app.SaveBackSubDataParametersButton.Text = 'Save BackSub Data & Parameters';
            app.LoadBackSubDataButton = uibutton(app.Panel_2, 'push');
            app.LoadBackSubDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBackSubDataButtonPushed, true);
            app.LoadBackSubDataButton.Text = 'Load BackSub Data';
            app.ResetBackSubDataButton = uibutton(app.Panel_2, 'push');
            app.ResetBackSubDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBackSubDataButtonPushed, true);
            app.ResetBackSubDataButton.Text = 'Reset BackSub Data';
            app.SimulatenousSubtractionConcExtroplationLabel = uilabel(app.BackSubTab);
            app.SimulatenousSubtractionConcExtroplationLabel.Text = 'Simulatenous Subtraction & Conc Extroplation';
            app.SAXSReportEditFieldLabel = uilabel(app.BackSubTab);
            app.SAXSReportEditFieldLabel.Text = 'SAXS Report:';
            app.SAXSReportEditField = uieditfield(app.BackSubTab, 'text');
            app.SAXSReportEditField.Value = 'mySAXS_report.doc';
            app.SaveSAXSReport1Button = uibutton(app.BackSubTab, 'push');
            app.SaveSAXSReport1Button.Text = 'Save SAXS Report 1';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.ButtonDownFcn = createCallbackFcn(app, @AnalysisTabButtonDown, true);
            app.Panel_3 = uipanel(app.AnalysisTab);
            app.qMergeEditFieldLabel = uilabel(app.Panel_3);
            app.qMergeEditFieldLabel.Text = 'qMerge';
            app.qMergeEditField = uieditfield(app.Panel_3, 'numeric');
            app.qMergeEditField.ValueDisplayFormat = '%.3f';
            app.qMergeEditField.ValueChangedFcn = createCallbackFcn(app, @qMergeEditFieldValueChanged, true);
            app.qMergeEditField.Value = 0.09;
            app.mBsubDropDownLabel = uilabel(app.Panel_3);
            app.mBsubDropDownLabel.Text = 'mBsub';
            app.mBsubDropDown = uidropdown(app.Panel_3);
            app.mBsubDropDown.Items = {'Samp1', 'Samp2', 'Samp3', 'Samp4', 'Samp5', 'Samp6'};
            app.mBsubDropDown.ValueChangedFcn = createCallbackFcn(app, @mBsubDropDownValueChanged, true);
            app.mBsubDropDown.Value = 'Samp1';
            app.MergeZeroConcFileButton = uibutton(app.Panel_3, 'push');
            app.MergeZeroConcFileButton.ButtonPushedFcn = createCallbackFcn(app, @MergeZeroConcFileButtonPushed, true);
            app.MergeZeroConcFileButton.Text = 'Merge Zero Conc File';
            app.mZeroConcFn = uieditfield(app.Panel_3, 'text');
            app.mZeroConcFn.ValueChangedFcn = createCallbackFcn(app, @mZeroConcFnValueChanged, true);
            app.mZeroConcFn.Value = 'mZeroConc.dat';
            app.MergeZeroConcLabel = uilabel(app.AnalysisTab);
            app.MergeZeroConcLabel.Text = 'Merge Zero Conc';
            app.Panel_4 = uipanel(app.AnalysisTab);
            app.SelectSAXSFileButton = uibutton(app.Panel_4, 'push');
            app.SelectSAXSFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSAXSFileButtonPushed, true);
            app.SelectSAXSFileButton.Text = 'Select SAXS File';
            app.SAXSFn = uieditfield(app.Panel_4, 'text');
            app.SAXSFn.Value = 'mZeroConc.dat';
            app.RunATSASGuinierFitButton = uibutton(app.Panel_4, 'push');
            app.RunATSASGuinierFitButton.ButtonPushedFcn = createCallbackFcn(app, @RunATSASGuinierFitButtonPushed, true);
            app.RunATSASGuinierFitButton.Text = {'Run ATSAS '; 'Guinier Fit'};
            app.RgEditFieldLabel = uilabel(app.Panel_4);
            app.RgEditFieldLabel.Text = 'Rg';
            app.RgEditField = uieditfield(app.Panel_4, 'numeric');
            app.RgEditField.ValueDisplayFormat = '%.3f';
            app.RgEditField.ValueChangedFcn = createCallbackFcn(app, @RgEditFieldValueChanged, true);
            app.RgEditField.Value = 20;
            app.IoEditFieldLabel = uilabel(app.Panel_4);
            app.IoEditFieldLabel.Text = 'Io';
            app.IoEditField = uieditfield(app.Panel_4, 'numeric');
            app.IoEditField.ValueDisplayFormat = '%.2E';
            app.IoEditField.ValueChangedFcn = createCallbackFcn(app, @IoEditFieldValueChanged, true);
            app.IoEditField.Value = 0.0012;
            app.ExtrapolateDateSaveFileButton = uibutton(app.Panel_4, 'push');
            app.ExtrapolateDateSaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExtrapolateDateSaveFileButtonPushed, true);
            app.ExtrapolateDateSaveFileButton.Text = {'Extrapolate Date'; '& Save File'};
            app.UsemidptsSNCheckBox = uicheckbox(app.Panel_4);
            app.UsemidptsSNCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsemidptsSNCheckBoxValueChanged, true);
            app.UsemidptsSNCheckBox.Text = 'Use mid-pts S/N';
            app.UsemidptsSNCheckBox.Value = true;
            app.gtotFn = uieditfield(app.Panel_4, 'text');
            app.gtotFn.ValueChangedFcn = createCallbackFcn(app, @gtotFnValueChanged, true);
            app.gtotFn.Value = 'samp_gtot.dat';
            app.FileNameLabel = uilabel(app.Panel_4);
            app.FileNameLabel.Text = 'File Name';
            app.ptStartSpinnerLabel = uilabel(app.Panel_4);
            app.ptStartSpinnerLabel.Text = 'ptStart';
            app.ptStartSpinner = uispinner(app.Panel_4);
            app.ptStartSpinner.ValueChangedFcn = createCallbackFcn(app, @ptStartSpinnerValueChanged, true);
            app.ptStartSpinner.Value = 40;
            app.ptEndSpinnerLabel = uilabel(app.Panel_4);
            app.ptEndSpinnerLabel.Text = 'ptEnd';
            app.ptEndSpinner = uispinner(app.Panel_4);
            app.ptEndSpinner.ValueChangedFcn = createCallbackFcn(app, @ptEndSpinnerValueChanged, true);
            app.ptEndSpinner.Value = 120;
            app.RgqMaxEditFieldLabel = uilabel(app.Panel_4);
            app.RgqMaxEditFieldLabel.Text = 'Rg*qMax';
            app.RgqMaxEditField = uieditfield(app.Panel_4, 'numeric');
            app.RgqMaxEditField.ValueDisplayFormat = '%.2f';
            app.RgqMaxEditField.Value = 1.3;
            app.SAVEResultsButton = uibutton(app.Panel_4, 'push');
            app.SAVEResultsButton.Text = 'SAVE Results';
            app.GuinierLabel = uilabel(app.AnalysisTab);
            app.GuinierLabel.Text = 'Guinier';
            app.Panel_5 = uipanel(app.AnalysisTab);
            app.SelectSAXSFilePDDF = uibutton(app.Panel_5, 'push');
            app.SelectSAXSFilePDDF.ButtonPushedFcn = createCallbackFcn(app, @SelectSAXSFilePDDFButtonPushed, true);
            app.SelectSAXSFilePDDF.Text = 'Select SAXS File';
            app.SAXS2Fn = uieditfield(app.Panel_5, 'text');
            app.SAXS2Fn.Value = 'mZeroConc.dat';
            app.qMinEditFieldLabel = uilabel(app.Panel_5);
            app.qMinEditFieldLabel.Text = 'qMin';
            app.qMin2 = uieditfield(app.Panel_5, 'numeric');
            app.qMin2.ValueChangedFcn = createCallbackFcn(app, @qMin2ValueChanged, true);
            app.qMin2.Value = 0.008;
            app.qMaxEditField_3Label = uilabel(app.Panel_5);
            app.qMaxEditField_3Label.Text = 'qMax';
            app.qMax2 = uieditfield(app.Panel_5, 'numeric');
            app.qMax2.ValueDisplayFormat = '%.2f';
            app.qMax2.ValueChangedFcn = createCallbackFcn(app, @qMax2ValueChanged, true);
            app.qMax2.Value = 0.35;
            app.SelectGNOMoutputButton = uibutton(app.Panel_5, 'push');
            app.SelectGNOMoutputButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGNOMoutputButtonPushed, true);
            app.SelectGNOMoutputButton.Text = 'Select GNOM output';
            app.gnomFile = uieditfield(app.Panel_5, 'text');
            app.gnomFile.ValueChangedFcn = createCallbackFcn(app, @gnomFileValueChanged, true);
            app.gnomFile.Value = 'samp_gtot.out';
            app.RunAutoGNOMButton = uibutton(app.Panel_5, 'push');
            app.RunAutoGNOMButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoGNOMButtonPushed, true);
            app.RunAutoGNOMButton.Text = 'Run AutoGNOM';
            app.ManualGNOMButton = uibutton(app.Panel_5, 'push');
            app.ManualGNOMButton.ButtonPushedFcn = createCallbackFcn(app, @ManualGNOMButtonPushed, true);
            app.ManualGNOMButton.Text = 'Manual GNOM';
            app.PlotPDDFButton = uibutton(app.Panel_5, 'push');
            app.PlotPDDFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPDDFButtonPushed, true);
            app.PlotPDDFButton.Text = 'Plot PDDF';
            app.SavePDDFButton = uibutton(app.Panel_5, 'push');
            app.SavePDDFButton.ButtonPushedFcn = createCallbackFcn(app, @SavePDDFButtonPushed, true);
            app.SavePDDFButton.Text = 'Save PDDF';
            app.NormalizeCheckBox = uicheckbox(app.Panel_5);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize?';
            app.PlotSAXSFitButton = uibutton(app.Panel_5, 'push');
            app.PlotSAXSFitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSAXSFitButtonPushed, true);
            app.PlotSAXSFitButton.Text = 'Plot SAXS Fit';
            app.SaveGNOMExtropSAXSButton = uibutton(app.Panel_5, 'push');
            app.SaveGNOMExtropSAXSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGNOMExtropSAXSButtonPushed, true);
            app.SaveGNOMExtropSAXSButton.Text = 'Save GNOM Extrop SAXS';
            app.alphaEditField_14Label = uilabel(app.Panel_5);
            app.alphaEditField_14Label.Text = 'alpha';
            app.alphaPDDF = uieditfield(app.Panel_5, 'numeric');
            app.alphaPDDF.ValueDisplayFormat = '%.2f';
            app.alphaPDDF.ValueChangedFcn = createCallbackFcn(app, @alphaPDDFValueChanged, true);
            app.alphaPDDF.Value = 0.5;
            app.DmaxSpinnerLabel = uilabel(app.Panel_5);
            app.DmaxSpinnerLabel.Text = 'Dmax';
            app.DmaxSpinner = uispinner(app.Panel_5);
            app.DmaxSpinner.Step = 0.5;
            app.DmaxSpinner.ValueDisplayFormat = '%.1f';
            app.DmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @DmaxSpinnerValueChanged, true);
            app.DmaxSpinner.Value = 100;
            app.liveGNOM = uicheckbox(app.Panel_5);
            app.liveGNOM.ValueChangedFcn = createCallbackFcn(app, @liveGNOMValueChanged, true);
            app.liveGNOM.Text = 'live?';
            app.PDDFAnalysisLabel = uilabel(app.AnalysisTab);
            app.PDDFAnalysisLabel.Text = 'PDDF Analysis';
            app.Panel_6 = uipanel(app.AnalysisTab);
            app.SelectSAXSFileKratky = uibutton(app.Panel_6, 'push');
            app.SelectSAXSFileKratky.Text = 'Select SAXS File';
            app.SAXS3Fn = uieditfield(app.Panel_6, 'text');
            app.SAXS3Fn.Value = 'mZeroConc.dat';
            app.KratkyPlotButton = uibutton(app.Panel_6, 'push');
            app.KratkyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @KratkyPlotButtonPushed, true);
            app.KratkyPlotButton.Text = 'Kratky Plot';
            app.nEditFieldLabel = uilabel(app.Panel_6);
            app.nEditFieldLabel.Text = 'n=';
            app.nEditField = uieditfield(app.Panel_6, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 1;
            app.QnIQplotButton = uibutton(app.Panel_6, 'push');
            app.QnIQplotButton.ButtonPushedFcn = createCallbackFcn(app, @QnIQplotButtonPushed, true);
            app.QnIQplotButton.Text = 'Q^n*I(Q) plot';
            app.qMinEditFieldLabel_2 = uilabel(app.Panel_6);
            app.qMinEditFieldLabel_2.Text = 'qMin';
            app.qMinEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.qMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @qMinEditField_2ValueChanged, true);
            app.qMinEditField_2.Value = 0.005;
            app.qMaxEditField_3Label_2 = uilabel(app.Panel_6);
            app.qMaxEditField_3Label_2.Text = 'qMax';
            app.qMaxEditField_4 = uieditfield(app.Panel_6, 'numeric');
            app.qMaxEditField_4.ValueDisplayFormat = '%.2f';
            app.qMaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @qMaxEditField_4ValueChanged, true);
            app.qMaxEditField_4.Value = 0.6;
            app.EstimateMWButton = uibutton(app.Panel_6, 'push');
            app.EstimateMWButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateMWButtonPushed, true);
            app.EstimateMWButton.Text = 'Estimate M/W';
            app.ButtonGroup = uibuttongroup(app.Panel_6);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ProteinButton = uiradiobutton(app.ButtonGroup);
            app.ProteinButton.Text = 'Protein';
            app.ProteinButton.Value = true;
            app.NAButton = uiradiobutton(app.ButtonGroup);
            app.NAButton.Text = 'NA';
            app.CalculateSAXSInvariantButton = uibutton(app.Panel_6, 'push');
            app.CalculateSAXSInvariantButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSAXSInvariantButtonPushed, true);
            app.CalculateSAXSInvariantButton.Text = 'Calculate SAXS Invariant';
            app.MsgLabel = uilabel(app.Panel_6);
            app.MsgLabel.Text = 'Msg:';
            app.SetATSASbinPathButton = uibutton(app.AnalysisTab, 'push');
            app.SetATSASbinPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetATSASbinPathButtonPushed, true);
            app.SetATSASbinPathButton.Text = 'Set ATSAS/bin Path';
            app.PathAtsasbinLabel = uilabel(app.AnalysisTab);
            app.PathAtsasbinLabel.Text = 'Path: c:\atsas\bin';
            app.KratkyMWetcLabel = uilabel(app.AnalysisTab);
            app.KratkyMWetcLabel.Text = 'Kratky, MW, etc';
            app.SaveSAXSReport2Button = uibutton(app.AnalysisTab, 'push');
            app.SaveSAXSReport2Button.Text = 'Save SAXS Report 2';
            app.SAXSWAXSTab = uitab(app.TabGroup);
            app.SAXSWAXSTab.Title = 'SAXS/WAXS';
            app.SAXSWAXSTab.ButtonDownFcn = createCallbackFcn(app, @SAXSWAXSTabButtonDown, true);
            app.Panel_7 = uipanel(app.SAXSWAXSTab);
            app.LoadSampleSAXSButton = uibutton(app.Panel_7, 'push');
            app.LoadSampleSAXSButton.Text = 'Load Sample SAXS';
            app.EditField_17 = uieditfield(app.Panel_7, 'text');
            app.EditField_17.Value = 'SAXS.dat';
            app.LoadSampleWAXSButton = uibutton(app.Panel_7, 'push');
            app.LoadSampleWAXSButton.Text = 'Load Sample WAXS';
            app.EditField_18 = uieditfield(app.Panel_7, 'text');
            app.EditField_18.Value = 'SAXS.dat';
            app.LoadBufferSAXSButton = uibutton(app.Panel_7, 'push');
            app.LoadBufferSAXSButton.Text = 'Load Buffer SAXS';
            app.EditField_19 = uieditfield(app.Panel_7, 'text');
            app.EditField_19.Value = 'SAXS.dat';
            app.LoadBufferWAXSButton = uibutton(app.Panel_7, 'push');
            app.LoadBufferWAXSButton.Text = 'Load Buffer WAXS';
            app.EditField_20 = uieditfield(app.Panel_7, 'text');
            app.EditField_20.Value = 'SAXS.dat';
            app.LoadEmptyCellSAXSButton = uibutton(app.Panel_7, 'push');
            app.LoadEmptyCellSAXSButton.Text = 'Load Empty Cell SAXS';
            app.EditField_21 = uieditfield(app.Panel_7, 'text');
            app.EditField_21.Value = 'SAXS.dat';
            app.LoadEmptyCellWAXSButton = uibutton(app.Panel_7, 'push');
            app.LoadEmptyCellWAXSButton.Text = 'Load Empty Cell WAXS';
            app.EditField_22 = uieditfield(app.Panel_7, 'text');
            app.EditField_22.Value = 'SAXS.dat';
            app.EmptyCellOnCheckBox = uicheckbox(app.Panel_7);
            app.EmptyCellOnCheckBox.Text = 'Empty Cell On';
            app.EmptyCellOnCheckBox.Value = true;
            app.SAXS_alphaEditFieldLabel = uilabel(app.Panel_7);
            app.SAXS_alphaEditFieldLabel.Text = 'SAXS_alpha';
            app.SAXS_alphaEditField = uieditfield(app.Panel_7, 'numeric');
            app.SAXS_alphaEditField.ValueDisplayFormat = '%.5f';
            app.SAXS_alphaEditField.Value = 1;
            app.synwWAXSCheckBox = uicheckbox(app.Panel_7);
            app.synwWAXSCheckBox.Text = 'syn w/ WAXS';
            app.synwWAXSCheckBox.Value = true;
            app.WAXS_alphaEditFieldLabel = uilabel(app.Panel_7);
            app.WAXS_alphaEditFieldLabel.Text = 'WAXS_alpha';
            app.WAXS_alphaEditField = uieditfield(app.Panel_7, 'numeric');
            app.WAXS_alphaEditField.ValueDisplayFormat = '%.5f';
            app.WAXS_alphaEditField.Value = 1;
            app.SAXSWAXSBackgroundSubtractionButton = uibutton(app.Panel_7, 'push');
            app.SAXSWAXSBackgroundSubtractionButton.Text = 'SAXS/WAXS Background Subtraction';
            app.PlotBSubonlyCheckBox = uicheckbox(app.Panel_7);
            app.PlotBSubonlyCheckBox.Text = 'Plot BSub only';
            app.PlotBSubonlyCheckBox.Value = true;
            app.WAXS_betaEditFieldLabel = uilabel(app.Panel_7);
            app.WAXS_betaEditFieldLabel.Text = 'WAXS_beta';
            app.WAXS_betaEditField = uieditfield(app.Panel_7, 'numeric');
            app.WAXS_betaEditField.ValueDisplayFormat = '%.5f';
            app.WAXS_betaEditField.Value = 1;
            app.SAXSWAXSMergingLabel = uilabel(app.Panel_7);
            app.SAXSWAXSMergingLabel.Text = 'SAXS/WAXS Merging:';
            app.qLowEditFieldLabel = uilabel(app.Panel_7);
            app.qLowEditFieldLabel.Text = 'qLow';
            app.qLowEditField = uieditfield(app.Panel_7, 'numeric');
            app.qLowEditField.ValueDisplayFormat = '%.4f';
            app.qLowEditField.Value = 0.85;
            app.qHighEditFieldLabel = uilabel(app.Panel_7);
            app.qHighEditFieldLabel.Text = 'qHigh';
            app.qHighEditField = uieditfield(app.Panel_7, 'numeric');
            app.qHighEditField.ValueDisplayFormat = '%.4f';
            app.qHighEditField.Value = 0.95;
            app.qMergeEditField_2Label = uilabel(app.Panel_7);
            app.qMergeEditField_2Label.Text = 'qMerge';
            app.qMergeEditField_2 = uieditfield(app.Panel_7, 'numeric');
            app.qMergeEditField_2.ValueDisplayFormat = '%.4f';
            app.qMergeEditField_2.Value = 0.9;
            app.AutomaticSAXSWAXSMergeButton = uibutton(app.Panel_7, 'push');
            app.AutomaticSAXSWAXSMergeButton.Text = 'Automatic SAXS/WAXS Merge';
            app.SAXSWAXSBackgroundSubtractionLabel = uilabel(app.SAXSWAXSTab);
            app.SAXSWAXSBackgroundSubtractionLabel.Text = 'SAXS / WAXS Background Subtraction';
            app.SaveSAXSReport3Button = uibutton(app.SAXSWAXSTab, 'push');
            app.SaveSAXSReport3Button.Text = 'Save SAXS Report 3';
            app.AveragingTab2 = uitab(app.TabGroup);
            app.AveragingTab2.Title = 'Averaging';
            app.AveragingTab2.ButtonDownFcn = createCallbackFcn(app, @AveragingTab2ButtonDown, true);
            app.Panel_8 = uipanel(app.AveragingTab2);
            app.CrossCorrectionCheckBox = uicheckbox(app.Panel_8);
            app.CrossCorrectionCheckBox.Text = 'Cross Correction';
            app.CrossCorrectionCheckBox.Value = true;
            app.Y2distanceCheckBox = uicheckbox(app.Panel_8);
            app.Y2distanceCheckBox.Text = 'Y^2 distance';
            app.Y2distanceCheckBox.Value = true;
            app.ChebyshevdistanceCheckBox = uicheckbox(app.Panel_8);
            app.ChebyshevdistanceCheckBox.Text = 'Chebyshev distance';
            app.ChebyshevdistanceCheckBox.Value = true;
            app.AlgorithmsinUseLabel = uilabel(app.AveragingTab2);
            app.AlgorithmsinUseLabel.Text = 'Algorithms in Use';
            app.OutlierDetectionLabel = uilabel(app.AveragingTab2);
            app.OutlierDetectionLabel.Text = 'Outlier Detection';
            app.ButtonGroup_2 = uibuttongroup(app.AveragingTab2);
            app.MADButton = uiradiobutton(app.ButtonGroup_2);
            app.MADButton.Text = 'MAD';
            app.MADButton.Value = true;
            app.StandardButton = uiradiobutton(app.ButtonGroup_2);
            app.StandardButton.Text = 'Standard';
            app.nMADEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.nMADEditFieldLabel.Text = 'nMAD';
            app.nMADEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.nMADEditField.ValueDisplayFormat = '%.2f';
            app.nMADEditField.Value = 5;
            app.alphaEditField_15Label = uilabel(app.ButtonGroup_2);
            app.alphaEditField_15Label.Text = 'alpha';
            app.alphaEditField_15 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.alphaEditField_15.ValueDisplayFormat = '%.2f';
            app.alphaEditField_15.Value = 0.05;
            app.critPEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.critPEditFieldLabel.Text = 'critP';
            app.critPEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.critPEditField.ValueDisplayFormat = '%.2f';
            app.critPEditField.Value = 5;
            app.Panel_9 = uipanel(app.AveragingTab2);
            app.qrangeEditFieldLabel = uilabel(app.Panel_9);
            app.qrangeEditFieldLabel.Text = 'q range';
            app.qrangeEditField = uieditfield(app.Panel_9, 'numeric');
            app.qrangeEditField.Value = 0.0045;
            app.SAXSLabel = uilabel(app.Panel_9);
            app.SAXSLabel.Text = 'SAXS';
            app.qrangeEditField_2 = uieditfield(app.Panel_9, 'numeric');
            app.qrangeEditField_2.Value = 0.4;
            app.qSegmSpinnerLabel = uilabel(app.Panel_9);
            app.qSegmSpinnerLabel.Text = 'q Segm';
            app.qSegmSpinner = uispinner(app.Panel_9);
            app.qSegmSpinner.Value = 2;
            app.CheckDamageCheckBox = uicheckbox(app.Panel_9);
            app.CheckDamageCheckBox.Text = 'Check Damage';
            app.CheckDamageCheckBox.Value = true;
            app.FileNameFormatEditFieldLabel = uilabel(app.Panel_9);
            app.FileNameFormatEditFieldLabel.Text = 'File Name Format';
            app.FileNameFormatEditField = uieditfield(app.Panel_9, 'text');
            app.FileNameFormatEditField.Value = '%s_%05d_05d.dat';
            app.SelectFilestoAverageButton = uibutton(app.Panel_9, 'push');
            app.SelectFilestoAverageButton.Text = 'Select Files to Average';
            app.Select1DSAXSDateFolderButton = uibutton(app.Panel_9, 'push');
            app.Select1DSAXSDateFolderButton.Text = 'Select 1D SAXS Date Folder';
            app.EditField_23 = uieditfield(app.Panel_9, 'text');
            app.EditField_23.Value = 'SAXS/';
            app.AutoDetectBKGCheckBox = uicheckbox(app.Panel_9);
            app.AutoDetectBKGCheckBox.Text = 'Auto Detect BKG';
            app.AutoDetectBKGCheckBox.Value = true;
            app.AveragebySerialButton = uibutton(app.Panel_9, 'push');
            app.AveragebySerialButton.Text = 'Average by Serial #';
            app.DataSerialsEditFieldLabel = uilabel(app.Panel_9);
            app.DataSerialsEditFieldLabel.Text = 'Data Serial#s ';
            app.DataSerialsEditField = uieditfield(app.Panel_9, 'text');
            app.DataSerialsEditField.Value = '5; 9:21';
            app.WAXSLabel = uilabel(app.Panel_9);
            app.WAXSLabel.Text = 'WAXS';
            app.qrangeEditField_3 = uieditfield(app.Panel_9, 'numeric');
            app.qrangeEditField_3.Value = 2;
            app.CheckDamageCheckBox_2 = uicheckbox(app.Panel_9);
            app.CheckDamageCheckBox_2.Text = 'Check Damage';
            app.CheckDamageCheckBox_2.Value = true;
            app.SelectFilestoAverageButton_2 = uibutton(app.Panel_9, 'push');
            app.SelectFilestoAverageButton_2.Text = 'Select Files to Average';
            app.Select1DWAXSDateFolderButton = uibutton(app.Panel_9, 'push');
            app.Select1DWAXSDateFolderButton.Text = 'Select 1D WAXS Date Folder';
            app.EditField_24 = uieditfield(app.Panel_9, 'text');
            app.EditField_24.Value = 'WAXS/';
            app.AutoDetectBKGCheckBox_2 = uicheckbox(app.Panel_9);
            app.AutoDetectBKGCheckBox_2.Text = 'Auto Detect BKG';
            app.AutoDetectBKGCheckBox_2.Value = true;
            app.AveragebySerialButton_2 = uibutton(app.Panel_9, 'push');
            app.AveragebySerialButton_2.Text = 'Average by Serial #';
            app.qrangeEditField_4Label = uilabel(app.Panel_9);
            app.qrangeEditField_4Label.Text = 'q range';
            app.qrangeEditField_4 = uieditfield(app.Panel_9, 'numeric');
            app.qrangeEditField_4.Value = 0.85;
            app.qSegmSpinner_2Label = uilabel(app.Panel_9);
            app.qSegmSpinner_2Label.Text = 'q Segm';
            app.qSegmSpinner_2 = uispinner(app.Panel_9);
            app.qSegmSpinner_2.Value = 2;
            app.FileNameFormatEditField_2Label = uilabel(app.Panel_9);
            app.FileNameFormatEditField_2Label.Text = 'File Name Format';
            app.FileNameFormatEditField_2 = uieditfield(app.Panel_9, 'text');
            app.FileNameFormatEditField_2.Value = '%s_%05d_05d.dat';
            app.DataSerialsEditField_2Label = uilabel(app.Panel_9);
            app.DataSerialsEditField_2Label.Text = 'Data Serial#s ';
            app.DataSerialsEditField_2 = uieditfield(app.Panel_9, 'text');
            app.DataSerialsEditField_2.Value = '5; 9:21';
            app.AverageSAXSWAXS1DsbySerialButton = uibutton(app.Panel_9, 'push');
            app.AverageSAXSWAXS1DsbySerialButton.Text = 'Average SAXS & WAXS 1Ds by Serial #  ';
            app.SAXSWAXSLabel = uilabel(app.Panel_9);
            app.SAXSWAXSLabel.Text = 'SAXS & WAXS';
            app.SAXSWAXSAveragingSettingLabel = uilabel(app.AveragingTab2);
            app.SAXSWAXSAveragingSettingLabel.Text = 'SAXS / WAXS Averaging Setting';
            app.SAXSAvgReportEditFieldLabel = uilabel(app.AveragingTab2);
            app.SAXSAvgReportEditFieldLabel.Text = 'SAXS Avg Report:';
            app.SAXSAvgReportEditField = uieditfield(app.AveragingTab2, 'text');
            app.SAXSAvgReportEditField.Value = 'mySAXS_Avg_report.doc';
            app.SECTab = uitab(app.TabGroup);
            app.SECTab.Title = 'SEC';
            app.SECTab.ButtonDownFcn = createCallbackFcn(app, @SECTabButtonDown, true);
            app.GridLayout2 = uigridlayout(app.SECTab);
            app.SelectResultsFolderButton = uibutton(app.GridLayout2, 'push');
            app.SelectResultsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectResultsFolderButtonPushed, true);
            app.SelectResultsFolderButton.Text = 'Select Results Folder';
            app.SECResultFolder = uieditfield(app.GridLayout2, 'text');
            app.SECResultFolder.Value = 'Processed/';
            app.Select1DSAXSFolderButton_2 = uibutton(app.GridLayout2, 'push');
            app.Select1DSAXSFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @Select1DSAXSFolderButton_2Pushed, true);
            app.Select1DSAXSFolderButton_2.Text = 'Select 1D SAXS Folder';
            app.SECDataFolder = uieditfield(app.GridLayout2, 'text');
            app.SECDataFolder.Value = 'SAXS/';
            app.SaveSECSAXSMetaResultsButton = uibutton(app.GridLayout2, 'push');
            app.SaveSECSAXSMetaResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSECSAXSMetaResultsButtonPushed, true);
            app.SaveSECSAXSMetaResultsButton.Text = 'Save SEC-SAXS Meta-Results';
            app.LoadSECSAXSMetaResultsButton = uibutton(app.GridLayout2, 'push');
            app.LoadSECSAXSMetaResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSECSAXSMetaResultsButtonPushed, true);
            app.LoadSECSAXSMetaResultsButton.Text = 'Load SEC-SAXS Meta-Results';
            app.Panel_10 = uipanel(app.GridLayout2);
            app.RunSAXSIntergrationButton = uibutton(app.Panel_10, 'push');
            app.RunSAXSIntergrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSAXSIntergrationButtonPushed, true);
            app.RunSAXSIntergrationButton.Text = 'Run SAXS Intergration ';
            app.DataSerialEditFieldLabel = uilabel(app.Panel_10);
            app.DataSerialEditFieldLabel.Text = 'Data Serial # ';
            app.SEC_DataSerialNum = uieditfield(app.Panel_10, 'numeric');
            app.SEC_DataSerialNum.Value = 50;
            app.FormatEditFieldLabel = uilabel(app.Panel_10);
            app.FormatEditFieldLabel.Text = 'Format';
            app.SerFormat = uieditfield(app.Panel_10, 'text');
            app.SerFormat.Value = '%05d';
            app.Panel_11 = uipanel(app.GridLayout2);
            app.SEC_AreaCutoffHigh = uieditfield(app.Panel_11, 'numeric');
            app.SEC_AreaCutoffHigh.ValueDisplayFormat = '%.4f';
            app.SEC_AreaCutoffHigh.Value = 1;
            app.SEC_qHigh = uieditfield(app.Panel_11, 'numeric');
            app.SEC_qHigh.ValueDisplayFormat = '%.4f';
            app.SEC_qHigh.Value = 0.6;
            app.area_cutoffEditFieldLabel = uilabel(app.Panel_11);
            app.area_cutoffEditFieldLabel.Text = 'area_cutoff';
            app.SEC_AreaCutoffLow = uieditfield(app.Panel_11, 'numeric');
            app.SEC_AreaCutoffLow.ValueDisplayFormat = '%.4f';
            app.q_rangeEditFieldLabel = uilabel(app.Panel_11);
            app.q_rangeEditFieldLabel.Text = 'q_range';
            app.SEC_qLow = uieditfield(app.Panel_11, 'numeric');
            app.SEC_qLow.ValueDisplayFormat = '%.4f';
            app.SEC_qLow.Value = 0.01;
            app.DataSelectionbySerialLabel = uilabel(app.GridLayout2);
            app.DataSelectionbySerialLabel.Text = 'Data Selection:   by Serial #';
            app.Panel_12 = uipanel(app.GridLayout2);
            app.SelectDataSetsRunIntergrationButton = uibutton(app.Panel_12, 'push');
            app.SelectDataSetsRunIntergrationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataSetsRunIntergrationButtonPushed, true);
            app.SelectDataSetsRunIntergrationButton.Text = {'Select Data Sets & '; 'Run  Intergration '};
            app.flexiblebytimeLabel = uilabel(app.GridLayout2);
            app.flexiblebytimeLabel.Text = 'flexible & by time';
            app.IntergrationSettingLabel = uilabel(app.GridLayout2);
            app.IntergrationSettingLabel.Text = 'Intergration Setting';
            app.Panel_13 = uipanel(app.GridLayout2);
            app.BkgrangeEditFieldLabel = uilabel(app.Panel_13);
            app.BkgrangeEditFieldLabel.Text = 'Bkg # range';
            app.BkgSerNumLow = uieditfield(app.Panel_13, 'numeric');
            app.BkgSerNumLow.ValueDisplayFormat = '%.0f';
            app.BkgSerNumLow.Value = 50;
            app.BkgSerNumHigh = uieditfield(app.Panel_13, 'numeric');
            app.BkgSerNumHigh.ValueDisplayFormat = '%.0f';
            app.BkgSerNumHigh.Value = 150;
            app.AveragingasBkgSAXSButton = uibutton(app.Panel_13, 'push');
            app.AveragingasBkgSAXSButton.ButtonPushedFcn = createCallbackFcn(app, @AveragingasBkgSAXSButtonPushed, true);
            app.AveragingasBkgSAXSButton.Text = 'Averaging as Bkg SAXS';
            app.DataSerNumHigh = uieditfield(app.Panel_13, 'numeric');
            app.DataSerNumHigh.ValueDisplayFormat = '%.0f';
            app.DataSerNumHigh.Value = 300;
            app.AveragingasDataSAXSButton = uibutton(app.Panel_13, 'push');
            app.AveragingasDataSAXSButton.ButtonPushedFcn = createCallbackFcn(app, @AveragingasDataSAXSButtonPushed, true);
            app.AveragingasDataSAXSButton.Text = 'Averaging as Data SAXS';
            app.DatarangeEditFieldLabel = uilabel(app.Panel_13);
            app.DatarangeEditFieldLabel.Text = 'Data # range';
            app.DataSerNumLow = uieditfield(app.Panel_13, 'numeric');
            app.DataSerNumLow.ValueDisplayFormat = '%.0f';
            app.DataSerNumLow.Value = 210;
            app.DataFileTag = uieditfield(app.Panel_13, 'text');
            app.DataFileTag.Value = '_peak1';
            app.BkgFileTag = uieditfield(app.Panel_13, 'text');
            app.BkgFileTag.Value = '_bkg';
            app.PlotSer_vs_SAXSButton = uibutton(app.GridLayout2, 'push');
            app.PlotSer_vs_SAXSButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSer_vs_SAXSButtonPushed, true);
            app.PlotSer_vs_SAXSButton.Text = 'Plot Ser#_vs_SAXS';
            app.TRSAXSTab = uitab(app.TabGroup);
            app.TRSAXSTab.Title = 'TRSAXS';
            app.TRSAXSTab.ButtonDownFcn = createCallbackFcn(app, @TRSAXSTabButtonDown, true);
            app.SFATR_DataDir = uieditfield(app.TRSAXSTab, 'text');
            app.SelectDataFolderButton = uibutton(app.TRSAXSTab, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.SFATR_ResultDir = uieditfield(app.TRSAXSTab, 'text');
            app.SelectResultFolderButton = uibutton(app.TRSAXSTab, 'push');
            app.SelectResultFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectResultFolderButtonPushed, true);
            app.SelectResultFolderButton.Text = 'Select Result Folder';
            app.SFATR_BkgFile = uieditfield(app.TRSAXSTab, 'text');
            app.SelectBackgrounddataFileButton = uibutton(app.TRSAXSTab, 'push');
            app.SelectBackgrounddataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBackgrounddataFileButtonPushed, true);
            app.SelectBackgrounddataFileButton.Text = 'Select Background data File';
            app.SampleDataNameEditFieldLabel = uilabel(app.TRSAXSTab);
            app.SampleDataNameEditFieldLabel.Text = 'Sample/Data Name';
            app.SampleDataNameEditField = uieditfield(app.TRSAXSTab, 'text');
            app.SampleDataNameEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDataNameEditFieldValueChanged, true);
            app.SampleDataNameEditField.Value = 'sampTrial1';
            app.DataSerialSquenceLabel = uilabel(app.TRSAXSTab);
            app.DataSerialSquenceLabel.Text = 'Data Serial Squence';
            app.DataSerialSquenceEditField = uieditfield(app.TRSAXSTab, 'text');
            app.DataSerialSquenceEditField.ValueChangedFcn = createCallbackFcn(app, @DataSerialSquenceEditFieldValueChanged, true);
            app.DataSerialSquenceEditField.Value = '35:46, 48:51, 55';
            app.NumberofShotsSerialLabel = uilabel(app.TRSAXSTab);
            app.NumberofShotsSerialLabel.Text = ' Shots/Serial#';
            app.ShotsSerialEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.ShotsSerialEditField.ValueChangedFcn = createCallbackFcn(app, @ShotsSerialEditFieldValueChanged, true);
            app.ShotsSerialEditField.Value = 20;
            app.XrayExptimemsEditFieldLabel = uilabel(app.TRSAXSTab);
            app.XrayExptimemsEditFieldLabel.Text = 'X-ray Exp time(ms)';
            app.XrayExptimemsEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.XrayExptimemsEditField.ValueDisplayFormat = '%5.2f';
            app.XrayExptimemsEditField.ValueChangedFcn = createCallbackFcn(app, @XrayExptimemsEditFieldValueChanged, true);
            app.XrayExptimemsEditField.Value = 10;
            app.ExtraDelaymsEditFieldLabel = uilabel(app.TRSAXSTab);
            app.ExtraDelaymsEditFieldLabel.Text = 'Extra Delay(ms)';
            app.ExtraDelaymsEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.ExtraDelaymsEditField.ValueDisplayFormat = '%5.2f';
            app.ExtraDelaymsEditField.ValueChangedFcn = createCallbackFcn(app, @ExtraDelaymsEditFieldValueChanged, true);
            app.XrayPeriodtimemsEditFieldLabel = uilabel(app.TRSAXSTab);
            app.XrayPeriodtimemsEditFieldLabel.Text = 'X-ray Period time(ms)';
            app.XrayPeriodtimemsEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.XrayPeriodtimemsEditField.ValueDisplayFormat = '%5.2f';
            app.XrayPeriodtimemsEditField.ValueChangedFcn = createCallbackFcn(app, @XrayPeriodtimemsEditFieldValueChanged, true);
            app.XrayPeriodtimemsEditField.Value = 50;
            app.UITable = uitable(app.TRSAXSTab);
            app.UITable.ColumnName = {'Sample'; 'Phase1'; 'Phase2'; 'Phase3'; 'Phase4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.StoppedFlowoperationSettingLabel = uilabel(app.TRSAXSTab);
            app.StoppedFlowoperationSettingLabel.Text = 'Stopped-Flow operation Setting:';
            app.RunTRSAXSDataProcessingButton = uibutton(app.TRSAXSTab, 'push');
            app.RunTRSAXSDataProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunTRSAXSDataProcessingButtonPushed, true);
            app.RunTRSAXSDataProcessingButton.Text = 'Run TR-SAXS Data Processing';
            app.CheckFlowSpeedButton = uibutton(app.TRSAXSTab, 'push');
            app.CheckFlowSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFlowSpeedButtonPushed, true);
            app.CheckFlowSpeedButton.Text = 'Check Flow Speed';
            app.ExperimentalNotesEditFieldLabel = uilabel(app.TRSAXSTab);
            app.ExperimentalNotesEditFieldLabel.Text = 'Experimental Notes';
            app.SFAExperimentalNotes = uieditfield(app.TRSAXSTab, 'text');
            app.SFAExperimentalNotes.Value = 'takeshot test1 0.010 20 0.050 3;';
            app.DeadVolumeuLEditFieldLabel = uilabel(app.TRSAXSTab);
            app.DeadVolumeuLEditFieldLabel.Text = 'Dead Volume (uL)';
            app.DeadVolumeuLEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.DeadVolumeuLEditField.ValueDisplayFormat = '%5.2f';
            app.DeadVolumeuLEditField.ValueChangedFcn = createCallbackFcn(app, @DeadVolumeuLEditFieldValueChanged, true);
            app.DeadVolumeuLEditField.Value = 18;
            app.FlowRatemLsEditFieldLabel = uilabel(app.TRSAXSTab);
            app.FlowRatemLsEditFieldLabel.Text = 'Flow Rate (mL/s)';
            app.FlowRatemLsEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.FlowRatemLsEditField.ValueDisplayFormat = '%5.2f';
            app.FlowRatemLsEditField.ValueChangedFcn = createCallbackFcn(app, @FlowRatemLsEditFieldValueChanged, true);
            app.FlowRatemLsEditField.Value = 4;
            app.First3timepointsmsLabel = uilabel(app.TRSAXSTab);
            app.First3timepointsmsLabel.Text = {'First 5 time '; 'points (ms)'};

            app.SFAFirst3timepoints = uieditfield(app.TRSAXSTab, 'text');
            app.DurationmsEditFieldLabel = uilabel(app.TRSAXSTab);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.DurationmsEditField.ValueDisplayFormat = '%5.2f';
            app.DurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmsEditFieldValueChanged, true);
            app.DurationmsEditField.Value = 100;
            app.TRSAXSReportEditFieldLabel = uilabel(app.TRSAXSTab);
            app.TRSAXSReportEditFieldLabel.Text = 'TRSAXS Report:';
            app.TRSAXSReportEditField = uieditfield(app.TRSAXSTab, 'text');
            app.TRSAXSReportEditField.Value = 'myTRSAXS_report.doc';
            app.SaveTRSAXSReportButton = uibutton(app.TRSAXSTab, 'push');
            app.SaveTRSAXSReportButton.Text = 'Save TRSAXS Report';
            app.alphaEditField_16Label = uilabel(app.TRSAXSTab);
            app.alphaEditField_16Label.Text = 'alpha';
            app.trAlphaEditField = uieditfield(app.TRSAXSTab, 'numeric');
            app.trAlphaEditField.ValueDisplayFormat = '%5.5f';
            app.trAlphaEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.SAXSPlotTab = uitab(app.TabGroup2);
            app.SAXSPlotTab.Title = 'SAXS Plot';
            app.UIAxes = uiaxes(app.SAXSPlotTab);
            title(app.UIAxes, 'SAXS plot')
            xlabel(app.UIAxes, 'Q (A^{-1})')
            ylabel(app.UIAxes, 'I(Q) (a.u.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0.002 0.9];
            app.UIAxes.YLim = [0.002 20];
            app.UIAxes.XScale = 'log';
            app.UIAxes.YScale = 'log';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.XlimEditFieldLabel = uilabel(app.SAXSPlotTab);
            app.XlimEditFieldLabel.Text = 'Xlim';
            app.XlimMin = uieditfield(app.SAXSPlotTab, 'numeric');
            app.XlimMin.ValueDisplayFormat = '%.2E';
            app.XlimMin.ValueChangedFcn = createCallbackFcn(app, @XlimMinValueChanged, true);
            app.XlimMin.Value = 0.002;
            app.XlimMax = uieditfield(app.SAXSPlotTab, 'numeric');
            app.XlimMax.ValueDisplayFormat = '%.2E';
            app.XlimMax.ValueChangedFcn = createCallbackFcn(app, @XlimMaxValueChanged, true);
            app.XlimMax.Value = 0.9;
            app.YlimMax = uieditfield(app.SAXSPlotTab, 'numeric');
            app.YlimMax.ValueDisplayFormat = '%.2E';
            app.YlimMax.ValueChangedFcn = createCallbackFcn(app, @YlimMaxValueChanged, true);
            app.YlimMax.Value = 20;
            app.YlimEditFieldLabel = uilabel(app.SAXSPlotTab);
            app.YlimEditFieldLabel.Text = 'Ylim';
            app.YlimMin = uieditfield(app.SAXSPlotTab, 'numeric');
            app.YlimMin.ValueDisplayFormat = '%.2E';
            app.YlimMin.ValueChangedFcn = createCallbackFcn(app, @YlimMinValueChanged, true);
            app.YlimMin.Value = 0.002;
            app.PlotOptionDropDownLabel = uilabel(app.SAXSPlotTab);
            app.PlotOptionDropDownLabel.Text = 'Plot Option';
            app.PlotOptionDropDown = uidropdown(app.SAXSPlotTab);
            app.PlotOptionDropDown.Items = {'linxy', 'logx', 'logy', 'logxy'};
            app.PlotOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotOptionDropDownValueChanged, true);
            app.PlotOptionDropDown.Value = 'logxy';
            app.ReplotButton = uibutton(app.SAXSPlotTab, 'push');
            app.ReplotButton.Text = 'Re-plot';
            app.AddDataButton = uibutton(app.SAXSPlotTab, 'push');
            app.AddDataButton.Text = 'Add Data';
            app.XLabelEditFieldLabel = uilabel(app.SAXSPlotTab);
            app.XLabelEditFieldLabel.Text = 'XLabel';
            app.XLabelEditField = uieditfield(app.SAXSPlotTab, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.XLabelEditField.Value = 'Q (A^{-1})';
            app.XLabelEditFieldLabel_2 = uilabel(app.SAXSPlotTab);
            app.XLabelEditFieldLabel_2.Text = 'YLabel';
            app.XLabelEditField_2 = uieditfield(app.SAXSPlotTab, 'text');
            app.XLabelEditField_2.ValueChangedFcn = createCallbackFcn(app, @XLabelEditField_2ValueChanged, true);
            app.XLabelEditField_2.ValueChangingFcn = createCallbackFcn(app, @XLabelEditField_2ValueChanging, true);
            app.XLabelEditField_2.Value = 'I(Q) (a.u.)';
            app.TitleLabelEditFieldLabel = uilabel(app.SAXSPlotTab);
            app.TitleLabelEditFieldLabel.Text = 'TitleLabel';
            app.TitleLabelEditField = uieditfield(app.SAXSPlotTab, 'text');
            app.TitleLabelEditField.ValueChangedFcn = createCallbackFcn(app, @TitleLabelEditFieldValueChanged, true);
            app.TitleLabelEditField.Value = 'SAXS plot';
            app.SaveFigureButton = uibutton(app.SAXSPlotTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.LoadDatatoPlotButton = uibutton(app.SAXSPlotTab, 'push');
            app.LoadDatatoPlotButton.Text = 'Load Data to Plot';
            app.EditField_16 = uieditfield(app.SAXSPlotTab, 'text');
            app.EditField_16.Value = 'SAXS.dat';
            app.ErrorbaronCheckBox = uicheckbox(app.SAXSPlotTab);
            app.ErrorbaronCheckBox.Text = 'Error bar on';
            app.AnalysisPlotTab = uitab(app.TabGroup2);
            app.AnalysisPlotTab.Title = 'Analysis Plot';
            app.UIAxesSFA3 = uiaxes(app.AnalysisPlotTab);
            title(app.UIAxesSFA3, 'SAXS Curves')
            xlabel(app.UIAxesSFA3, 'q (A^{-1})')
            ylabel(app.UIAxesSFA3, 'I(q) (a.u.)')
            zlabel(app.UIAxesSFA3, 'Z')
            app.UIAxesSFA4 = uiaxes(app.AnalysisPlotTab);
            title(app.UIAxesSFA4, 'Kratky Plot')
            xlabel(app.UIAxesSFA4, 'q (A^{-1})')
            ylabel(app.UIAxesSFA4, 'q^2 *I(0)')
            zlabel(app.UIAxesSFA4, 'Z')
            app.SECPlotTab = uitab(app.TabGroup2);
            app.SECPlotTab.Title = 'SEC ';
            app.GridLayout3 = uigridlayout(app.SECPlotTab);
            app.SEC2Axes = uiaxes(app.GridLayout3);
            title(app.SEC2Axes, 'Series Plot')
            xlabel(app.SEC2Axes, 'Volume (mL)')
            ylabel(app.SEC2Axes, 'Absorbance (mAU)')
            zlabel(app.SEC2Axes, 'Z')
            app.SEC2Axes.XLim = [0 36];
            app.SEC2Axes.YLim = [-2 20];
            app.LoadFPLCElutionFileButton = uibutton(app.GridLayout3, 'push');
            app.LoadFPLCElutionFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFPLCElutionFileButtonPushed, true);
            app.LoadFPLCElutionFileButton.Text = 'Load FPLC Elution File';
            app.PlotSAXS_vs_SerButton = uibutton(app.GridLayout3, 'push');
            app.PlotSAXS_vs_SerButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSAXS_vs_SerButtonPushed, true);
            app.PlotSAXS_vs_SerButton.Tooltip = {'Plot SAXS_vs_Serial# from Meta-data'};
            app.PlotSAXS_vs_SerButton.Text = 'Plot SAXS_vs_Ser# ';
            app.LoadSAXS_vs_SerFileButton_2 = uibutton(app.GridLayout3, 'push');
            app.LoadSAXS_vs_SerFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadSAXS_vs_SerFileButton_2Pushed, true);
            app.LoadSAXS_vs_SerFileButton_2.Tooltip = {'Load and Plot fron SAXS_vs_Serial# File'};
            app.LoadSAXS_vs_SerFileButton_2.Text = 'Load SAXS_vs_Ser# File';
            app.XlimMax_2 = uieditfield(app.GridLayout3, 'numeric');
            app.XlimMax_2.ValueChangedFcn = createCallbackFcn(app, @XlimMax_2ValueChanged, true);
            app.XlimMax_2.Value = 35;
            app.YlimMax_2 = uieditfield(app.GridLayout3, 'numeric');
            app.YlimMax_2.ValueDisplayFormat = '%5.2f';
            app.YlimMax_2.ValueChangedFcn = createCallbackFcn(app, @YlimMax_2ValueChanged, true);
            app.YlimMax_2.Value = 250;
            app.YlimEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.YlimEditFieldLabel_2.Text = 'Left_Ylim';
            app.YlimMin_2 = uieditfield(app.GridLayout3, 'numeric');
            app.YlimMin_2.ValueChangedFcn = createCallbackFcn(app, @YlimMin_2ValueChanged, true);
            app.YlimMin_2.Value = -5;
            app.YlimMax_3 = uieditfield(app.GridLayout3, 'numeric');
            app.YlimMax_3.ValueDisplayFormat = '%5.4f';
            app.YlimMax_3.ValueChangedFcn = createCallbackFcn(app, @YlimMax_3ValueChanged, true);
            app.YlimMax_3.Value = 0.02;
            app.YlimEditFieldLabel_3 = uilabel(app.GridLayout3);
            app.YlimEditFieldLabel_3.Text = 'Right_Ylim';
            app.YlimMin_3 = uieditfield(app.GridLayout3, 'numeric');
            app.YlimMin_3.ValueChangedFcn = createCallbackFcn(app, @YlimMin_3ValueChanged, true);
            app.YlimMin_3.Value = -0.002;
            app.XLabelEditFieldLabel_3 = uilabel(app.GridLayout3);
            app.XLabelEditFieldLabel_3.Text = 'Left YLabel';
            app.LeftYLabelEditField = uieditfield(app.GridLayout3, 'text');
            app.LeftYLabelEditField.ValueChangedFcn = createCallbackFcn(app, @LeftYLabelEditFieldValueChanged, true);
            app.LeftYLabelEditField.Value = 'UV Absorbance (mAU)';
            app.FPLCLabel = uilabel(app.GridLayout3);
            app.FPLCLabel.Text = 'FPLC';
            app.SAXSLabel_2 = uilabel(app.GridLayout3);
            app.SAXSLabel_2.Text = 'SAXS';
            app.XLabelEditFieldLabel_4 = uilabel(app.GridLayout3);
            app.XLabelEditFieldLabel_4.Text = 'Right YLabel';
            app.RightYLabelEditField = uieditfield(app.GridLayout3, 'text');
            app.RightYLabelEditField.ValueChangedFcn = createCallbackFcn(app, @RightYLabelEditFieldValueChanged, true);
            app.RightYLabelEditField.Value = 'SAXS Int. (a.u.)';
            app.FlowRatemlminLabel = uilabel(app.GridLayout3);
            app.FlowRatemlminLabel.Text = 'Flow Rate (ml/min)';
            app.SEC_fplcFlowRate = uieditfield(app.GridLayout3, 'numeric');
            app.SEC_fplcFlowRate.ValueChangedFcn = createCallbackFcn(app, @SEC_fplcFlowRateValueChanged, true);
            app.SEC_fplcFlowRate.Value = 0.4;
            app.FlowRatemlminLabel_2 = uilabel(app.GridLayout3);
            app.FlowRatemlminLabel_2.Text = {'SAXS Data Collection'; ' Period(sec/frame)'};
            app.SEC_saxsPeriod = uieditfield(app.GridLayout3, 'numeric');
            app.SEC_saxsPeriod.ValueChangedFcn = createCallbackFcn(app, @SEC_saxsPeriodValueChanged, true);
            app.SEC_saxsPeriod.Value = 2;
            app.FlowRatemlminLabel_3 = uilabel(app.GridLayout3);
            app.FlowRatemlminLabel_3.Text = 'time-delay(sec)';
            app.SEC_saxsTimeDelay = uieditfield(app.GridLayout3, 'numeric');
            app.SEC_saxsTimeDelay.ValueChangedFcn = createCallbackFcn(app, @SEC_saxsTimeDelayValueChanged, true);
            app.SEC_saxsTimeDelay.Value = 30;
            app.SECCurvestyleLabel = uilabel(app.GridLayout3);
            app.SECCurvestyleLabel.Text = 'SAXS Curve style';
            app.SEC_SAXSCurveStyle = uieditfield(app.GridLayout3, 'text');
            app.SEC_SAXSCurveStyle.ValueChangedFcn = createCallbackFcn(app, @SEC_SAXSCurveStyleValueChanged, true);
            app.SEC_SAXSCurveStyle.Value = '''-or''';
            app.SECCurvestyleLabel_2 = uilabel(app.GridLayout3);
            app.SECCurvestyleLabel_2.Text = 'SEC Curve style';
            app.SEC_FPLCcurveStyle = uieditfield(app.GridLayout3, 'text');
            app.SEC_FPLCcurveStyle.Value = '''-b''';
            app.LylimLabel = uilabel(app.GridLayout3);
            app.LylimLabel.Text = 'Xlim';
            app.XlimMin_2 = uieditfield(app.GridLayout3, 'numeric');
            app.XlimMin_2.ValueChangedFcn = createCallbackFcn(app, @XlimMin_2ValueChanged, true);
            app.ExtCoeffLabel = uilabel(app.GridLayout3);
            app.ExtCoeffLabel.Text = 'Ext. Coeff.';
            app.ExtCoeffEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ExtCoeffEditField.ValueDisplayFormat = '%.3E';
            app.ExtCoeffEditField.Tooltip = {'Extinction Coefficient, (M^-1cm^-1), @280nm for protein, 260nm for Nucleic acids'};
            app.ExtCoeffEditField.Value = 5000;
            app.AlignatPeakButton = uibutton(app.GridLayout3, 'push');
            app.AlignatPeakButton.Text = 'Align at Peak';
            app.MWEditFieldLabel = uilabel(app.GridLayout3);
            app.MWEditFieldLabel.Text = 'MW';
            app.MWEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MWEditField.ValueDisplayFormat = '%.1f';
            app.MWEditField.Tooltip = {'Molecular Weight, (Dalton)'};
            app.MWEditField.Value = 14320;
            app.SFATab = uitab(app.TabGroup2);
            app.SFATab.Title = 'SFA ';
            app.UIAxesSFA1 = uiaxes(app.SFATab);
            title(app.UIAxesSFA1, 'Rg Change')
            xlabel(app.UIAxesSFA1, 'Shot Number')
            ylabel(app.UIAxesSFA1, 'Rg ( A)')
            zlabel(app.UIAxesSFA1, 'Z')
            app.UIAxesSFA2 = uiaxes(app.SFATab);
            title(app.UIAxesSFA2, 'I(0) Change')
            xlabel(app.UIAxesSFA2, 'Shot Number')
            ylabel(app.UIAxesSFA2, 'I(0)')
            zlabel(app.UIAxesSFA2, 'Z')
            app.PlotsTab = uitab(app.TabGroup2);
            app.PlotsTab.Title = '2 Plots';
            app.UIAxes2 = uiaxes(app.PlotsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PlotsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ContextMenu = uicontextmenu(app.BioSAXSMatUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.BioSAXSMatUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.BioSAXSMatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bioSAXSmat2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BioSAXSMatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioSAXSMatUIFigure)
        end
    end
end